classdef DPAS_for_102Lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabExoAnalystUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        BioMechProcessButton            matlab.ui.control.StateButton
        GlobalSettingButton             matlab.ui.control.StateButton
        ProcessAnalysisButton           matlab.ui.control.StateButton
        LabDataProcessKitLabel          matlab.ui.control.Label
        AnaDataTab                      matlab.ui.container.Tab
        BACKButton                      matlab.ui.control.Button
        PlotButtonGroup                 matlab.ui.container.ButtonGroup
        RESPButton                      matlab.ui.control.Button
        PLOTButton                      matlab.ui.control.Button
        GROUPBARButton                  matlab.ui.control.Button
        FITTINGButton                   matlab.ui.control.Button
        SIGNFIButton                    matlab.ui.control.Button
        ToolTabGroup                    matlab.ui.container.TabGroup
        LoadTab                         matlab.ui.container.Tab
        SelectVarButton                 matlab.ui.control.Button
        DataDownButton                  matlab.ui.control.Button
        DataUpButton                    matlab.ui.control.Button
        RenameDataButton                matlab.ui.control.Button
        ExtractSCONEDataButton          matlab.ui.control.Button
        SAVEVARButton                   matlab.ui.control.Button
        DELETEDATAButton                matlab.ui.control.Button
        LOADDATAButton                  matlab.ui.control.Button
        VarEditTab                      matlab.ui.container.Tab
        PROCESSALLButton                matlab.ui.control.Button
        NormVarButton                   matlab.ui.control.Button
        CreateStateButton               matlab.ui.control.Button
        BiasVarButton                   matlab.ui.control.Button
        PROCESSButton                   matlab.ui.control.Button
        NormalVarButton                 matlab.ui.control.Button
        DelVarButton                    matlab.ui.control.Button
        RenameVarButton                 matlab.ui.control.Button
        MergeDataButton                 matlab.ui.control.Button
        DivideDataButton                matlab.ui.control.Button
        DeleteStepsButton               matlab.ui.control.Button
        PlotSetTab                      matlab.ui.container.Tab
        TimeCheckBox                    matlab.ui.control.CheckBox
        FigureDropDown                  matlab.ui.control.DropDown
        FigureDropDownLabel             matlab.ui.control.Label
        ColorButton                     matlab.ui.control.Button
        EdgeCheckBox                    matlab.ui.control.CheckBox
        RightCheckBox                   matlab.ui.control.CheckBox
        SaveFigure                      matlab.ui.control.Button
        ReverseCheckBox                 matlab.ui.control.CheckBox
        ClearAllButton                  matlab.ui.control.Button
        ClearLastButton                 matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        SubColDropDown                  matlab.ui.control.DropDown
        SubColDropDownLabel             matlab.ui.control.Label
        SubRowDropDown                  matlab.ui.control.DropDown
        SubRowDropDownLabel             matlab.ui.control.Label
        NEWSubButton                    matlab.ui.control.Button
        NEWButton                       matlab.ui.control.Button
        ColorMapLabel                   matlab.ui.control.Label
        ColorMapDropDown                matlab.ui.control.DropDown
        MarkerTypeDropDown              matlab.ui.control.DropDown
        MarkerTypeDropDownLabel         matlab.ui.control.Label
        MarkerSizeDropDown              matlab.ui.control.DropDown
        MarkerSizeDropDownLabel         matlab.ui.control.Label
        LineTypeDropDown                matlab.ui.control.DropDown
        LineTypeDropDownLabel           matlab.ui.control.Label
        LineWidthDropDown               matlab.ui.control.DropDown
        LineWidthDropDownLabel          matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Lamp                            matlab.ui.control.Button
        ColorMapAxes                    matlab.ui.control.UIAxes
        DataTypeTab                     matlab.ui.container.Tab
        HistoryButton                   matlab.ui.control.StateButton
        SCONEButton                     matlab.ui.control.StateButton
        PredSimButton                   matlab.ui.control.StateButton
        OpenSimButton                   matlab.ui.control.StateButton
        dSPACEButton                    matlab.ui.control.StateButton
        StepSelectTab                   matlab.ui.container.Tab
        OKButton_2                      matlab.ui.control.Button
        BaselineEditField               matlab.ui.control.NumericEditField
        BaselineEditFieldLabel          matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SmallerButton                   matlab.ui.control.RadioButton
        BiggerButton                    matlab.ui.control.RadioButton
        CANCELButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        StepListBox                     matlab.ui.control.ListBox
        SelectstepstodeleteLabel        matlab.ui.control.Label
        GaitPhaseTab                    matlab.ui.container.Tab
        Panel_17                        matlab.ui.container.Panel
        Panel_18                        matlab.ui.container.Panel
        PhaseEndEditField4              matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_12    matlab.ui.control.Label
        PhaseStartEditField4            matlab.ui.control.NumericEditField
        PhaseEndEditField3              matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_11    matlab.ui.control.Label
        PhaseStartEditField3            matlab.ui.control.NumericEditField
        PhaseEndEditField2              matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_10    matlab.ui.control.Label
        PhaseStartEditField2            matlab.ui.control.NumericEditField
        PhaseEndEditField1              matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_9     matlab.ui.control.Label
        PhaseStartEditField1            matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_8     matlab.ui.control.Label
        StrideShiftEditField            matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_7     matlab.ui.control.Label
        GaitCycleEditField7             matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_6     matlab.ui.control.Label
        GaitCycleEditField6             matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_5     matlab.ui.control.Label
        GaitCycleEditField5             matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_4     matlab.ui.control.Label
        GaitCycleEditField4             matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_3     matlab.ui.control.Label
        GaitCycleEditField3             matlab.ui.control.NumericEditField
        GaitCycleEditField_2Label_2     matlab.ui.control.Label
        GaitCycleEditField2             matlab.ui.control.NumericEditField
        ToolButtonGroup                 matlab.ui.container.ButtonGroup
        PlotSetTabButton_2              matlab.ui.control.Button
        PlotSetTabButton                matlab.ui.control.Button
        VarEditTabButton                matlab.ui.control.Button
        LoadTabButton                   matlab.ui.control.Button
        PlotGroup                       matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        GROUPPLOTPanel                  matlab.ui.container.Panel
        GROUPPLOTButton_2               matlab.ui.control.Button
        GroupPlotSetColor               matlab.ui.control.Button
        GroupPlotDown                   matlab.ui.control.Button
        GroupPlotUp                     matlab.ui.control.Button
        GroupPlotRemove                 matlab.ui.control.Button
        GroupPlotClear                  matlab.ui.control.Button
        GroupPlotAdd                    matlab.ui.control.Button
        GroupPlotTable                  matlab.ui.control.Table
        GROUPPLOTButton                 matlab.ui.control.Button
        TrajectoryPLOTPanel             matlab.ui.container.Panel
        PlotTypeDropDown                matlab.ui.control.DropDown
        PlotTypeDropDownLabel           matlab.ui.control.Label
        LinecolorDropDown               matlab.ui.control.DropDown
        LinecolorDropDownLabel          matlab.ui.control.Label
        StepsDropDown                   matlab.ui.control.DropDown
        StepsDropDownLabel              matlab.ui.control.Label
        ALTERStridePlotButton_4         matlab.ui.control.Button
        SIGNELSTRIDEPLOTPanel           matlab.ui.container.Panel
        UpdateButton                    matlab.ui.control.Button
        AverageNumDropDown              matlab.ui.control.DropDown
        AverageNumDropDownLabel         matlab.ui.control.Label
        StepsNumDropDown                matlab.ui.control.DropDown
        StepsNumDropDownLabel           matlab.ui.control.Label
        ALTERStridePlotButton_5         matlab.ui.control.Button
        STRIDEINTERVALPLOTPanel         matlab.ui.container.Panel
        IntervalStepsDropDown           matlab.ui.control.DropDown
        IntervalStepsDropDownLabel      matlab.ui.control.Label
        ALTERStridePlotButton_3         matlab.ui.control.Button
        STRIDEPLOTPanel                 matlab.ui.container.Panel
        eachCheckBox                    matlab.ui.control.CheckBox
        stdCheckBox                     matlab.ui.control.CheckBox
        meanCheckBox                    matlab.ui.control.CheckBox
        STRIDEPLOTButton                matlab.ui.control.Button
        FEATUREPLOTPanel                matlab.ui.container.Panel
        PhaseDropDown                   matlab.ui.control.DropDown
        PhaseDropDownLabel              matlab.ui.control.Label
        SmoothDropDown                  matlab.ui.control.DropDown
        SmoothDropDownLabel             matlab.ui.control.Label
        FeatureDropDown                 matlab.ui.control.DropDown
        FeatureDropDown_5Label          matlab.ui.control.Label
        FEATUREPLOTButton               matlab.ui.control.Button
        PLOTTESTPanel                   matlab.ui.container.Panel
        TIMEPLOTButton                  matlab.ui.control.Button
        BarTab                          matlab.ui.container.Tab
        FEATURESBARPLOTPanel            matlab.ui.container.Panel
        ClassSetColor                   matlab.ui.control.Button
        ClassListTable                  matlab.ui.control.Table
        BarWidthEditField               matlab.ui.control.NumericEditField
        BarFixedWidthCheckBox           matlab.ui.control.CheckBox
        BarEdgeCheckBox                 matlab.ui.control.CheckBox
        Panel_16                        matlab.ui.container.Panel
        BarPhaseDropDown                matlab.ui.control.DropDown
        PhaseDropDown_2Label            matlab.ui.control.Label
        ALTERNEWButton_9                matlab.ui.control.Button
        BarTypeDropDown                 matlab.ui.control.DropDown
        BarPlotTypeLabel                matlab.ui.control.Label
        GroupIndexDropDown              matlab.ui.control.DropDown
        ChangeColorLabel                matlab.ui.control.Label
        BarFeatureDropDown              matlab.ui.control.DropDown
        FeatureDropDown_2Label_5        matlab.ui.control.Label
        CHANGECOLORButton               matlab.ui.control.Button
        VarAlterPlotButton              matlab.ui.control.Button
        GroupDownButton                 matlab.ui.control.Button
        GroupRenameButton               matlab.ui.control.Button
        GroupUpButton                   matlab.ui.control.Button
        GroupDeleteButton               matlab.ui.control.Button
        GroupClearButton                matlab.ui.control.Button
        GroupAddButton                  matlab.ui.control.Button
        ClassDownButton_3               matlab.ui.control.Button
        ClassRenameButton               matlab.ui.control.Button
        ClassUpButton_3                 matlab.ui.control.Button
        ClassDeleteButton_3             matlab.ui.control.Button
        ClassClearButton_3              matlab.ui.control.Button
        ClassAddButton_3                matlab.ui.control.Button
        Panel_14                        matlab.ui.container.Panel
        GroupLabel                      matlab.ui.control.Label
        ClassLabel                      matlab.ui.control.Label
        GroupListBox                    matlab.ui.control.ListBox
        FitTab                          matlab.ui.container.Tab
        FITTINGANALYSISPanel            matlab.ui.container.Panel
        PhaseDropDown_2Label_4          matlab.ui.control.Label
        FitGainEditField                matlab.ui.control.NumericEditField
        FitPhaseDropDown                matlab.ui.control.DropDown
        PhaseDropDown_2Label_2          matlab.ui.control.Label
        ClassAddButton_5                matlab.ui.control.Button
        AddDataButton                   matlab.ui.control.Button
        FittingButton2                  matlab.ui.control.Button
        FittingMethodDropDown2          matlab.ui.control.DropDown
        FittingMethodDropDown1          matlab.ui.control.DropDown
        FittingButton1                  matlab.ui.control.Button
        AddLabelYButton                 matlab.ui.control.Button
        AddLabelXButton                 matlab.ui.control.Button
        FittingTable                    matlab.ui.control.Table
        ALTERNEWButton_10               matlab.ui.control.Button
        FitFeatureDropDown              matlab.ui.control.DropDown
        FeatureDropDown_2Label_6        matlab.ui.control.Label
        ClassDeleteButton_4             matlab.ui.control.Button
        ClassClearButton_4              matlab.ui.control.Button
        ClassAddButton_4                matlab.ui.control.Button
        SignifTab                       matlab.ui.container.Tab
        Panel_15                        matlab.ui.container.Panel
        SigPhaseDropDown                matlab.ui.control.DropDown
        PhaseDropDown_2Label_3          matlab.ui.control.Label
        VarAnaButton_5                  matlab.ui.control.Button
        VarAnaButton_4                  matlab.ui.control.Button
        VarAnaButton_3                  matlab.ui.control.Button
        VarAnaButton_2                  matlab.ui.control.Button
        VarAnaButton                    matlab.ui.control.Button
        NormalityAnaButton              matlab.ui.control.Button
        SigFeatureDropDown              matlab.ui.control.DropDown
        FeatureDropDown_2Label_7        matlab.ui.control.Label
        AddDataButton2_2                matlab.ui.control.Button
        GroupDataListBox                matlab.ui.control.ListBox
        ClassDownButton_4               matlab.ui.control.Button
        ClassUpButton_4                 matlab.ui.control.Button
        AddDataButton2                  matlab.ui.control.Button
        AddDataButton1                  matlab.ui.control.Button
        SigTable                        matlab.ui.control.Table
        DeleteGroupButton               matlab.ui.control.Button
        ClearGroupButton                matlab.ui.control.Button
        AddGroupButton                  matlab.ui.control.Button
        RespTab                         matlab.ui.container.Tab
        PhaseAnalysisPanel              matlab.ui.container.Panel
        UpdatePhaaseButton              matlab.ui.control.Button
        YDropDown                       matlab.ui.control.DropDown
        YDropDownLabel                  matlab.ui.control.Label
        XDropDown                       matlab.ui.control.DropDown
        XDropDownLabel                  matlab.ui.control.Label
        FPIPLOTButton_4                 matlab.ui.control.Button
        FPIPLOTButton_3                 matlab.ui.control.Button
        FPIPLOTPanel                    matlab.ui.container.Panel
        FPIPLOTButton_2                 matlab.ui.control.Button
        GaitCycleEditField              matlab.ui.control.NumericEditField
        GaitCycleEditFieldLabel         matlab.ui.control.Label
        ViewSwitch                      matlab.ui.control.Switch
        FPIPLOTButton                   matlab.ui.control.Button
        StepPLOTPanel                   matlab.ui.container.Panel
        BODEPLOTButton_6                matlab.ui.control.Button
        STEPPLOTButton_2                matlab.ui.control.Button
        STEPPLOTButton_3                matlab.ui.control.Button
        STEPPLOTButton_1                matlab.ui.control.Button
        FrequencyAnalysisPanel          matlab.ui.container.Panel
        CutOffFreqEditField             matlab.ui.control.NumericEditField
        CutOffFreqEditFieldLabel        matlab.ui.control.Label
        BODEPLOTButton_5                matlab.ui.control.Button
        BODEPLOTButton_4                matlab.ui.control.Button
        BODEPLOTButton_3                matlab.ui.control.Button
        BODEPLOTButton_2                matlab.ui.control.Button
        BODEPLOTButton_1                matlab.ui.control.Button
        CloorTab                        matlab.ui.container.Tab
        ColorMapsPanel                  matlab.ui.container.Panel
        ColorSetButton1_40              matlab.ui.control.Button
        ColorSetButton1_39              matlab.ui.control.Button
        ColorSetButton1_38              matlab.ui.control.Button
        ColorSetButton1_37              matlab.ui.control.Button
        ColorSetButton1_36              matlab.ui.control.Button
        ColorSetButton1_35              matlab.ui.control.Button
        ColorSetButton1_34              matlab.ui.control.Button
        ColorSetButton1_33              matlab.ui.control.Button
        ColorSetButton1_32              matlab.ui.control.Button
        ColorSetButton1_31              matlab.ui.control.Button
        ColorSetButton1_30              matlab.ui.control.Button
        ColorSetButton1_29              matlab.ui.control.Button
        ColorSetButton1_28              matlab.ui.control.Button
        ColorSetButton1_27              matlab.ui.control.Button
        ColorSetButton1_26              matlab.ui.control.Button
        ColorSetButton1_25              matlab.ui.control.Button
        ColorSetButton1_24              matlab.ui.control.Button
        ColorSetButton1_23              matlab.ui.control.Button
        ColorSetButton1_22              matlab.ui.control.Button
        ColorSetButton1_21              matlab.ui.control.Button
        ColorSetButton1_20              matlab.ui.control.Button
        ColorSetButton1_19              matlab.ui.control.Button
        ColorSetButton1_18              matlab.ui.control.Button
        ColorSetButton1_17              matlab.ui.control.Button
        ColorSetButton1_16              matlab.ui.control.Button
        ColorSetButton1_15              matlab.ui.control.Button
        ColorSetButton1_14              matlab.ui.control.Button
        ColorSetButton1_13              matlab.ui.control.Button
        ColorSetButton1_12              matlab.ui.control.Button
        ColorSetButton1_11              matlab.ui.control.Button
        ColorSetButton1_10              matlab.ui.control.Button
        ColorSetButton1_9               matlab.ui.control.Button
        ColorSetButton1_8               matlab.ui.control.Button
        ColorSetButton1_7               matlab.ui.control.Button
        ColorSetButton1_6               matlab.ui.control.Button
        ColorSetButton1_5               matlab.ui.control.Button
        ColorSetButton1_4               matlab.ui.control.Button
        ColorSetButton1_3               matlab.ui.control.Button
        ColorSetButton1_2               matlab.ui.control.Button
        ColorSetButton1                 matlab.ui.control.Button
        ListButton                      matlab.ui.control.Button
        SearchButton                    matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        VariableLabel                   matlab.ui.control.Label
        VariableListBox                 matlab.ui.control.ListBox
        Panel_2                         matlab.ui.container.Panel
        EditKey                         matlab.ui.control.EditField
        KeyVariableListBox              matlab.ui.control.ListBox
        DataLabel                       matlab.ui.control.Label
        DataListBox                     matlab.ui.control.ListBox
        BioMechTab                      matlab.ui.container.Tab
        ClassSetColor_3                 matlab.ui.control.Button
        ClassSetColor_2                 matlab.ui.control.Button
        TreadmillForceCalibrationPanel  matlab.ui.container.Panel
        ExportOneMOTButton              matlab.ui.control.Button
        CheckCOPButton                  matlab.ui.control.Button
        CheckGRFButton                  matlab.ui.control.Button
        TFRCalibStartEditField          matlab.ui.control.NumericEditField
        TFRCalibEndEditField            matlab.ui.control.NumericEditField
        TFLCalibStartEditField          matlab.ui.control.NumericEditField
        TFLCalibEndEditField            matlab.ui.control.NumericEditField
        PosXLabel_23                    matlab.ui.control.Label
        PosXLabel_22                    matlab.ui.control.Label
        PosXLabel_17                    matlab.ui.control.Label
        PosXLabel_16                    matlab.ui.control.Label
        DataPropertyPanel               matlab.ui.container.Panel
        GRFRateEditField                matlab.ui.control.NumericEditField
        GRFRateLabel                    matlab.ui.control.Label
        GRFTrigBiasEditField            matlab.ui.control.NumericEditField
        PosXLabel_24                    matlab.ui.control.Label
        GRFPositionMethodDropDown       matlab.ui.control.DropDown
        GRFPositionMethodDropDownLabel  matlab.ui.control.Label
        GRFSmoothLenEditField           matlab.ui.control.NumericEditField
        PosXLabel_9                     matlab.ui.control.Label
        QTMRateEditField                matlab.ui.control.NumericEditField
        PosXLabel_8                     matlab.ui.control.Label
        TreadmillOriginmmPanel          matlab.ui.container.Panel
        TOBiasLZEditField               matlab.ui.control.NumericEditField
        PosXLabel_7                     matlab.ui.control.Label
        TOBiasLYEditField               matlab.ui.control.NumericEditField
        PosXLabel_6                     matlab.ui.control.Label
        TOBiasLXEditField               matlab.ui.control.NumericEditField
        PosXLabel_5                     matlab.ui.control.Label
        TOBiasRZEditField               matlab.ui.control.NumericEditField
        PosXLabel_4                     matlab.ui.control.Label
        TOBiasRYEditField               matlab.ui.control.NumericEditField
        PosXLabel_3                     matlab.ui.control.Label
        TOBiasRXEditField               matlab.ui.control.NumericEditField
        PosXLabel_2                     matlab.ui.control.Label
        ExportMOTButton                 matlab.ui.control.Button
        ExportTRCButton                 matlab.ui.control.Button
        DeleteMATButton                 matlab.ui.control.Button
        DownMATButton                   matlab.ui.control.Button
        UpMATButton                     matlab.ui.control.Button
        AddMATButton                    matlab.ui.control.Button
        DeleteC3DButton                 matlab.ui.control.Button
        DownC3DButton                   matlab.ui.control.Button
        UpC3DButton                     matlab.ui.control.Button
        AddC3DButton                    matlab.ui.control.Button
        MATTable                        matlab.ui.control.Table
        C3DTable                        matlab.ui.control.Table
        BACKButton_3                    matlab.ui.control.Button
        GlobalSetTab                    matlab.ui.container.Tab
        FigPosYEditField                matlab.ui.control.NumericEditField
        PosYLabel                       matlab.ui.control.Label
        FigPosXEditField                matlab.ui.control.NumericEditField
        PosXLabel                       matlab.ui.control.Label
        FigHeightEditField              matlab.ui.control.NumericEditField
        HeightLabel                     matlab.ui.control.Label
        WidthText                       matlab.ui.control.Label
        FigWdithEditField               matlab.ui.control.NumericEditField
        XDensityDropDown                matlab.ui.control.DropDown
        XDensityDropDownLabel           matlab.ui.control.Label
        FontSizeDropDown                matlab.ui.control.DropDown
        FontSizeDropDownLabel           matlab.ui.control.Label
        LineWidthDropDown_2             matlab.ui.control.DropDown
        LineWidthDropDown_2Label        matlab.ui.control.Label
        FigureLabel                     matlab.ui.control.Label
        SetBalckColor_7                 matlab.ui.control.Button
        SetBalckColor_6                 matlab.ui.control.Button
        ProcessLabel                    matlab.ui.control.Label
        ColorsLabel                     matlab.ui.control.Label
        SetBalckColor_5                 matlab.ui.control.Button
        SetBalckColor_4                 matlab.ui.control.Button
        SetBalckColor_3                 matlab.ui.control.Button
        SetBalckColor_2                 matlab.ui.control.Button
        BACKButton_2                    matlab.ui.control.Button
        SetBalckColor                   matlab.ui.control.Button
    end


    properties (Access = private)
        data
        setting
        figureList
        userfig
        usersubfigmap
        userbar
        userpl
        bioMech
    end

    methods (Access = private)
        
        function updateVarTable(app)
            
            if strcmp(app.DataListBox.Value, 'NULL')
                app.VariableListBox.Items = {};
            elseif isempty(app.DataListBox.Value)
                app.VariableListBox.Items = {};
            else
                data_name = char(app.DataListBox.Value);
                if size(data_name,1) > 1
                    data_name = data_name(1,:);
                end
                eval(['app.VariableListBox.Items = app.data.' data_name '.name;']);
            end

        end
        
        function updateKeyVarTable(app)
            
            key = app.EditKey.Value;
            
            if strcmp(app.DataListBox.Value, 'NULL')
                app.KeyVariableListBox.Items = {};
            elseif isempty(app.DataListBox.Value)
                app.KeyVariableListBox.Items = {};
            elseif isempty(key)
                app.KeyVariableListBox.Items = {};
            elseif strcmp(key, 'NULL')
                app.KeyVariableListBox.Items = {};
            else
                data_name = char(app.DataListBox.Value);
                if size(data_name,1) > 1
                    data_name = data_name(1,:);
                end
                eval(['var_name = app.data.' data_name '.name;']);
                find_key = strfind(var_name, key);
                key_var_num = 1;
                key_var_name = {};
                for var_index = 1:length(var_name)
                    if ~isempty(find_key{var_index})
                        key_var_name(key_var_num) = var_name(var_index);
                        key_var_num = key_var_num + 1;
                    end
                end
                app.KeyVariableListBox.Items = key_var_name;
                app.KeyVariableListBox.Value = {};
                app.VariableListBox.Value = {};
            end

        end
        
        function updateGroupData(app)
            if ~isempty(app.data.sig.selectedGroup)
                eval(['groupdata = app.data.sig.' app.data.sig.selectedGroup ';']);
                
                GroupDataList = {};
                for i=1:length(groupdata)
                    GroupDataList{i} = num2str(groupdata(i));
                end
                
                app.GroupDataListBox.Items = GroupDataList;
            else
                app.GroupDataListBox.Items = {};
            end
        end
        
        function loadDspaceData(app)
            [file_all,path,indx] = uigetfile('*.mat','Select the dSPACE MAT file','MultiSelect', 'on');
            
            if indx == 1
                % 加载数据
                if ischar(file_all)
                    file_all = {file_all};
                end
                for file_index = 1:length(file_all)
                    file = file_all{file_index};
                    data_name = ['dspace.' file(1:end-4)];
                    
                    % 提取数据中的第一个结构体，若存在多个结构体则返回报错信息
                    LoadData = load(fullfile(path, file));
                    DataFields = fieldnames(LoadData);
                    if length(DataFields) > 1
                        errordlg([file 'has more than one data. Please check the data.'])
                        return
                    end
                    eval(['DspaceData = LoadData.' DataFields{1} ';']);

                    % 数据转换
                    if isempty(app.data.name)
                        app.data.name = {data_name};
                    else
                        app.data.name(end+1) = {data_name};
                    end
                    
                    app.DataListBox.Items = app.data.name;
                    
                    % 添加时间数据
                    data_time = DspaceData.X(1).Data;
                    data_length = length(data_time);
                    var_name = 'time';name_temp = {var_name};
                    eval(['app.data.' data_name '.' var_name '.origin = data_time;']);
                    % 添加每一步的时间数据 - v6.27
                    var_name = 'step_time'; name_temp(end+1,1) = {var_name};
                    eval(['app.data.' data_name '.' var_name '.origin = data_time;']); % 后续处理
                    
                    for var_index = 1:length(DspaceData.Y)
                        var_data = DspaceData.Y(var_index).Data;
                        
                        if length(var_data) == data_length
                            
                            var_name = DspaceData.Y(var_index).Name;
                            
                            if strcmp(var_name,'Out1')
                                var_path = DspaceData.Y(var_index).Path;
                                cut_index = strfind(var_path,'/');
                                var_name = var_path((cut_index(end)+1):end);
                            end
                            
                            var_name = strrep(var_name,'[','_');
                            var_name = strrep(var_name,']','');
                            var_name = strrep(var_name,' ','');
                            
                            while ismember(1,strcmp(name_temp,var_name))
                                var_name = [var_name '_X'];
                            end
                            name_temp(end+1,1) = {var_name};
                            eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                        end
                    end
                    
                    
                    eval(['app.data.' data_name '.name = name_temp;']);
                    eval(['app.data.' data_name '.processFlag = false;']);
                    app.updateVarTable();
                end
            end
     
                end
        
        function loadPredSimData(app)
            % 选择文件
            [file_all,path,indx] = uigetfile('*.mat','Select the PredSim mat file','MultiSelect', 'on');
            
  
            if indx == 1
                if ischar(file_all)
                    file_all = {file_all};
                end
                for file_index = 1:length(file_all)
                    % 加载数据
                    file = file_all{file_index};
                    data_name = ['predsim.' file(1:end-4)];
                    
                    load(fullfile(path, file));
%                     eval(['PredSimData = ' data_name(9:end) ';']);
                    namelist = fieldnames(Results_all);
                    eval(['PredSimData = Results_all.' namelist{1} ';']);
                    colheaders = Results_all.colheaders;
                    
                    % 数据转换
                    if isempty(app.data.name)
                        app.data.name = {data_name};
                    else
                        app.data.name(end+1) = {data_name};
                    end
                    
                    app.DataListBox.Items = app.data.name;
                    eval(['app.data.' data_name ' = {};']);
                    eval(['app.data.' data_name '.name = {};']);
                    
                    
                    var_name_all = fieldnames(PredSimData);
                    remove_var_list = {'Qs_opt','Qdots_opt','Ts_opt','GRFs_opt','Acts_opt','stats','Power_opt','Tau_pass_opt','FTtilde','AssistTau','AssistPower','Muscle_meta'};
                    var_name_others = {};
                    for index_var = 1:length(var_name_all)
                        if sum(strcmp(var_name_all{index_var},remove_var_list)) == 0
                            var_name_others(end+1,1) = var_name_all(index_var);
                        end
                    end
                    
                    grfs = colheaders.GRF;
                    statesNames = {'Qs_opt','Qdots_opt','Ts_opt'};
                    states = {'Qs','Qdots','Tau'};
                    MusleStatesName = {'Acts_opt'};
                    muscleStates = {'Act'};
                    joints = colheaders.joints;
                    muscles = colheaders.muscles;
                    
                    withAssistance = 0;
                    if sum(strcmp(fieldnames(colheaders),'assist'))
                        assistJoints = colheaders.assist;
                        withAssistance = 1;
                    end
                    
                    if sum(strcmp('Tau_pass_opt',var_name_all))
                        statesNames(end+1) = {'Tau_pass_opt'};
                        states(end+1) = {'TPass'};
                    end
                    if sum(strcmp('Power_opt',var_name_all))
                        statesNames(end+1) = {'Power_opt'};
                        states(end+1) = {'Power'};
                    end
                    if sum(strcmp('FTtilde',var_name_all))
                        MusleStatesName(end+1) = {'FTtilde'};
                        muscleStates(end+1) = {'MTF'};
                    end
                    if sum(strcmp('Muscle_meta',var_name_all))
                        MusleStatesName(end+1) = {'Muscle_meta'};
                        muscleStates(end+1) = {'MH'};
                    end
                    
                    
                    var_index = 1;
                    var_name = 'time';
                    data_length = size(PredSimData.Qs_opt,1);
                    if sum(strcmp(var_name_all,'tf_opt')) == 0
                        var_data = linspace(0,1,data_length);
                    else
                        var_data = linspace(0,PredSimData.tf_opt*2,data_length);
                    end
                    eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                    eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                    var_index = var_index + 1;
                    
                    for index_grfs = 1:length(grfs)
                        var_name = ['GRF_',grfs{index_grfs}];
                        var_data = PredSimData.GRFs_opt(:,index_grfs)';
                        eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                        eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                        var_index = var_index + 1;
                    end
                    
                    for index_states = 1:length(statesNames)
                        if strcmp('Tau_pass_opt',statesNames{index_states})
                            state = states{index_states};
                            eval(['num_dofs = size(PredSimData.',statesNames{index_states}, ',2);']); % Tau_pass has only 23 dofs
                            for index_joints = 1:num_dofs
                                joint = joints{index_joints+6};
                                var_name = [state,'_',joint];
                                eval(['var_data = PredSimData.',statesNames{index_states},'(:,index_joints)'';']);
                                eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                                eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                                var_index = var_index + 1;
                            end
                        else
                            state = states{index_states};
                            eval(['num_dofs = size(PredSimData.',statesNames{index_states}, ',2);']); % Tau_pass has only 23 dofs
                            for index_joints = 1:num_dofs
                                joint = joints{index_joints};
                                var_name = [state,'_',joint];
                                eval(['var_data = PredSimData.',statesNames{index_states},'(:,index_joints)'';']);
                                eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                                eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                                var_index = var_index + 1;
                            end
                        end
                    end
                    
                    for index_states = 1:length(MusleStatesName)
                        muscleState = muscleStates{index_states};
                        for index_muscles = 1:length(muscles)
                            var_name = [muscleState,'_',muscles{index_muscles}];
                            eval(['var_data = PredSimData.' MusleStatesName{index_states} '(:,index_muscles)'';']);
                            eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                            eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                            var_index = var_index + 1;
                        end
                    end
                    
                    if withAssistance == 1
                        if sum(strcmp(var_name_all, 'AssistTau'))
                            assistTau_data = PredSimData.AssistTau;
                            for assist_index = 1:size(assistTau_data,2)
                                var_name = ['AstTau_',assistJoints{assist_index}];
                                var_data = assistTau_data(:,assist_index)';
                                eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                                eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                                var_index = var_index + 1;
                            end
                        end
                        if sum(strcmp(var_name_all, 'AssistPower'))
                            assistPower_data = PredSimData.AssistPower;
                            for assist_index = 1:size(assistPower_data,2)
                                var_name = ['AstPower_',assistJoints{assist_index}];
                                var_data = assistPower_data(:,assist_index)';
                                eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                                eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                                var_index = var_index + 1;
                            end
                        end
                    end
                    
                    for index_var = 1:length(var_name_others)
                        var_name = var_name_others{index_var};
                        eval(['var_data = PredSimData.',var_name,''';']);
                        if length(var_data) == 1
                            eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                            eval(['app.data.' data_name '.' var_name '.origin = var_data*ones(1,data_length);']);
                            var_index = var_index + 1;
                        elseif length(var_data) == data_length
                            eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                            eval(['app.data.' data_name '.' var_name '.origin = var_data;']);
                            var_index = var_index + 1;
                        end
                    end
                    
                    eval(['app.data.' data_name '.processFlag = false;']);
                    app.updateVarTable();
                end
            end
            
        end
        
        function loadOpenSimData(app)
             
            % 选择文件
            [file_all,path,indx] = uigetfile({'*.sto;*.mot'},'Select the OpenSim STO or MOT file','MultiSelect', 'on');
            
             
            if indx == 1
                if ischar(file_all)
                    file_all = {file_all};
                end
                for file_index = 1:length(file_all)
                    % 加载数据
                    file = file_all{file_index};
                    data_name = ['opensim.' file(1:end-4)];
                    
                    file_path = fullfile(path, file);
                    fp = fopen(file_path, 'rt');
                    
                    n_header = 0;
                    while ~strcmp(fgetl(fp), 'endheader')
                        n_header = n_header + 1;
                    end
                    
                    delimmiter = '	';
                    filename = file_path;
                    nheaderlines = n_header + 2;
                    DataStru = importdata(filename,delimmiter,nheaderlines);
                    OpenSimData.name = reshape(DataStru.colheaders,[length(DataStru.colheaders) 1]);
                    OpenSimData.data = DataStru.data;
                    
                    % 数据转换
                    if isempty(app.data.name)
                        app.data.name = {data_name};
                    else
                        app.data.name(end+1) = {data_name};
                    end
                    
                    app.DataListBox.Items = app.data.name;
                    
                    for var_index = 1:length(OpenSimData.name)
                        var_name = char(OpenSimData.name(var_index));
                        var_name = strrep(var_name,'/jointset/','');
                        var_name = strrep(var_name,'/forceset/','');
                        var_name = strrep(var_name,'.','_');
                        var_name = strrep(var_name,'-','_');
                        var_name = strrep(var_name,'/','_');
                        eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                        eval(['app.data.' data_name '.' var_name '.origin = OpenSimData.data(:,var_index)'';']);
                    end
                    
                    eval(['app.data.' data_name '.processFlag = false;']);
                    app.updateVarTable();
                end
            end
        end
        
        function loadSconeData(app)
            % 选择文件
            [file_all,path,indx] = uigetfile('*.sto','Select the SCONE STO file','MultiSelect', 'on');

            if indx == 1
                if ischar(file_all)
                    file_all = {file_all};
                end
                for file_index = 1:length(file_all)
                    file = file_all{file_index};
                    data_name = ['scone.' file(1:end-4)];
                    
                    % 加载数据
                    delimmiter = '	';
                    filename = fullfile(path, file);
                    nheaderlines = 7;
                    DataStru = importdata(filename,delimmiter,nheaderlines);
                    SconeData.name = reshape(DataStru.colheaders,[length(DataStru.colheaders) 1]);
                    SconeData.data = DataStru.data;
                    
                    % 数据转换
                    if isempty(app.data.name)
                        app.data.name = {data_name};
                    else
                        app.data.name(end+1) = {data_name};
                    end
                    
                    app.DataListBox.Items = app.data.name;
                    
                    for var_index = 1:length(SconeData.name)
                        var_name = char(SconeData.name(var_index));
                        var_name = strrep(var_name,'.','_');
                        var_name = strrep(var_name,'-','_');
                        eval(['app.data.' data_name '.name(var_index,1) = {var_name};']);
                        eval(['app.data.' data_name '.' var_name '.origin = SconeData.data(:,var_index)'';']);
                    end
                    
                    eval(['app.data.' data_name '.processFlag = false;']);
                    app.updateVarTable();
                end
            end
        end
        
        
        function loadHistory(app)
            [file_all,path,indx] = uigetfile('*.mat','Select the dSPACE MAT file','MultiSelect', 'on');
            
            if indx == 1
                % 加载数据
                if ischar(file_all)
                    file_all = {file_all};
                end
                for file_index = 1:length(file_all)
                    
                    file = file_all{file_index};
                    
                    history = load(fullfile(path, file));
                    
                    for data_index = 1:length(history.data.name)
                        
                        if isempty(app.data.name)
                            app.data.name = history.data.name(data_index);
                        else
                            app.data.name(end+1) = history.data.name(data_index);
                        end
                        
                        eval(['app.data.' history.data.name{data_index} ' = history.data.' history.data.name{data_index} ';']);
 
                    end

                end
                
                app.DataListBox.Items = app.data.name;
                app.updateVarTable();
                
            end
        end
        %% 通过状态信号提取步态切换时间
        function stride_index = getStrideIndexByState(app,state_temp)
            
            stride_index = [];
            num = 0;
            for index_i=1:length(state_temp)-1
                if state_temp(index_i) == 0 && state_temp(index_i+1) == 1
                    num = num + 1;
                    stride_index(num) = index_i;
                end
            end
            
        end
        %% 通过时间序列或缺步态切换时间
        function stride_index = getStrideIndexByTime(app, step_time, time_data)
            
            stride_index = zeros(length(step_time),1);
            for index_i = 1:length(step_time)
                index_all = find(time_data >= step_time(index_i));
                stride_index(index_i) = index_all(1);
            end
            
        end
        %% 对于步态的时间信号，需计算步态分割后步态时间(每一步从0开始)，以及步态时长(最大值)
        function  data_time = processTime(app, stride_index, time)
            nSteps = length(stride_index) - 1; % 因为是上升沿信号
            data_time = {};
            data_time.origin = time; data_time.origin_step = time; data_time.step_split = {}; data_time.step_time = [];
            for step_indx = 1:nSteps
                step_inx_list  = stride_index(step_indx):stride_index(step_indx+1);
                step_inx_start = stride_index(step_indx); step_inx_end = stride_index(step_indx+1);
                data_time.origin_step(step_inx_list) = data_time.origin(step_inx_list) - data_time.origin(step_inx_start);
                data_time.step_time(step_indx) = data_time.origin(step_inx_end) - data_time.origin(step_inx_start);
                data_time.step_split{step_indx} = data_time.origin_step(step_inx_list)/data_time.step_time(step_indx);
            end
            step_inx_list = stride_index(end):length(data_time.origin);step_inx_start = stride_index(end);
            data_time.origin_step(step_inx_list) = data_time.origin(step_inx_list) - data_time.origin(step_inx_start);

        end
        %% 获取步态相位设定
        function [phase,PhT] = getGaitPhsae(app)

            stride_percent = app.setting.gaitPecent/100; lenGait = length(stride_percent); %len_stp = length(stride_percent)/10;
            phase = {'whole','stance','swing','st1','st2','st3','st4','sw1','sw2','sw3','ph1','ph2','ph3','ph4'};

            OT_Index = floor(app.GaitCycleEditField2.Value / 100 * (lenGait-1))+1;
            HR_Index = floor(app.GaitCycleEditField3.Value / 100 * (lenGait-1))+1;
            OI_Index = floor(app.GaitCycleEditField4.Value / 100 * (lenGait-1))+1;
            TO_Index = floor(app.GaitCycleEditField5.Value / 100 * (lenGait-1))+1;
            FA_Index = floor(app.GaitCycleEditField6.Value / 100 * (lenGait-1))+1;
            TV_Index = floor(app.GaitCycleEditField7.Value / 100 * (lenGait-1))+1;
            
            FH1Start = floor(app.PhaseStartEditField1.Value / 100 * (lenGait-1))+1;
            FH2Start = floor(app.PhaseStartEditField2.Value / 100 * (lenGait-1))+1;
            FH3Start = floor(app.PhaseStartEditField3.Value / 100 * (lenGait-1))+1;
            FH4Start = floor(app.PhaseStartEditField4.Value / 100 * (lenGait-1))+1;
            FH1End = floor(app.PhaseEndEditField1.Value / 100 * (lenGait-1))+1;
            FH2End = floor(app.PhaseEndEditField2.Value / 100 * (lenGait-1))+1;
            FH3End = floor(app.PhaseEndEditField3.Value / 100 * (lenGait-1))+1;
            FH4End = floor(app.PhaseEndEditField4.Value / 100 * (lenGait-1))+1;

            PhT = [1,       1,        TO_Index, 1,        OT_Index, HR_Index, OI_Index, TO_Index, FA_Index, TV_Index, FH1Start,FH2Start,FH3Start,FH4Start;
                   lenGait, TO_Index, lenGait,  OT_Index, HR_Index, OI_Index, TO_Index, FA_Index, TV_Index, lenGait,  FH1End,  FH2End,  FH3End,  FH4End];

        end
        %% 处理单个dSPACE的实验数据
        function  data_process = processData(app, stride_index_temp, time_temp, data_temp)
            
            data_temp = double(data_temp);
            data_process.origin = data_temp;

            if length(time_temp.origin) ~= length(data_temp)
                errordlg('data length is unequal','error');
                return
            end
            
            time  = time_temp.origin; stepTimeList = time_temp.step_split; gaitTime = time_temp.step_time;

            stride_index = stride_index_temp; nStep = length(stride_index) - 1;
            stride_percent = app.setting.gaitPecent/100; lenGait = length(stride_percent); % len_stp = length(stride_percent)/10;
            ShiftIndex = floor(app.StrideShiftEditField.Value / 100 * lenGait);
            if ShiftIndex < 1
                ShiftIndex = 1;
            end

            data_stride = zeros(nStep,lenGait); data_stride_pos = zeros(nStep,lenGait); data_stride_neg = zeros(nStep,lenGait);

            for step_indx = 1:nStep
                step_idx_list  = stride_index(step_indx):stride_index(step_indx+1);
                step_idx_start = stride_index(step_indx); step_idx_end = stride_index(step_indx+1);
                stepTime = (time(step_idx_list) - time(step_idx_start))/(time(step_idx_end) - time(step_idx_start)); % stepTime = stepTimeList{step_indx};
                data_interp = interp1(stepTime, data_temp(step_idx_list), stride_percent);
                % 其他几种数据插值方法
                % F = griddedInterpolant(stepTime, data_temp(step_idx_list)); data_interp = F(stride_percent);
                % data_interp = spline(stepTime, data_temp(step_idx_list), stride_percent);
                % data_interp = resample(data_temp(step_idx_list), lenGait, length(step_idx_list));

                data_interp = [data_interp(end-ShiftIndex+1:end),data_interp(1:end-ShiftIndex)];
                data_stride(step_indx,:) = data_interp;
            end
            
            data_process.stride.each = data_stride;
            data_process.stride.mean = mean(data_stride,1);
            if nStep == 1
                data_process.stride.std = zeros(1,lenGait);
            else
                data_process.stride.std = std(data_stride,1);
            end

        end

        function data_process = computeDataFeature(app, data_temp, gaitTime, remove_list)

            [phase,PhT] = app.getGaitPhsae();
            features = {'rms', 'mean','max','min','pt','pos_mean','neg_mean'};
            
            data_process = data_temp;
            data_stride = data_temp.stride.each;
            data_stride(remove_list,:) = [];
            gaitTime(remove_list) = [];
            data_stride_pos = (data_stride>0).*data_stride;
            data_stride_neg = (data_stride<0).*data_stride;
            
            data_process.stride.each = data_stride;
            data_process.stride.mean = mean(data_stride,1);
            data_process.stride.std = std(data_stride,1);

            for f_index = 1:length(features)
                for ph_index = 1:length(phase)
                    if max(strcmp({'rms', 'mean'},features{f_index}))
                        eval(['feature_data = ' features{f_index} '(data_stride(:,PhT(1,ph_index):PhT(2,ph_index)),2)''.*gaitTime;']);
                    end
                    if max(strcmp({'max','min'},features{f_index}))
                        eval(['feature_data = ' features{f_index} '(data_stride(:,PhT(1,ph_index):PhT(2,ph_index))'')'';']);
                    end
                    if max(strcmp({'pt'},features{f_index}))
                        eval(['[~,feature_data] = max(data_stride(:,PhT(1,ph_index):PhT(2,ph_index))'');']);
                        feature_data = (feature_data' + PhT(1,ph_index))/10;
                    end
                    if max(strcmp({'pos_mean'},features{f_index}))
                        eval(['feature_data = mean(data_stride_pos(:,PhT(1,ph_index):PhT(2,ph_index)),2)''.*gaitTime;']);
                    end
                    if max(strcmp({'neg_mean'},features{f_index}))
                        eval(['feature_data = mean(data_stride_neg(:,PhT(1,ph_index):PhT(2,ph_index)),2)''.*gaitTime;']);
                    end
                    if ph_index > 1
                        feature_name = [features{f_index}, '_', phase{ph_index}];
                    else
                        feature_name = features{f_index};
                    end
                    eval(['data_process.' feature_name '.each = feature_data;']);
                    
                    ffeatures = {'mean','max','min','std'};
                    for ff_index = 1:length(ffeatures)
                        eval(['data_process.' feature_name '.' ffeatures{ff_index} ' = ' ffeatures{ff_index} '(feature_data);']);
                    end
                end
            end
        end

        %% 处理多组实验数据
        function processMultiData(app, data_name_list)
            tic;
            for j = 1:size(data_name_list,1)
                data_name_whole = strrep(data_name_list(j,:), ' ', '');         % 处理数据多选时的空格问题
                data_name_split = strsplit(data_name_whole,'.');                % 分离数据类型前缀(dspace)和数据名称

                app.DataListBox.Value = {data_name_whole}; pause(0.01);
                app.updateVarTable();

                if strcmp(data_name_split{1}, 'dspace')
                    data_name = data_name_split{2};
                    name_temp = app.data.dspace.(data_name).name;
                    if ismember(1,strcmp(name_temp, 'state'))    %  仅支持处理单侧数据
                        eval(['state_data = app.data.' data_name_whole '.state.origin;']);
                        % 获取步态划分信号
                        stride_index = app.getStrideIndexByState(state_data);
                        % 首先提取并处理时间信号
                        time = app.data.dspace.(data_name).time.origin;
                        data_time = app.processTime(stride_index, time);
                        % 将每一步的时间数据重新赋值
                        app.data.dspace.(data_name).step_time.origin = data_time.origin_step;

                        for i = 1:length(name_temp)
                            app.VariableListBox.Value = name_temp(i); pause(0.001);
                            if strcmp(name_temp{i},'time')
                                continue;
                            end
                            data_temp = app.data.dspace.(data_name).(name_temp{i}).origin;
                            app.data.dspace.(data_name).(name_temp{i}) = app.processData(stride_index, data_time, data_temp);
                            app.data.dspace.(data_name).(name_temp{i}) = app.computeDataFeature(app.data.dspace.(data_name).(name_temp{i}),data_time.step_time,[]);
                        end
                    else
                        errordlg([data_name, '无state信号/双侧数据暂不支持'],'error');
                        break;
                    end
                elseif strcmp(data_name_split{1}, 'opensim')
                    [file,path,indx] = uigetfile('*.mat','Select the Step Initial Time file (contain a step variable)');
                    if indx == 1
                        Step = load(fullfile(path, file));
                        if isfield(Step,'step')
                            step_initial_time = Step.step;
                            eval(['data_time = app.data.' data_name_whole '.time.origin;']);
                            stride_index = app.getStrideIndexByTime(step_initial_time, data_time);
                            for i = 1:length(name_temp)
                                eval(['data_temp = app.data.' data_name_whole '.' char(name_temp(i)) '.origin;']);
                                eval(['app.data.' data_name_whole '.' name_temp{i} ' = app.processData(stride_index, data_time, data_temp);']);
                            end
                        else
                            errordlg('no step variable','error');
                        end
                    end
                elseif strcmp(data_name_split{1}, 'predsim')
%                     app.reArrayPredSimData(data_name);
                    eval(['data_time = app.data.' data_name_whole '.time.origin;']);
                    stride_index = [1,length(data_time)];
                    for i = 1:length(name_temp)
                        app.VariableListBox.Value = name_temp(i);pause(0.001);
                        eval(['data_temp = app.data.' data_name_whole '.' char(name_temp(i)) '.origin;']);
                        eval(['app.data.' data_name_whole '.' char(name_temp(i)) ' = app.processData(stride_index, data_time, data_temp);']);
                    end
                elseif strcmp(data_name_split{1}, 'scone')
                    if ismember(1,strcmp(name_temp, 'exp_stride_state_r'))
                        eval(['state_data = app.data.' data_name_whole '.exp_stride_state_r.origin;']);
                        eval(['data_time = app.data.' data_name_whole '.time.origin;']);
                        stride_index = app.getStrideIndexByState(state_data);   
                        for i = 1:length(name_temp)
                            eval(['data_temp = app.data.' data_name_whole '.' char(name_temp(i)) '.origin;']);
                            eval(['app.data.' data_name_whole '.' char(name_temp(i)) ' = app.processData(stride_index, data_time, data_temp);']);
                        end
                    else
                        errordlg('no exp_stride_state_r variable','error');
                        return
                    end
                else
                    errordlg('error data form','error');
                    return
                end
                eval(['app.data.' data_name_whole '.processFlag = true;']);
                app.updateVarTable();
            end
            msgbox('Process Completed');
            disp(['time consumption is ' num2str(toc)]);
            
        end
        
        %% 处理单个数据的缩放问题
        function scaleOneVar(app, data_name, var_name, norm_gain)
            
            if norm_gain ~= 0
                gain = 1/norm_gain;
            else
                gain = 1;
            end

            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            
            data_name_split = strsplit(data_name,'.');
            eval(['name_temp = app.data.' data_name '.name;']);
            
            stride_percent = app.setting.gaitPecent/100;
            lenGait = length(stride_percent);
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            else
                [phase,PhT] = app.getGaitPhsae();
                features = {'rms', 'mean','max','min','pt','pos_mean','neg_mean'};
                
                eval(['data_temp = app.data.' data_name '.' var_name ';']);

                data_temp.stride.each = gain*data_temp.stride.each;
                data_temp.stride.mean = gain*data_temp.stride.mean;
                data_temp.stride.std = abs(gain)*data_temp.stride.std;
                
                for f_index = 1:length(features)
                    for ph_index = 1:length(phase)
                        if ph_index > 1
                            feature_name = [features{f_index}, '_', phase{ph_index}];
                        else
                            feature_name = features{f_index};
                        end

                        eval(['feature_old = data_temp.' feature_name '.each;']);

                        if max(strcmp({'mean','max','min','pos_mean','neg_mean'}, features{f_index}))
                            feature_data = gain*feature_old;
                        end
                        if max(strcmp({'rms'}, features{f_index}))
                            feature_data = abs(gain)*feature_old;
                        end

                        eval(['data_temp.' feature_name '.each = feature_data;']);
                        
                        ffeatures = {'mean','max','min','std'};
                        for ff_index = 1:length(ffeatures)
                            eval(['data_temp.' feature_name '.' ffeatures{ff_index} ' = ' ffeatures{ff_index} '(feature_data);']);
                        end
                    end
                end
                
                eval(['app.data.' data_name '.' var_name ' = data_temp;'])
                        
            end
        end
        %% 处理单个数据的偏置问题
        function biasOneVar(app, data_name, var_name, norm_bias)

            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            app.data.dspace.(data_name).(var_name).origin = app.data.dspace.(data_name).(var_name).origin - norm_bias;
            app.data.dspace.(data_name).(var_name).stride.mean = app.data.dspace.(data_name).(var_name).stride.mean - norm_bias;
            app.data.dspace.(data_name).(var_name) = app.computeDataFeature(app.data.dspace.(data_name).(var_name),data_time.step_time,[]);

        end
        
        function reArrayPredSimData(app, data_name)
            
            eval(['grf_r = app.data.' data_name '.grf_y_r.origin;']);
            eval(['name_temp = app.data.' data_name '.name;']);
            split_index = 1;
            
            for i = 1:length(grf_r)-5
                if grf_r(i) <= 10 && grf_r(i+5) >= 45
                    split_index = i - floor(length(grf_r)/50);
                    if split_index < 1
                        split_index = 1;
                    end
                    break;
                end
            end
            
            for i = 1:length(name_temp)
                if ~strcmp(name_temp(i), 'time')
                    eval(['app.data.' data_name '.' char(name_temp(i)) '.origin = app.data.' data_name '.' char(name_temp(i)) '.origin([split_index:end, 1:split_index-1]);']);
                end
            end
            
        end
        
        function upColorMap(app)
            
            colorname = app.ColorMapDropDown.Value;
            colordata = app.othercolor(colorname,0);
            x = [0; 0; 10; 10];
            y = [0; 1;  1;  0];
            axis(app.ColorMapAxes,[0,10,0,1]);
            colormap(app.ColorMapAxes, colordata);
            c = [0,0,1,1];
            patch(app.ColorMapAxes,x,y,c);
            
        end
        
        function c = othercolor(app, name, len)
            
            colordata = app.setting.ColorData;
            
            if isempty(fieldnames(colordata))
                c = [];
            else
                if len <= 0
                    c = colordata.(name);
                else
                    c = interp1(linspace(0,1,size(colordata.(name),1)),colordata.(name),linspace(0,1,len),'PCHIP');
                    c(c<0) = 0;
                    c(c>1) = 1;
                end
            end
            
        end
        
        function moveui(app,uiname,x,y,speedx,speedy)
            
            
            pos_x_this = 0;
            pos_y_this = 0;
            dir_x = 1;
            dir_y = 1;
            
            eval(['pos_x_this = ' uiname '.Position(1);']);
            eval(['pos_y_this = ' uiname '.Position(2);']);
            
            if pos_x_this > x
                dir_x = -1;
            end
            if pos_y_this > y
                dir_y = -1;
            end
            
            while (pos_x_this ~= x) || pos_y_this ~= y
                if pos_x_this ~= x
                    if abs(pos_x_this-x) <= speedx
                        pos_x_this = x;
                    else
                        pos_x_this = pos_x_this + dir_x*speedx;
                    end
                end
                if pos_y_this ~= y
                    if abs(pos_y_this-y) <= speedy
                        pos_y_this = y;
                    else
                        pos_y_this = pos_y_this + dir_y*speedy;
                    end
                end
                eval([uiname '.Position(1) = pos_x_this;']);
                eval([uiname '.Position(2) = pos_y_this;']);
                pause(0.02)
%                 disp(pos_y_this);
            end
        end
        
        
        function SetBackGroundSolor(app,Colors)
            % 读取颜色参数
            app.setting.backcolorset.defult = Colors;
            % 设置主界面的背景色
            app.MainTab.BackgroundColor = Colors(1,:);
            app.AnaDataTab.BackgroundColor = Colors(1,:);
            app.GlobalSetTab.BackgroundColor = Colors(1,:);
            
            app.BACKButton_2.BackgroundColor = Colors(1,:);
            app.BACKButton.BackgroundColor = Colors(1,:);
            app.SearchButton.BackgroundColor = Colors(1,:);
            app.ListButton.BackgroundColor = Colors(2,:);
            app.Panel_3.BackgroundColor = Colors(1,:);
            app.Panel_2.BackgroundColor = Colors(1,:);
            
            app.ProcessAnalysisButton.BackgroundColor = Colors(2,:);
            app.GlobalSettingButton.BackgroundColor = Colors(2,:);
            app.LoadTabButton.BackgroundColor = Colors(2,:);
            
            % 设置切换按钮的背景色
            app.ToolButtonGroup.BackgroundColor = Colors(1,:);
            app.PlotButtonGroup.BackgroundColor = Colors(1,:);
            for i = 1:length(app.ToolButtonGroup.Children)
                app.ToolButtonGroup.Children(i).BackgroundColor = Colors(1,:);
            end
            for i = 1:length(app.PlotButtonGroup.Children)
                app.PlotButtonGroup.Children(i).BackgroundColor = Colors(1,:);
            end
            app.LoadTabButton.BackgroundColor = Colors(2,:);
            app.PLOTButton.BackgroundColor = Colors(2,:);
            % 设置工具栏的背景色
            for i = 1:length(app.ToolTabGroup.Children)
                if isprop(app.ToolTabGroup.Children(i),'BackgroundColor')
                    app.ToolTabGroup.Children(i).BackgroundColor = Colors(2,:);
                end
                for j = 1:length(app.ToolTabGroup.Children(i).Children)
                    if isprop(app.ToolTabGroup.Children(i).Children(j),'BackgroundColor')
                        if app.ToolTabGroup.Children(i).Children(j).Type == "uibutton" || app.ToolTabGroup.Children(i).Children(j).Type == "uidropdown"
                            app.ToolTabGroup.Children(i).Children(j).BackgroundColor = 0.5*Colors(1,:)+0.5*Colors(2,:);
                        else
                            app.ToolTabGroup.Children(i).Children(j).BackgroundColor = Colors(2,:);
                        end
                    end
                end
            end
            % 设置绘图栏的背景色
            for i = 1:length(app.PlotGroup.Children)
                if isprop(app.PlotGroup.Children(i),'BackgroundColor')
                    app.PlotGroup.Children(i).BackgroundColor = 0.5*Colors(1,:)+0.5*Colors(2,:);
                end
                for j = 1:length(app.PlotGroup.Children(i).Children)
                    if isprop(app.PlotGroup.Children(i).Children(j),'BackgroundColor')
                        app.PlotGroup.Children(i).Children(j).BackgroundColor = Colors(2,:);
                    end
                    for k = 1:length(app.PlotGroup.Children(i).Children(j).Children)
                        if isprop(app.PlotGroup.Children(i).Children(j).Children(k),'BackgroundColor')
                            if app.PlotGroup.Children(i).Children(j).Children(k).Type == "uibutton" || app.PlotGroup.Children(i).Children(j).Children(k).Type == "uidropdown"
                                app.PlotGroup.Children(i).Children(j).Children(k).BackgroundColor = 0.5*Colors(1,:)+0.5*Colors(2,:);
                            else
                                app.PlotGroup.Children(i).Children(j).Children(k).BackgroundColor = Colors(2,:);
                            end
                        end
                    end
                end
            end
            
            % 设置Table背景色
            app.FittingTable.BackgroundColor = [1 1 1];
            app.SigTable.BackgroundColor = [1 1 1];
            app.GroupDataListBox.BackgroundColor = [1 1 1];
            % 设置数据/变量Box背景色
            app.DataListBox.BackgroundColor = Colors(3,:);
            app.KeyVariableListBox.BackgroundColor = Colors(3,:);
            app.VariableListBox.BackgroundColor = Colors(3,:);
            app.DataListBox.FontColor = Colors(4,:);
            app.KeyVariableListBox.FontColor = Colors(4,:);
            app.VariableListBox.FontColor = Colors(4,:);
            app.ClassListBox.BackgroundColor = Colors(3,:);
            app.GroupListBox.BackgroundColor = Colors(3,:);
            app.GroupDataListBox.BackgroundColor = Colors(4,:);
            app.ClassListBox.FontColor = Colors(4,:);
            app.GroupListBox.FontColor = Colors(4,:);
            app.GroupDataListBox.FontColor = Colors(4,:);
            

        end


    function PhaseOut = reCalPhase(app, PhaseIn)
      
        PI_count = 0;

        PhaseOut = zeros(1,length(PhaseIn));
        PhaseOut(1) = PhaseIn(1);
        
        for timeIndex = 3:length(PhaseIn)
%             if PhaseIn(timeIndex) - PhaseIn(timeIndex-1) > (120)/180*pi
%                 PI_count = 1;
%             elseif PhaseIn(timeIndex) - PhaseIn(timeIndex-1) < -(10)/180*pi
%                 PI_count = 0;
%             end
            if PhaseIn(timeIndex) - PhaseIn(timeIndex-1) > (180)/180*pi && PI_count == 0
                PI_count = 1;
            elseif PhaseIn(timeIndex) - PhaseIn(timeIndex-1) < -(540)/180*pi && PI_count == 1
                PI_count = -1;
            elseif PhaseIn(timeIndex) - PhaseIn(timeIndex-1) < -(180)/180*pi && PI_count == 0
                PI_count = -1;
            elseif PhaseIn(timeIndex) - PhaseIn(timeIndex-1) < -(90)/180*pi && PI_count == 1
                PI_count = 0;
            elseif PhaseIn(timeIndex) - PhaseIn(timeIndex-1) > (90)/180*pi && PI_count == -1
                PI_count = 0;
            end
            PhaseOut(timeIndex) = PhaseIn(timeIndex) - 2*PI_count*pi;
        end
        
%         PhaseOut = PhaseOut/pi*180;
        
        
    end
        
        function state = grf2state(app,grf,trig_force)
            state = zeros(size(grf,1),size(grf,2));
            Hold_Len = 50;
            Hold_timer = 50;
            
            for i = 2:(max(size(grf,1),size(grf,2)))
                if state(i-1) == 0 && grf(i) > trig_force && Hold_timer >= Hold_Len
                    state(i) = 1;
                    Hold_timer = 0;
                elseif state(i-1) == 1 && grf(i) > trig_force
                    state(i) = 1;
                    Hold_timer = Hold_timer + 1;
                elseif state(i-1) == 1 && grf(i) <= trig_force && Hold_timer >= Hold_Len
                    state(i) = 0;
                    Hold_timer = 0;
                elseif state(i-1) == 0 && grf(i) <= trig_force
                    state(i) = 0;
                    Hold_timer = Hold_timer + 1;
                else
                    state(i) = state(i-1);
                end
            end
            
        end
        
        function GRF = getGRFfromMAT(app,file_path,COP_method, CalibSwitch)
            dSpaceData = load(file_path);
            dSpaceData = dSpaceData.(string(fieldnames(dSpaceData)));
            varNames = arrayfun(@(x)x.Name, (dSpaceData.Y), 'UniformOutput', false);
            grfNames = {'ground_force_r_vx','ground_force_r_vy','ground_force_r_vz','ground_force_r_px','ground_force_r_py','ground_force_r_pz','ground_torque_r_x','ground_torque_r_y','ground_torque_r_z',...
                        'ground_force_l_vx','ground_force_l_vy','ground_force_l_vz','ground_force_l_px','ground_force_l_py','ground_force_l_pz','ground_torque_l_x','ground_torque_l_y','ground_torque_l_z'};
            % 提取时间数据和出发信号
            time = dSpaceData.X.Data';
            trig = dSpaceData.Y(find(strcmp(varNames, 'Syn'))).Data';
            % 一步转换到QTM坐标系
            ground_force_r_vx = -dSpaceData.Y(find(strcmp(varNames, 'Fy_R'))).Data'; ground_force_l_vx = -dSpaceData.Y(find(strcmp(varNames, 'Fy_L'))).Data';
            ground_force_r_vy =  dSpaceData.Y(find(strcmp(varNames, 'Fz_R'))).Data'; ground_force_l_vy =  dSpaceData.Y(find(strcmp(varNames, 'Fz_L'))).Data';
            ground_force_r_vz =  dSpaceData.Y(find(strcmp(varNames, 'Fx_R'))).Data'; ground_force_l_vz =  dSpaceData.Y(find(strcmp(varNames, 'Fx_L'))).Data';
            ground_torque_r_x = -dSpaceData.Y(find(strcmp(varNames, 'My_R'))).Data'; ground_torque_l_x = -dSpaceData.Y(find(strcmp(varNames, 'My_L'))).Data';
            ground_torque_r_y =  dSpaceData.Y(find(strcmp(varNames, 'Mz_R'))).Data'; ground_torque_l_y =  dSpaceData.Y(find(strcmp(varNames, 'Mz_L'))).Data';
            ground_torque_r_z =  dSpaceData.Y(find(strcmp(varNames, 'Mx_R'))).Data'; ground_torque_l_z =  dSpaceData.Y(find(strcmp(varNames, 'Mx_L'))).Data';
            
            % 地反力校准
            if CalibSwitch
                % 获取校准区域
                calibTimeStartR = app.TFRCalibStartEditField.Value;calibTimeEndR = app.TFRCalibEndEditField.Value;
                calibTimeStartL = app.TFLCalibStartEditField.Value;calibTimeEndL = app.TFLCalibEndEditField.Value;
                % 判断右侧校准区域是否正确
                if calibTimeEndR > calibTimeStartR
                    % 右侧校准区间的开始时刻和结束时刻(相对trig开始): calibTimeStartR, calibTimeEndR
                    trigTime = time(find(trig,1)); 
                    calibIdx = find(trigTime+calibTimeStartR<time & time<trigTime+calibTimeEndR);
                    % 校准右侧地反力
                    varList = {'ground_force_r_vx','ground_force_r_vy','ground_force_r_vz','ground_torque_r_x','ground_torque_r_y','ground_torque_r_z'};
                    for grf_var = varList
                        eval([grf_var{:} ' = ' grf_var{:}  ' - mean(' grf_var{:} '(calibIdx));']);
                    end
                end
                % 判断左侧校准区域是否正确
                if calibTimeEndL > calibTimeStartL
                    % 左侧校准区间的开始时刻和结束时刻(相对trig开始): calibTimeStartR, calibTimeEndR
                    trigTime = time(find(trig,1)); 
                    calibIdx = find(trigTime+calibTimeStartL<time & time<trigTime+calibTimeEndL);
                    % 校准左侧地反力
                    varList = {'ground_force_l_vx','ground_force_l_vy','ground_force_l_vz','ground_torque_l_x','ground_torque_l_y','ground_torque_l_z'};
                    for grf_var = varList
                        eval([grf_var{:} ' = ' grf_var{:}  ' - mean(' grf_var{:} '(calibIdx));']);
                    end
                end
            end
            
            % 数据平滑
            if app.GRFSmoothLenEditField.Value > 1
                for grf_var = grfNames([1:3,7:9,10:12,16:18])
                    eval([grf_var{:} '= smooth(' grf_var{:} ',' num2str(app.GRFSmoothLenEditField.Value) ');']);
                end
            end
            
            % 数据同步
            QTMRate = app.QTMRateEditField.Value;
            synList = find(trig); 
            nFrames = round((time(synList(end))-time(synList(1))) * QTMRate);
            synIdx = synList(round(linspace(1,length(synList), nFrames)));
            syntTime = linspace(0,30,nFrames+1); syntTime = syntTime(1:end-1)';
            % 地反力数据频率较高，这里直接降采样即可
            for grf_var = grfNames([1:3,7:9,10:12,16:18])
                eval([grf_var{:} '=' grf_var{:} '(synIdx);']);
            end

            % 两侧跑台原点在QTM坐标系下的位置
            cop_bias_r_x = app.TOBiasRXEditField.Value/1000; cop_bias_r_y = app.TOBiasRYEditField.Value/1000; cop_bias_r_z = app.TOBiasRZEditField.Value/1000;
            cop_bias_l_x = app.TOBiasLXEditField.Value/1000; cop_bias_l_y = app.TOBiasLYEditField.Value/1000; cop_bias_l_z = app.TOBiasLZEditField.Value/1000;
            
            % 计算COP位置，两种方式
            if strcmp(COP_method,'Treadmill Origin')
                ground_force_zero = zeros(nFrames,1);
                ground_force_r_px = ground_force_zero + cop_bias_r_x; ground_force_r_py = ground_force_zero + cop_bias_r_y; ground_force_r_pz = ground_force_zero + cop_bias_r_z;
                ground_force_l_px = ground_force_zero + cop_bias_l_x; ground_force_l_py = ground_force_zero + cop_bias_l_y; ground_force_l_pz = ground_force_zero + cop_bias_l_z;
            else
                % 由跑台地反力计算足底压力中心的位置
                ground_force_zero = zeros(nFrames,1); trigBias = app.GRFTrigBiasEditField.Value/100;
                standList_r = double((ground_force_r_vy > trigBias*max(ground_force_r_vy)));
                standList_l = double((ground_force_l_vy > trigBias*max(ground_force_l_vy)));
                ground_force_r_px = (-cop_bias_r_y*ground_force_r_vx + ground_torque_r_z)./ground_force_r_vy.*standList_r + cop_bias_r_x;
                ground_force_r_pz = (-cop_bias_r_y*ground_force_r_vz-ground_torque_r_x)./ground_force_r_vy.*standList_r + cop_bias_r_z;
                ground_force_r_py = ground_force_zero + cop_bias_r_y;
                ground_force_l_px = (-cop_bias_l_y*ground_force_l_vx + ground_torque_l_z)./ground_force_l_vy.*standList_l + cop_bias_l_x;
                ground_force_l_pz = (-cop_bias_l_y*ground_force_l_vz-ground_torque_l_x)./ground_force_l_vy.*standList_l + cop_bias_l_z;
                ground_force_l_py = ground_force_zero + cop_bias_l_y;

                % 参考OpenSim的示例数据，这里强制将所有力矩转化为零，但在Y轴方向扭矩或许存在问题
                ground_torque_r_x = ground_force_zero;ground_torque_r_z = ground_force_zero;ground_torque_r_y = ground_force_zero;
                ground_torque_l_x = ground_force_zero;ground_torque_l_z = ground_force_zero;ground_torque_l_y = ground_force_zero;

                % 计算COP的平均位置
                standList_r = find(ground_force_r_vy > trigBias*max(ground_force_r_vy));
                standList_l = find(ground_force_l_vy > trigBias*max(ground_force_l_vy));
                ground_force_r_px_avg = mean(ground_force_r_px(standList_r));ground_force_r_pz_avg = mean(ground_force_r_pz(standList_r));
                ground_force_l_px_avg = mean(ground_force_l_px(standList_l));ground_force_l_pz_avg = mean(ground_force_l_pz(standList_l));
                % 特殊情况处理
                if standList_r(1) ~= 1
                    standList_r = [1;standList_r]; ground_force_r_px(1) = ground_force_r_px_avg; ground_force_r_pz(1) = ground_force_r_pz_avg;
                end
                if standList_r(end) ~= nFrames
                    standList_r = [standList_r;nFrames]; ground_force_r_px(end) = ground_force_r_px_avg; ground_force_r_pz(end) = ground_force_r_pz_avg;
                end
                if standList_l(1) ~= 1
                    standList_l = [1;standList_l]; ground_force_l_px(1) = ground_force_l_px_avg; ground_force_l_pz(1) = ground_force_l_pz_avg;
                end
                if standList_l(end) ~= nFrames
                    standList_l = [standList_l;nFrames]; ground_force_l_px(end) = ground_force_l_px_avg; ground_force_l_pz(end) = ground_force_l_pz_avg;
                end
                % 找出摆动相区间
                standListDiff_r = standList_r(2:end) - standList_r(1:end-1);standListDiff_l = standList_l(2:end) - standList_l(1:end-1);
                swingStartIdx_r = standList_r(find(standListDiff_r > 1) + 0); swingEndIdx_r = standList_r(find(standListDiff_r > 1) + 1);
                swingStartIdx_l = standList_l(find(standListDiff_l > 1) + 0); swingEndIdx_l = standList_l(find(standListDiff_l > 1) + 1);
                % 对摆动相COP进行插值
                for i = 1:length(swingStartIdx_r)
                    swingList = swingStartIdx_r(i):swingEndIdx_r(i);
                    ground_force_r_px(swingList) = linspace(ground_force_r_px(swingList(1)),ground_force_r_px(swingList(end)),length(swingList))';
                    ground_force_r_pz(swingList) = linspace(ground_force_r_pz(swingList(1)),ground_force_r_pz(swingList(end)),length(swingList))';
                end
                for i = 1:length(swingStartIdx_l)
                    swingList = swingStartIdx_l(i):swingEndIdx_l(i);
                    ground_force_l_px(swingList) = linspace(ground_force_l_px(swingList(1)),ground_force_l_px(swingList(end)),length(swingList))';
                    ground_force_l_pz(swingList) = linspace(ground_force_l_pz(swingList(1)),ground_force_l_pz(swingList(end)),length(swingList))';
                end

            end
            
            % 提取转化数据为结构体
            GRF = {};
            GRF.time = syntTime;
            for i = 1:18
                GRF.(grfNames{i}) = eval(grfNames{i});
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % 初始化数据结构
            app.data = struct;
            app.data.name = {};
            app.data.comp.name = {};
            app.data.groupbar.class.name = {};
            app.data.groupbar.group.name = {};
            app.data.groupbar.group.name_re = {};
            app.data.fit.name = {};
            app.data.fit.value = {};
            app.data.fit.labelX = {};
            app.data.fit.labelY = {};
            app.data.fit.selectedRow = 1;
            app.data.sig.name = {};
            app.data.sig.selectedGroup = [];
            app.data.groupplot.selectedRow = 1;
            app.data.groupplot.colorlist = [];
            app.data.barplot.colorlist = [];
            app.data.barclass.selectedRow = 1;
            
            app.figureList = struct;
            app.figureList.figs = struct;
            app.figureList.list = [];
            app.figureList.figThis = 0;
            
            
            % 获得屏幕分辨率
            ScreenSize = get(0,'ScreenSize');
            app.setting.screen = ScreenSize(3:4);
            % 设置显示位置
            app.LabExoAnalystUIFigure.Position = [app.setting.screen(1)-1080 app.setting.screen(2)-600 1080 540];
%             app.LabExoAnalystUIFigure.Position = [app.setting.screen(1)-1080*1.5 app.setting.screen(2)-750*1.3 1080 720];
            app.setting.FigureW = 800;
            app.setting.FigureH = 400;
            app.setting.FigureX = 0;
            app.setting.FigureY = 500;
            app.setting.StrideShift = 0.0;
            app.setting.FontSize = 14;
            app.setting.Xdensity = 10;
            app.setting.XAxisThickness = 0.5;
            
%             app.usersubfigmap. = struct;
%             for i = 1:10
%                 for j = 1:10
%                     eval([('app.usersubfigmap.'  ' = struct;']);
%                 end
%             end
            
            % 定义初始颜色
            app.setting.usercolor = [0 0.45 0.74];
            % 加载othercolor配色方案
            app.setting.ColorData = load('colorData.mat');
            ColorName = load('colorName.mat');
            app.setting.ColorName = ColorName.ColorName;
            app.setting.ColorIndex = 1;
            app.Lamp.BackgroundColor = app.setting.usercolor;
            
             % 设置配色选择框
            app.ColorMapAxes.Toolbar.Visible = 'off';
            app.ColorMapDropDown.Items = ColorName.ColorName;
            app.upColorMap();
            app.setting.LastTab = 'PlotTab';
            
            % 设置背景颜色
            % 颜色说明
            app.setting.backcolorset = {};
            app.setting.backcolorset.defult = [0.15,0.15,0.15; 0.25,0.25,0.25; 0.50,0.50,0.50;0.65,0.65,0.65; 1 1 1];
            app.setting.backcolorset.dark = [0.15,0.15,0.15; 0.25,0.25,0.25; 0.50,0.50,0.50;0.65,0.65,0.65; 1 1 1];
            app.setting.backcolorset.blue = [0.5941    0.7333    0.8607;
                                             0.4627    0.6235    0.8039;
                                             0.8392    0.9020    0.9490;
                                             1.0000    1.0000    1.0000];
            app.setting.backcolorset.green = [0.4275    0.5961    0.5255;
                                              0.5529    0.7098    0.5882;
                                              0.7412    0.8235    0.7137;
                                              1.0000    1.0000    1.0000];
            app.setting.backcolorset.pink = [0.8941    0.7294    0.8314;
                                             0.9294    0.8020    0.8765;
                                             0.9470    0.8383    0.8990;
                                             1.0000    1.0000    1.0000];
            app.setting.backcolorset.purple = [0.0667    0.1765    0.3059;
                                               0.1961    0.3216    0.5333;
                                               0.8588    0.8863    0.9373;
                                               1.0000    1.0000    1.0000];
%             app.SetBackGroundSolor(app.setting.backcolorset.dark);
            
            % 设置数据处理精度
            app.setting.gaitPecent = 0:0.1:100;
            
            % 调整各模块位置
            app.Panel_2.Position = [35,-520,220,300];
            app.Panel_3.Position = [25,49,220,300];
            
            app.Lamp.BackgroundColor = app.setting.usercolor;
           
            % BioMech相关变量
            app.bioMech = struct;
            app.bioMech.C3DFilePath = '';
            app.bioMech.C3DFileList = {};
            app.bioMech.MATFilePath = '';
            app.bioMech.MATFileList = {};
            app.bioMech.selectedRowC3D = 1;
            app.bioMech.selectedRowMAT = 1;

            
        end

        % Value changed function: ProcessAnalysisButton
        function ProcessAnalysisButtonValueChanged(app, event)
            % 进入数据分析界面
            app.TabGroup.SelectedTab = app.AnaDataTab;
        end

        % Value changed function: BioMechProcessButton
        function BioMechProcessButtonValueChanged(app, event)
            % 进入生物力学数据处理界面
            app.TabGroup.SelectedTab = app.BioMechTab;
        end

        % Value changed function: GlobalSettingButton
        function GlobalSettingButtonValueChanged(app, event)
            % 进入设置界面
            app.TabGroup.SelectedTab = app.GlobalSetTab;
        end

        % Button pushed function: BACKButton, BACKButton_2, BACKButton_3
        function BACKButtonPushed(app, event)
            % 返回主界面
            app.TabGroup.SelectedTab = app.MainTab;
        end

        % Callback function
        function LOADButtonPushed(app, event)
            % 进入数据加载界面
            app.moveui('app.Panel_2',35,-520,10,200);
            app.moveui('app.Panel_3',35,30,10,200);
            app.moveui('app.Panel_5',1080,590,200,10);
            app.moveui('app.Panel_6',1080,590,200,10);
            app.moveui('app.Panel_4',296,590,200,10);
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
            % 进入绘图界面
%             app.moveui('app.Panel_3',35,-520,10,200);
%             app.moveui('app.Panel_8',276,-576,10,200);
%             app.moveui('app.Panel_12',276,-576,10,200);
%             app.moveui('app.Panel_13',276,-576,10,200);
% %             app.moveui('app.Panel_2',35,30,10,200);
%             app.moveui('app.Panel_7',276,2,10,200);
%             app.moveui('app.Panel_4',1080,590,200,10);
%             app.moveui('app.Panel_6',1080,590,200,10);
%             app.moveui('app.Panel_5',296,590,200,10);
            for i = 1:length(app.PlotButtonGroup.Children)
                app.PlotButtonGroup.Children(i).BackgroundColor = app.setting.backcolorset.defult(1,:);
            end
            app.PLOTButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            
            app.PlotGroup.SelectedTab = app.PlotTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
            
        end

        % Button pushed function: LOADDATAButton
        function LOADDATAButtonPushed(app, event)
            
            % 进入数据类型选择界面 app.Panel_4_3
%             app.moveui('app.Panel_4_3',326,590,100,10);
            app.ToolTabGroup.SelectedTab = app.DataTypeTab;
            
        end

        % Value changed function: dSPACEButton
        function dSPACEButtonValueChanged(app, event)
            
            % 返回并读取dSPACE数据
            app.ToolTabGroup.SelectedTab = app.LoadTab;
            app.loadDspaceData();
            
        end

        % Value changed function: OpenSimButton
        function OpenSimButtonValueChanged(app, event)
            
            % 返回并读取OpenSim数据
            app.ToolTabGroup.SelectedTab = app.LoadTab;
            app.loadOpenSimData();
            
        end

        % Value changed function: PredSimButton
        function PredSimButtonValueChanged(app, event)
            
            % 返回并读取PredSim数据
            app.ToolTabGroup.SelectedTab = app.LoadTab;
            app.loadPredSimData();
            
        end

        % Value changed function: SCONEButton
        function SCONEButtonValueChanged(app, event)
            
            % 返回并读取Scone数据
            app.ToolTabGroup.SelectedTab = app.LoadTab;
            app.loadSconeData();
            
        end

        % Value changed function: HistoryButton
        function HistoryButtonValueChanged(app, event)
            
            app.ToolTabGroup.SelectedTab = app.LoadTab;
            app.loadHistory();
            
        end

        % Button pushed function: PROCESSButton
        function PROCESSButtonPushed(app, event)
            
            % 允许多选数据组进行数据处理
            data_name_list = char(app.DataListBox.Value);
            app.processMultiData(data_name_list);
            
        end

        % Button pushed function: PROCESSALLButton
        function PROCESSALLButtonPushed(app, event)
            
            % 允许多选数据组进行数据处理
            data_name_list = char(app.data.name');
            app.processMultiData(data_name_list);
            
        end

        % Callback function
        function DataListBoxValueChanged(app, event)
            
            app.updateVarTable();
            
        end

        % Button pushed function: DELETEDATAButton
        function DELETEDATAButtonPushed(app, event)
            data_name_list = char(app.DataListBox.Value);
%             if size(data_name,1) > 1
%                 data_name = data_name(1,:);
%             end
%             disp(data_name_list)
            for j = 1:size(data_name_list,1)
                data_name = strrep(data_name_list(j,:), ' ', '');
                if ~isempty(data_name) && ~strcmp(data_name,'NULL')
                    for i = 1:length(app.data.name)
                        if strcmp(app.data.name(i), data_name)
                            app.data.name(i) = [];
                            break;
                        end
                    end
                    
                    data_name = strsplit(data_name,'.');
                    
                    eval(['app.data.' data_name{1} ' = rmfield(app.data.' data_name{1} ', ''' data_name{2} ''');']);
                    
                    app.DataListBox.Items = app.data.name;
                    app.updateVarTable();
                end
            end
            
        end

        % Button pushed function: SAVEVARButton
        function SAVEVARButtonPushed(app, event)
            [file, path]=uiputfile('AnaData.mat','Save data name');
            data = app.data;
            if ~(isequal(file,0))  
                save(fullfile(path, file),'data');
            end
        end

        % Button pushed function: DivideDataButton
        function DivideDataButtonPushed(app, event)
            
            data_name_list = char(app.DataListBox.Value);
            if size(data_name_list,1) > 1
                data_name = data_name_list(1,:);
            end
            data_name_split = strsplit(data_name_list(1,:),'.');
            eval(['max_time = app.data.' data_name_list(1,:) '.time.origin(end);']);
            last_time = str2double(inputdlg(['Input how long time(s) needed to divide(max ' num2str(floor(max_time)) ')：'],'Divide Data')); 
            
            for j = 1:size(data_name_list,1)
                data_name = data_name_list(j,:);
                if strcmp(data_name_split{1}, 'dspace')
                    if ~isempty(last_time)
                        eval(['divide_list = find(app.data.' data_name '.time.origin-(app.data.' data_name '.time.origin(end) - last_time) > 0);']);
                        if ~isempty(divide_list)
                            
                            divide_index = divide_list(1);
                            
                            new_name = [data_name '_last' num2str(last_time)];
                            app.data.name(end+1) = {new_name};
                            app.DataListBox.Items = app.data.name;
                            
                            eval(['app.data.' new_name ' = app.data.' data_name ';']);
                            eval(['name_temp = app.data.' new_name '.name;']);
                            for i = 1:length(name_temp)
                                eval(['app.data.' new_name '.' char(name_temp(i)) '.origin = app.data.' new_name '.' char(name_temp(i)) '.origin(divide_index:end);']);
                            end
                            
                            eval(['app.data.' new_name '.processFlag = false;']);
                            app.updateVarTable();
                            
                        end
                    end
                end
            end
            
        end

        % Button pushed function: MergeDataButton
        function MergeDataButtonPushed(app, event)
           
            data_name = (app.DataListBox.Value);
            if size(data_name,1) < 1
                return;
            end
            
            new_name = 'dspace.Merge_';
            merge_num = size(data_name,2);
            cut_list = zeros(merge_num,2);
            
            %% 本版本未加入数据的变量一致性检验，需注意
            
            for i = 1:merge_num
                eval(['name_temp = app.data.' data_name{i} '.name;']);
                if ismember(1,strcmp(name_temp, 'state'))
                    eval(['state_data = app.data.' data_name{i} '.state.origin;']);
                elseif ismember(1,strcmp(name_temp, 'state_r'))
                    eval(['state_data = app.data.' data_name{i} '.state_r.origin;']);
                else
                    errordlg(['no state variable in ' data_name{i}],'error');
                    return
                end
                stance_index = find(state_data(2:end) - state_data(1:end-1) == 1) + 1;
                cut_list(i,1) = stance_index(1);cut_list(i,2) = stance_index(end)-1;
            end
            
            for i = 1:merge_num-1
                data_name_split = strsplit(data_name{i},'.');
                new_name = [new_name data_name_split{2} '_'];
            end
            
            data_name_split = strsplit(data_name{end},'.');
            new_name = [new_name data_name_split{2}];
            
            app.data.name(end+1) = {new_name};
            app.DataListBox.Items = app.data.name;
                        
            eval(['app.data.' new_name ' = app.data.' data_name{1} ';']);
            eval(['name_temp = app.data.' new_name '.name;']);
            for i = 1:length(name_temp)
                eval(['app.data.' new_name '.' char(name_temp(i)) '.origin = [];']);
                for j = 1:merge_num
                    eval(['app.data.' new_name '.' char(name_temp(i)) '.origin = [app.data.' new_name '.' char(name_temp(i)) '.origin, app.data.' data_name{j} '.' char(name_temp(i)) '.origin(cut_list(j,1):cut_list(j,2))];']);
                end
            end
            
            %% process time data separately
            eval(['app.data.' new_name '.time.origin = app.data.' data_name{1} '.time.origin(cut_list(1,1):cut_list(1,2));']);
            for j = 2:merge_num
                eval(['app.data.' new_name '.time.origin = [app.data.' new_name '.time.origin, app.data.' new_name '.time.origin(end) + app.data.' data_name{j} '.time.origin(cut_list(j,1):cut_list(j,2)) - app.data.' data_name{j} '.time.origin(cut_list(j,1)-1)];']);
            end
            
            eval(['app.data.' new_name '.processFlag = false;']);
            app.updateVarTable();
            
            
        end

        % Button pushed function: DelVarButton
        function DelVarButtonPushed(app, event)
            DataList = char(app.DataListBox.Value);
            VarList = char(app.VariableListBox.Value);
            
            for i = 1:size(DataList,1)
                dataName = DataList(i,:);
                eval(['name_temp = app.data.' dataName '.name;']);
                for j = 1:size(VarList,1)
                    varName = strrep(VarList(j,:), ' ', '');
                    % 删除效率待优化
                    if ~isempty(dataName) && ~isempty(varName)
                        eval(['name_temp = app.data.' dataName '.name;']);
                        for k = 1:length(name_temp)
                            if strcmp(name_temp{k}, varName)
                                name_temp(k) = [];
                                break;
                            end
                        end
                        eval(['app.data.' dataName ' = rmfield(app.data.' dataName ', ''' varName ''');']);
                        eval(['app.data.' dataName '.name = name_temp;']);
                        app.updateVarTable();
                    end
                end
            end
        end

        % Button pushed function: RenameVarButton
        function RenameVarButtonPushed(app, event)
            % 允许多组数据进行变量重命名
            data_name_list = char(app.DataListBox.Value);
            var_name = char(app.VariableListBox.Value);
            new_var_name = char(inputdlg('Input new variable name：','Rename'));

            if isempty(data_name_list) || isempty(var_name)
                errordlg('未选择数据和变量','error'); return;
            end
            if isempty(new_var_name)
                return;
            end

            for i = 1:size(data_name_list,1)
                data_name_whole = strrep(data_name_list(i,:), ' ', '');         % 处理数据多选时的空格问题
                data_name_split = strsplit(data_name_whole,'.');                % 分离数据类型前缀(dspace)和数据名称
                eval(['name_temp = app.data.' data_name_whole '.name;']);

                if ismember(1,strcmp(name_temp,new_var_name))
                    errordlg(['input name has existed in ', data_name_whole],'error'); return;
                end

                eval(['app.data.' data_name_whole '.' new_var_name ' = app.data.' data_name_whole '.' var_name ';']);
                eval(['app.data.' data_name_whole ' = rmfield(app.data.' data_name_whole ', ''' var_name ''');']);
                for j = 1:length(name_temp)
                    if strcmp(name_temp{j}, var_name)
                        name_temp(j) = {new_var_name};
                        break;
                    end
                end
                
                eval(['app.data.' data_name_whole '.name = name_temp;']);
                app.updateVarTable();
            end
        end

        % Button pushed function: NormalVarButton
        function NormalVarButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
%             if size(data_name,1) > 1
%                 data_name = data_name(1,:);
%             end
            
            var_name = char(app.VariableListBox.Value);
            
            if ~isempty(data_name) && ~isempty(var_name)
                normal_gain = str2double(inputdlg(['Input normalization gain：'],'Normalization'));
                if isnumeric(normal_gain)
                    if ~isempty(normal_gain) && ~isnan(normal_gain) && normal_gain ~= 0
                        if size(data_name,1) == 1
%                             eval(['app.data.' data_name '.' var_name '.origin = 1 / normal_gain * app.data.' data_name '.' var_name '.origin;']);
                            % eval(['app.data.' data_name '.' var_name '.stride.each = 1/normal_gain * app.data.' data_name '.' var_name '.stride.each;']);
                            app.scaleOneVar(data_name,var_name,normal_gain);
                        else
                            for i = 1:size(data_name,1)
%                                 eval(['app.data.' data_name(i,:) '.' var_name '.origin = 1/normal_gain * app.data.' data_name(i,:) '.' var_name '.origin;']);
                                % eval(['app.data.' data_name(i,:) '.' var_name '.stride.each = 1/normal_gain * app.data.' data_name(i,:) '.' var_name '.stride.each;']);
                                app.scaleOneVar(data_name(i,:), var_name,normal_gain);
                            end
                        end
                        msgbox('Process Completed');
                        return
                    end
                end
            end
            msgbox('No Processing');
            
        end

        % Button pushed function: BiasVarButton
        function BiasVarButtonPushed(app, event)
            
            data_name = char(app.DataListBox.Value);      
            var_name = char(app.VariableListBox.Value);
            
            if ~isempty(data_name) && ~isempty(var_name)
                bias = str2double(inputdlg(['Input calibration bias：'],'Calibration'));
                if isnumeric(bias)
                    if ~isempty(bias) && ~isnan(bias)
                        if size(data_name,1) == 1
                            eval(['app.data.' data_name '.' var_name '.origin = app.data.' data_name '.' var_name '.origin - bias;']);
                            eval(['app.data.' data_name '.' var_name '.stride.each = app.data.' data_name '.' var_name '.stride.each - bias;']);
                            app.processOneVar(data_name,var_name);
                        else
                            for i = 1:size(data_name,1)
%                                 eval(['app.data.' data_name(i,:) '.' var_name '.origin = app.data.' data_name(i,:) '.' var_name '.origin - bias;']);
                                % eval(['app.data.' data_name(i,:) '.' var_name '.stride.each = app.data.' data_name(i,:) '.' var_name '.stride.each - bias;']);
                                app.biasOneVar(data_name(i,8:end), var_name,bias);
                            end
                        end
                        msgbox('Process Completed');
                        return
                    end
                end
            end
            msgbox('No Processing');
            
        end

        % Value changed function: DataListBox
        function DataListBoxValueChanged2(app, event)
            
            app.updateKeyVarTable();
            app.updateVarTable();
            
            
        end

        % Value changed function: EditKey
        function EditKeyValueChanged(app, event)
            
            app.updateKeyVarTable();
            
        end

        % Callback function
        function NEWButtonPushed(app, event)
            figure('position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
            app.userfig = subplot(1,1,1);
            xlabel('Time(s)');
            legend('show');
            hleg = legend('boxoff');
            set(hleg,'Interpreter','none')
            hold on;
            h = gca;
            h.Box = 'off';
            h.FontName = 'Times New Roman';
            h.FontSize = 14;
            h = gcf;
            h.Color = [1,1,1];
        end

        % Callback function
        function NEWButton_2Pushed(app, event)
            figure('position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
            app.userfig = gca;
            xlabel('Stride Num(n)');
            legend('show');
            hleg = legend('boxoff');
            set(hleg,'Interpreter','none')
            hold on;
            h = gca;
            h.Box = 'off';
            h.FontName = 'Times New Roman';
            h.FontSize = 14;
            h = gcf;
            h.Color = [1,1,1];
        end

        % Callback function
        function NEWButton_3Pushed(app, event)
            figure('position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
            app.userfig = gca;
            xlabel('Stride Percent(%)');
            hold on;
            h = gca;
            h.Box = 'off';
            h.FontName = 'Times New Roman';
            h.FontSize = 14;
            h = gcf;
            h.Color = [1,1,1];
        end

        % Callback function
        function NEWButton_4Pushed(app, event)
            % Add you code here
        end

        % Callback function
        function CLEARButtonPushed(app, event)
            
            if ~isempty(app.userfig)
                cla(app.userfig);
            end
            
        end

        % Callback function
        function SETCOLORButtonPushed(app, event)
            app.setting.usercolor = uisetcolor(app.setting.usercolor);
            app.Lamp.BackgroundColor = app.setting.usercolor;
        end

        % Callback function
        function ADDButtonPushed(app, event)
            % Add you code
        end

        % Callback function
        function DELButtonPushed(app, event)
            % Add you code
        end

        % Callback function
        function PLOTButton_2Pushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.KeyVariableListBox.Value);
            
            if isempty(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please select a variable','error');
            else
                eval(['plot(app.userfig, app.data.' data_name '.time.origin, app.data.' data_name '.' var_name '.origin,''LineWidth'',1,''DisplayName'',''' var_name ''');']);
            end
            
        end

        % Callback function
        function PLOTButton_3Pushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.KeyVariableListBox.Value);
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            feature_name = app.FeatureDropDown.Value;
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else 
                eval(['plot(app.userfig, app.data.' data_name '.' var_name '.' feature_name '.each,''LineWidth'',1,''DisplayName'',''' var_name '_' feature_name ''');']);
            end
                
        end

        % Callback function
        function PLOTButton_4Pushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.KeyVariableListBox.Value);
            stride_percent = app.setting.gaitPecent;
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else
                eval(['data_temp =   app.data.' data_name '.' var_name '.stride;']);
                
                % 数据反向处理
                if app.ReverseCheckBox.Value == 1
                    data_each = -data_temp.each;
                    data_mean = -data_temp.mean;
                    data_std  = -data_temp.std;
                else
                    data_each = data_temp.each;
                    data_mean = data_temp.mean;
                    data_std  = data_temp.std;
                end
                
                % 绘制每条轨迹
                if app.eachCheckBox.Value == 1
                    userAlpha = 0.6;
                    for j = 1:length(data_each(:,1))
                        plot(app.userfig, stride_percent, data_each(j,:),'-','color',1.0 - userAlpha*(1.0 - app.setting.usercolor), 'EraseMode', 'xor','LineWidth',0.5);
                    end
                end
                % 绘制误差区域
                if app.stdCheckBox.Value == 1
                    h = area(app.userfig, stride_percent,[data_mean' - data_std', 2 * data_std']);
                    set(h(1),'Visible','off');
                    set(h(2),'EdgeColor','none','FaceColor',app.setting.usercolor,'FaceAlpha',0.4,'ShowBaseLine','off');
                end
                % 绘制平均曲线
                if app.meanCheckBox.Value == 1
                    plot(app.userfig, stride_percent, data_mean','color',app.setting.usercolor,'LineWidth',2);
                end
            end
            
        end

        % Callback function
        function TIMECLEARButtonPushed(app, event)
            
            if ~isempty(app.userfig)
                cla(app.userfig);
                app.userpl = struct;
            end
            
        end

        % Button pushed function: TIMEPLOTButton
        function TIMEPLOTButtonPushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);

            
            if isempty(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please select a variable','error');
            else
                if app.ReverseCheckBox.Value == 1
                    eval(['p = plot(app.userfig, app.data.' data_name '.time.origin, -app.data.' data_name '.' var_name '.origin,''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata );']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                else
                    eval(['p = plot(app.userfig, app.data.' data_name '.time.origin, app.data.' data_name '.' var_name '.origin,''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata );']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
            end   
        end

        % Callback function
        function FEATURENEWButtonPushed(app, event)
            figure('position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
            app.userfig = gca;
            xlabel('Stride Num(n)');
            legend('show');
            hleg = legend('boxoff');
            set(hleg,'Interpreter','none')
            hold on;
            h = gca;
            h.Box = 'off';
            h.FontName = 'Times New Roman';
            h.FontSize = 14;
            h = gcf;
            h.Color = [1,1,1];
            app.userpl = struct;
        end

        % Button pushed function: FEATUREPLOTButton
        function FEATUREPLOTButtonPushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            feature_name = app.FeatureDropDown.Value;
            phase_name = app.PhaseDropDown.Value;
            if strcmp({'whole'},phase_name)
                phase_name = '';
            else
                phase_name = ['_' phase_name];
            end
            averagenum = str2num(char(app.SmoothDropDown.Value));
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else
                
                eval(['stride_data = app.data.' data_name '.' var_name '.' feature_name phase_name '.each;']);
                stride_data = smooth(stride_data,averagenum);
                
                if app.ReverseCheckBox.Value == 1
                stride_data = -stride_data;
                end
                                
                stride_index = 1:length(stride_data);
                eval(['p = plot(app.userfig, stride_index, stride_data,''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata);']);%p = scatter(app.userfig, stride_index, stride_data, sz, c, 'filled','MarkerFaceColor', app.setting.usercolor);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
            end
        end

        % Callback function
        function SETCOLORButtonPushed2(app, event)
            app.setting.usercolor = uisetcolor(app.setting.usercolor);
            app.Lamp.BackgroundColor = app.setting.usercolor;
        end

        % Button pushed function: NEWButton
        function NEWButtonPushed2(app, event)
            slectedFigureID = str2num(app.FigureDropDown.Value);
            app.setting.FigureW = app.FigWdithEditField.Value;
            app.setting.FigureH = app.FigHeightEditField.Value;
            app.setting.FigureX = app.FigPosXEditField.Value;
            app.setting.FigureY = app.FigPosYEditField.Value;
            
            if isempty(slectedFigureID) || (slectedFigureID == length(app.figureList.list) && app.figureList.figThis == slectedFigureID)
                
                app.figureList.list = [app.figureList.list, length(app.figureList.list)+1];
                fighandle = figure(app.figureList.list(end));
%                 fighandle = figure(app.figureList.list(end),'position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
                eval(['app.figureList.figs.f' num2str(app.figureList.list(end)) ' = fighandle;']);
                set(fighandle,'position',[app.setting.FigureX,app.setting.FigureY,app.setting.FigureW,app.setting.FigureH]);
                fig_name = {};
                
                for i = 1:length(app.figureList.list)
                    fig_name(i) = {num2str(app.figureList.list(i))};
                end
                
                app.FigureDropDown.Items = fig_name;
                app.FigureDropDown.Value = fig_name{end};
                app.figureList.figThis = app.figureList.list(end);
                eval(['app.userpl.f' num2str(app.figureList.list(i)) ' = struct;']);
                
                xlabel('Gait Cycle (%)')
                ylabel('Y Data')
                if app.EdgeCheckBox.Value == 0
                    set(gca,'looseInset',[0 0 0 0]);
                end
                app.userfig = gca;
    %             xlabel('Stride Percent(%)');
    %             legend('show');
    %             hleg = legend('boxoff');
    %             set(hleg,'Interpreter','none')
                hold on;
                h = gca;
                h.Box = 'off';
                h.FontName = 'Times New Roman';
                h.LineWidth = app.setting.XAxisThickness;
                h.FontSize = app.setting.FontSize;
                if app.setting.Xdensity ~= 10
                    h.XTick = 0:app.setting.Xdensity:100;
                    xlim([0 100]);
                end
                h = gcf;
                h.Color = [1,1,1];
                
            elseif slectedFigureID ~= length(app.figureList.list) || app.figureList.figThis ~= slectedFigureID
                eval(['figureIsOpen = ishandle(app.figureList.figs.f' num2str(slectedFigureID) ');']);
                if figureIsOpen
                    eval(['figure(app.figureList.figs.f' num2str(slectedFigureID) ')']);
                else
                    errordlg('The figure is closed','error');
                end
                app.figureList.figThis = slectedFigureID;
                app.userfig = gca;
            else
                disp("Create Figure Failed")
            end
            
            
        end

        % Button pushed function: STRIDEPLOTButton
        function STRIDEPLOTButtonPushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = app.figureList.figThis;
            
            stride_percent = app.setting.gaitPecent;
            
            OT_Index = app.GaitCycleEditField2.Value;
            HR_Index = app.GaitCycleEditField3.Value;
            OI_Index = app.GaitCycleEditField4.Value;
            TO_Index = app.GaitCycleEditField5.Value;
            FA_Index = app.GaitCycleEditField6.Value;
            TV_Index = app.GaitCycleEditField7.Value;
            PhT = [0, OT_Index, HR_Index, OI_Index, TO_Index, FA_Index, TV_Index, 100];
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else
                eval(['data_temp =   app.data.' data_name '.' var_name '.stride;']);
                
                % 横坐标转化为时间
                XTimeFlag = app.TimeCheckBox.Value;
                if XTimeFlag
                    eval(['time_temp =   app.data.' data_name '.time.stride.mean;']);
                    stride_percent = stride_percent / 100 * (max(time_temp)-min(time_temp));
                    xlabel('Time (s)');
                end
                
                % 数据反向处理
                if app.ReverseCheckBox.Value == 1
                    data_each = -data_temp.each;
                    data_mean = -data_temp.mean;
                    data_std  = -data_temp.std;
                else
                    data_each = data_temp.each;
                    data_mean = data_temp.mean;
                    data_std  = data_temp.std;
                end
                
                % 绘制每条轨迹
                if app.eachCheckBox.Value == 1
                    userAlpha = 0.6;
                    for j = 1:length(data_each(:,1))
%                         p = plot(app.userfig, stride_percent, data_each(j,:),'-','color',1.0 - userAlpha*(1.0 - colordata), 'EraseMode', 'xor','LineWidth',0.5);
                        p = plot(app.userfig, stride_percent, data_each(j,:),'-','color',1.0 - userAlpha*(1.0 - colordata),'LineWidth',0.5);
                        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                    end
                end
                % 绘制误差区域
                if app.stdCheckBox.Value == 1
                    h = fill([stride_percent, fliplr(stride_percent)],[ data_mean + 1*data_std, fliplr(data_mean - 1*data_std)],colordata,'linestyle', 'none', 'FaceAlpha', 0.4);
%                     h = area(app.userfig, stride_percent,[data_mean' - 1*data_std', 2 * data_std']);
%                     h = area(app.userfig, stride_percent,[data_mean' - 3', 6*ones(1,1001)']);
%                     set(h(1),'Visible','off');
%                     set(h(2),'EdgeColor','none','FaceColor',colordata,'FaceAlpha',0.4,'ShowBaseLine','off');
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = h;']);
                end
                % 绘制平均曲线
                if app.meanCheckBox.Value == 1
                    eval(['p = plot(app.userfig, stride_percent, data_mean'',''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata);']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
                app.userfig.XTick = PhT;
            end
        end

        % Callback function
        function ALTERButtonPushed(app, event)
            app.AnaDataGroup.SelectedTab = app.PLOTALLTab;
            app.PlotGroup.SelectedTab = app.ALTERTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
        end

        % Callback function
        function PLOTCLEARButton2Pushed(app, event)
            
            pl_name = fieldnames(app.userpl);
            eval(['delete(app.userpl.' pl_name{end} ');']);
            eval(['app.userpl = rmfield(app.userpl, ''' pl_name{end} ''');']);
            
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            
            app.upColorMap();
            app.setting.ColorIndex = 1;
            
        end

        % Callback function
        function ALTERSelectVarButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.KeyVariableListBox.Value);
            app.VarLabel.Text = [data_name '.' var_name];         
        end

        % Callback function
        function ALTERStridePlotButtonPushed(app, event)
            data_name = char(app.VarLabel.Text);
            data_name_split = strsplit(data_name,'.');
            eval(['processflag = app.data.' data_name_split{1} '.' data_name_split{2} '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                eval(['stride_data = app.data.' data_name '.stride.each;']);
                stride_percent = app.setting.gaitPecent;
                datalen = size(stride_data,1);
                linewidth = char(app.LineWidthDropDown.Value);
                colorname = char(app.ColorMapDropDown.Value);
                colordata = app.othercolor(colorname,datalen);
                for stride_index = 1:datalen
                    eval(['p = plot(app.userfig, stride_percent, stride_data(stride_index,:),''LineWidth'',' linewidth ',''color'', colordata(stride_index,:) );']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
            end
        end

        % Callback function
        function ALTERNEWButtonPushed(app, event)
            figure('position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
            app.userfig = gca;
%             legend('show');
%             hleg = legend('boxoff');
%             set(hleg,'Interpreter','none')
            hold on;
            h = gca;
            h.Box = 'off';
            h.FontName = 'Times New Roman';
            h.FontSize = 14;
            h = gcf;
            h.Color = [1,1,1];
            app.userpl = struct;
        end

        % Button pushed function: ClearLastButton
        function ClearLastButtonPushed(app, event)
            slectedFigureID = str2num(app.FigureDropDown.Value);
            eval(['pl_name = fieldnames(app.userpl.f' num2str(slectedFigureID) ');']);
            eval(['delete(app.userpl.f' num2str(slectedFigureID) '.' pl_name{end} ');']);
            eval(['app.userpl.f' num2str(slectedFigureID) '= rmfield(app.userpl.f' num2str(slectedFigureID) ', ''' pl_name{end} ''');']);
            app.userbar = struct;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            slectedFigureID = str2num(app.FigureDropDown.Value);
            eval(['figureIsEmpty = isempty(app.userpl.f' num2str(slectedFigureID) ');']);
            if ~figureIsEmpty
                eval(['cla(app.userfig);']);
                eval(['app.userpl.f' num2str(slectedFigureID) ' = struct;']);
            end
        end

        % Callback function
        function VarAlterAddButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            data_name_split = strsplit(data_name,'.');
            var_name = char(app.KeyVariableListBox.Value);
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else
                if isempty(app.data.comp.name)
                    alter_var_name = [data_name_split{2} '_' var_name];
                    eval(['var_data = app.data.' data_name '.' var_name ';']);
                    app.data.comp.name = {alter_var_name};
                    eval(['app.data.comp.' alter_var_name ' = var_data;']);
                else
                    alter_var_name = [data_name_split{2} '_' var_name];
                    eval(['var_data = app.data.' data_name '.' var_name ';']);
                    app.data.comp.name(end+1) = {alter_var_name};
                    eval(['app.data.comp.' alter_var_name ' = var_data;']);
                end
                app.VarAlterListBox.Items = app.data.comp.name;
            end
            
        end

        % Callback function
        function SETCOLORMAPButtonPushed(app, event)
            if isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else 
                colorname = app.ColorMapDropDown.Value;
                colordata = app.othercolor(colorname,0);
                if colordata(1,:) == [1, 1, 1]
                    colormap(app.userfig, colordata(2:end,:));
                else
                    colormap(app.userfig, colordata);
                end
            end
        end

        % Callback function
        function VarAlterDeleteButtonPushed(app, event)
            var_name = char(app.VarAlterListBox.Value);
            
            if isempty(var_name)
                errordlg('Please select a alter variable','error');
            else
                for i = 1:length(app.data.comp.name)
                    if strcmp(app.data.comp.name(i),var_name)
                        app.data.comp.name(i) = [];
                        break;
                    end
                end
                
                eval(['app.data.comp = rmfield(app.data.comp, ''' var_name ''');']);
                app.VarAlterListBox.Items = app.data.comp.name;
            end
            
            
        end

        % Callback function
        function VarAlterClearButtonPushed(app, event)
            app.data.comp = struct;
            app.data.comp.name = {};
            app.VarAlterListBox.Items = app.data.comp.name;
        end

        % Callback function
        function VarAlterRenameButtonPushed(app, event)
            var_name = char(app.VarAlterListBox.Value);
            
            if isempty(var_name)
                errordlg('Please select a alter variable','error');
            else
                new_name = char(inputdlg('Input the new name：','ReName'));
                if ~isempty(new_name)
                    for i = 1:length(app.data.comp.name)
                        if strcmp(app.data.comp.name(i),var_name)
                            app.data.comp.name(i) = {new_name};
                            break;
                        end
                    end
                    
                    eval(['app.data.comp.' new_name ' = app.data.comp.' var_name ';']);
                    eval(['app.data.comp = rmfield(app.data.comp, ''' var_name ''');']);
                    app.VarAlterListBox.Items = app.data.comp.name;
                end
            end
            
        end

        % Callback function
        function VarAlterUpButtonPushed(app, event)
            var_name = char(app.VarAlterListBox.Value);
            
            if isempty(var_name)
                errordlg('Please select a alter variable','error');
            else
                for i = 1:length(app.data.comp.name)
                    if strcmp(app.data.comp.name(i),var_name)
                        if i > 1
                            name_temp = app.data.comp.name(i);
                            app.data.comp.name(i) = app.data.comp.name(i-1);
                            app.data.comp.name(i-1) = name_temp;
                            break;
                        end
                    end
                end

                app.VarAlterListBox.Items = app.data.comp.name;
            end
        end

        % Callback function
        function VarAlterDownButtonPushed(app, event)
            var_name = char(app.VarAlterListBox.Value);
            
            if isempty(var_name)
                errordlg('Please select a alter variable','error');
            else
                for i = 1:length(app.data.comp.name)
                    if strcmp(app.data.comp.name(i),var_name)
                        if i < length(app.data.comp.name)
                            name_temp = app.data.comp.name(i);
                            app.data.comp.name(i) = app.data.comp.name(i+1);
                            app.data.comp.name(i+1) = name_temp;
                            break;
                        end
                    end
                end

                app.VarAlterListBox.Items = app.data.comp.name;
            end
        end

        % Callback function
        function VarAlterPlotButtonPushed(app, event)
            comp_num = length(app.data.comp.name);
            colormapname = app.ColorMapDropDown.Value;
            comp_color = app.othercolor(colormapname,comp_num);
            linewidth = char(app.LineWidthDropDown.Value);
            
            if comp_num == 0
                errordlg('Please add alter variables','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                stride_percent = app.setting.gaitPecent;
                for i = 1:comp_num
                    eval(['data_mean = app.data.comp.' app.data.comp.name{i} '.stride.mean;']);
                    if app.FlipCheckBox.Value == 1
                        data_mean = - data_mean;
                    end
                    eval(['p = plot(app.userfig, stride_percent, data_mean,''color'', comp_color(i,:) ,''LineWidth'',' linewidth ');']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
            end
            
        end

        % Callback function
        function PAHSEXSelectVarButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.KeyVariableListBox.Value);
            app.VarXNameLabel.Text = [data_name '.' var_name];     
        end

        % Callback function
        function PAHSEYSelectVarButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.KeyVariableListBox.Value);
            app.VarYNameLabel.Text = [data_name '.' var_name];    
        end

        % Callback function
        function ALTERNEWButton_3Pushed(app, event)
            figure('position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
            app.userfig = gca;
            xlabel('X');
            ylabel('Y');
%             legend('show');
%             hleg = legend('boxoff');
%             set(hleg,'Interpreter','none')
            hold on;
            h = gca;
            h.Box = 'off';
            h.FontName = 'Times New Roman';
            h.FontSize = 14;
            h = gcf;
            h.Color = [1,1,1];
            app.userpl = struct;
        end

        % Callback function
        function ALTERStridePlotButton_2Pushed(app, event)
            Xdata_name = char(app.VarXNameLabel.Text);
            Ydata_name = char(app.VarYNameLabel.Text);
            Xdata_name_split = strsplit(Xdata_name,'.');
            Ydata_name_split = strsplit(Ydata_name,'.');
            
            linewidth = char(app.LineWidthDropDown.Value);
            phase_len = app.EndTimeSlider.Value*10;
            
            eval(['processflagX = app.data.' Xdata_name_split{1} '.' Xdata_name_split{2} '.processFlag;']);
            eval(['processflagY = app.data.' Ydata_name_split{1} '.' Ydata_name_split{2} '.processFlag;']);
            
            if ~processflagX || ~processflagY
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                eval(['stride_dataX = app.data.' Xdata_name '.stride.mean;']);
                eval(['stride_dataY = app.data.' Ydata_name '.stride.mean;']);
                stride_percent = app.setting.gaitPecent;
                
                if app.XreverseCheckBox.Value == 1
                    stride_dataX = - stride_dataX;
                end
                if app.YreverseCheckBox.Value == 1
                    stride_dataY = - stride_dataY;
                end
                
                colorname = app.ColorMapDropDown.Value;
                colordata = app.othercolor(colorname,101);
                colordata = [colordata(2:101,:); 1 1 1];
                colormap(app.userfig, colordata);
                
                eval(['p = patch(stride_dataX(1:phase_len),stride_dataY(1:phase_len),stride_percent(1:phase_len),''edgecolor'',''flat'',''facecolor'',''none'',''LineWidth'',' linewidth ');']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);

            end
        end

        % Callback function
        function COMPButtonPushed(app, event)
            app.AnaDataGroup.SelectedTab = app.PLOTALLTab;
            app.PlotGroup.SelectedTab = app.COMPTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
        end

        % Callback function
        function VarAlterPlotButton_2Pushed(app, event)
            comp_num = length(app.data.comp.name);
            colormapname = app.ColorMapDropDown.Value;
            
            if comp_num == 0
                errordlg('Please add alter variables','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                app.ClearAllButton.ButtonPushedFcn(app,1);
                colordata = app.othercolor(colormapname,length(app.data.comp.name));
%                 colormap(app.userfig, colordata);
                
                data_mean = zeros(length(app.data.comp.name),1);
                data_std = zeros(length(app.data.comp.name),1);
                data_name = app.data.comp.name;
                
                for i = 1:length(app.data.comp.name)
                    eval(['data_mean(i) = app.data.comp.' char(app.data.comp.name(i)) '.' app.BarFeatureDropDown.Value '.mean;']);
                    eval(['data_std(i) = app.data.comp.' char(app.data.comp.name(i)) '.' app.BarFeatureDropDown.Value '.std;']);
                end
                
                numgroups = size(data_mean, 1);
                numbars = size(data_mean, 2);
                groupwidth = min(0.8, numbars/(numbars+1.5));
                bar_index = [1:numgroups]';
                
                b = bar(app.userfig, data_mean, 0.6, 'grouped');
                b.FaceColor = 'flat';
                set(b,'edgecolor','none');
                b.CData = colordata;
                hold on;
                
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = b;']);
                app.userbar = b;
                
                if strcmp(app.BarTypeDropDown.Value, 'error-bar')
                    for i = 1:numbars
                        x = (1:numgroups) - groupwidth/2 + (2*i-1) * groupwidth / (2*numbars);% Aligning error bar with individual bar
                        b = errorbar(app.userfig,x, data_mean(:,i), data_std(:,i), 'k', 'linestyle', 'none', 'LineWidth', 1, 'CapSize', 10);
                        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = b;']);
                    end
                end
                
                set(app.userfig, 'xtick', bar_index);
                set(app.userfig, 'xticklabel', data_name);
                set(app.userfig, 'TickLabelInterpreter', 'none');
                
                h = gca;
                h.Box = 'off';
                h.FontName = 'Times New Roman';
                h.FontSize = 12;
                h = gcf;
                h.Color = [1,1,1];
                app.IndexDropDown.Items = data_name;
            end
        end

        % Callback function
        function CHANGECOLORButtonPushed(app, event)
            app.setting.usercolor = uisetcolor(app.setting.usercolor);
            var_name = char(app.IndexDropDown.Value);
            data_name = app.data.comp.name;
            for i = 1:length(data_name)
                    if strcmp(data_name(i),var_name)
                        app.userbar.CData(i,:) = app.setting.usercolor;
                    end
            end
            app.Lamp.BackgroundColor = app.setting.usercolor;
        end

        % Button pushed function: GROUPBARButton
        function GROUPBARButtonPushed(app, event)
%             app.moveui('app.Panel_3',35,-520,10,200);
%             app.moveui('app.Panel_7',276,-576,10,200);
%             app.moveui('app.Panel_12',276,-576,10,200);
%             app.moveui('app.Panel_13',276,-576,10,200);
% %             app.moveui('app.Panel_2',35,30,10,200);
%             app.moveui('app.Panel_8',276,2,10,200);
%             app.moveui('app.Panel_4',1080,590,200,10);
%             app.moveui('app.Panel_6',1080,590,200,10);
%             app.moveui('app.Panel_5',296,590,200,10);
            for i = 1:length(app.PlotButtonGroup.Children)
                app.PlotButtonGroup.Children(i).BackgroundColor = app.setting.backcolorset.defult(1,:);
            end
            app.GROUPBARButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            
            app.PlotGroup.SelectedTab = app.BarTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
        end

        % Button pushed function: ClassAddButton_3
        function ClassAddButtonPushed(app, event)
            % data_name = char(app.DataListBox.Value);
            % if size(data_name,1) > 1
            %     data_name = data_name(1,:);
            % end
            % 
            % colordata = app.setting.usercolor;
            % s1 = uistyle('BackgroundColor', colordata);
            % 
            % if isempty(data_name)
            %     errordlg('Please select a Data','error');
            % else
            %     if isempty(app.GroupPlotTable.Data)
            %         app.GroupPlotTable.Data = {[data_name]};
            %         app.data.groupplot.colorlist = colordata;
            %     else
            %         app.GroupPlotTable.Data(end+1,:) = {[data_name]};
            %         app.data.groupplot.colorlist = [app.data.groupplot.colorlist; colordata];
            %     end
            %     row_num = size(app.GroupPlotTable.Data,1);
            %     addStyle(app.GroupPlotTable,s1,'row',[row_num]);
            % end

            data_name = char(app.DataListBox.Value);
            colordata = app.setting.usercolor;
            s1 = uistyle('BackgroundColor', colordata);

            if isempty(data_name)
                errordlg('Please select a Data','error');
            else
                
                if size(data_name,1) == 1
                    data_name_split = strsplit(data_name,'.');
                    
                    eval(['processflag = app.data.' data_name '.processFlag;']);
                    
                    if ~processflag
                        errordlg('Please process data first','error');
                    else
                        if isempty(app.data.groupbar.class.name)
                            app.data.groupbar.class.name = {data_name_split{2}};
                            eval(['app.data.groupbar.class.' data_name_split{2} ' = {data_name};']);
                            app.data.barplot.colorlist = colordata;
                        else
                            app.data.groupbar.class.name(end+1,:) = {data_name_split{2}};
                            eval(['app.data.groupbar.class.' data_name_split{2} ' = {data_name};']);
                            app.data.barplot.colorlist = [app.data.barplot.colorlist; colordata];
                        end
                        % app.ClassListBox.Items = app.data.groupbar.class.name;
                        app.ClassListTable.Data = app.data.groupbar.class.name;
                        row_num = size(app.ClassListTable.Data,1);
                        addStyle(app.ClassListTable,s1,'row',row_num);
                    end
                else
                    for i = 1:size(data_name,1)
                        
                        data_name_split = strsplit(data_name(i,:),'.');
                        eval(['processflag = app.data.' data_name(i,:) '.processFlag;']);
                        
                        if ~processflag
                            errordlg('Please process data first','error');
                        else
                            if isempty(app.data.groupbar.class.name)
                                app.data.groupbar.class.name = {data_name_split{2}};
                                eval(['app.data.groupbar.class.' data_name_split{2} ' = {data_name(i,:)};']);
                                app.data.barplot.colorlist = colordata;
                            else
                                app.data.groupbar.class.name(end+1,:) = {data_name_split{2}};
                                eval(['app.data.groupbar.class.' data_name_split{2} ' = {data_name(i,:)};']);
                                app.data.barplot.colorlist = [app.data.barplot.colorlist; colordata];
                            end
                            % app.ClassListBox.Items = app.data.groupbar.class.name;
                            app.ClassListTable.Data = app.data.groupbar.class.name;
                            row_num = size(app.ClassListTable.Data,1);
                            addStyle(app.ClassListTable,s1,'row',row_num);
                        end
                    end
                end
            end
            
        end

        % Button pushed function: ClassDeleteButton_3
        function ClassDeleteButtonPushed(app, event)
            % class_name = char(app.ClassListBox.Value);

%             nrow = app.data.groupplot.selectedRow;
%             app.GroupPlotTable.Data(nrow,:) = [];
%             app.data.groupplot.colorlist(nrow,:) = [];
%             for i = 1:size(app.data.groupplot.colorlist,1)
%                 s1 = uistyle('BackgroundColor', app.data.groupplot.colorlist(i,:));
% %                 row_num = size(app.GroupPlotTable.Data,1);
%                 addStyle(app.GroupPlotTable,s1,'row',i);
%             end

            nRow = app.data.barclass.selectedRow;

            if isempty(nRow)
                errordlg('Please select a class','error');
            else
                
                app.data.groupbar.class.name(nRow) = [];
                app.ClassListTable.Data(nRow,:) = [];
                app.data.barplot.colorlist(nRow,:) = [];
                class_name = app.data.groupbar.class.name{nRow};
                eval(['app.data.groupbar.class = rmfield(app.data.groupbar.class, ''' class_name ''');']);

                for i = 1:size(app.data.barplot.colorlist,1)
                    s1 = uistyle('BackgroundColor', app.data.barplot.colorlist(i,:));
                    addStyle(app.ClassListTable,s1,'row',i);
                end

                % for i = 1:length(app.data.groupbar.class.name)
                %     if strcmp(app.data.groupbar.class.name(i),class_name)
                %         app.data.groupbar.class.name(i) = [];
                %         break;
                %     end
                % end
                
                % eval(['app.data.groupbar.class = rmfield(app.data.groupbar.class, ''' class_name ''');']);
                % app.ClassListBox.Items = app.data.groupbar.class.name;
            end
        end

        % Button pushed function: ClassClearButton_3
        function ClassClearButtonPushed(app, event)
            app.data.groupbar.class = struct;
            app.data.groupbar.class.name = {};
            % app.ClassListBox.Items = app.data.groupbar.class.name;

            app.ClassListTable.Data = [];
            app.data.barplot.colorlist = [];
        end

        % Button pushed function: ClassUpButton_3
        function ClassUpButtonPushed(app, event)
            
            nRow = app.data.barclass.selectedRow;
            if nRow > 1
                name_temp = app.data.groupbar.class.name(nRow);
                app.data.groupbar.class.name(nRow) = app.data.groupbar.class.name(nRow-1);
                app.data.groupbar.class.name(nRow-1) = name_temp;

                color_temp = app.data.barplot.colorlist(nRow,:);
                app.data.barplot.colorlist(nRow,:) = app.data.barplot.colorlist(nRow-1,:);
                app.data.barplot.colorlist(nRow-1,:) = color_temp;

                app.ClassListTable.Data = app.data.groupbar.class.name;
                for i = 1:size(app.data.barplot.colorlist,1)
                    s1 = uistyle('BackgroundColor', app.data.barplot.colorlist(i,:));
                    addStyle(app.ClassListTable,s1,'row',i);
                end
            end
            
            % class_name = char(app.ClassListBox.Value);
            % if isempty(class_name)
            %     errordlg('Please select a class','error');
            % else
            %     for i = 1:length(app.data.groupbar.class.name)
            %         if strcmp(app.data.groupbar.class.name(i),class_name)
            %             if i > 1
            %                 name_temp = app.data.groupbar.class.name(i);
            %                 app.data.groupbar.class.name(i) = app.data.groupbar.class.name(i-1);
            %                 app.data.groupbar.class.name(i-1) = name_temp;
            %                 break;
            %             end
            %         end
            %     end
            %     app.ClassListBox.Items = app.data.groupbar.class.name;
            % end
        end

        % Button pushed function: ClassDownButton_3
        function ClassDownButtonPushed(app, event)
            nRow = app.data.barclass.selectedRow;
            if nRow < length(app.data.groupbar.class.name)
                name_temp = app.data.groupbar.class.name(nRow);
                app.data.groupbar.class.name(nRow) = app.data.groupbar.class.name(nRow+1);
                app.data.groupbar.class.name(nRow+1) = name_temp;

                color_temp = app.data.barplot.colorlist(nRow,:);
                app.data.barplot.colorlist(nRow,:) = app.data.barplot.colorlist(nRow+1,:);
                app.data.barplot.colorlist(nRow+1,:) = color_temp;

                app.ClassListTable.Data = app.data.groupbar.class.name;
                for i = 1:size(app.data.barplot.colorlist,1)
                    s1 = uistyle('BackgroundColor', app.data.barplot.colorlist(i,:));
                    addStyle(app.ClassListTable,s1,'row',i);
                end
            end
            
            % class_name = char(app.ClassListBox.Value);
            % if isempty(class_name)
            %     errordlg('Please select a class','error');
            % else
            %     for i = 1:length(app.data.groupbar.class.name)
            %         if strcmp(app.data.groupbar.class.name(i),class_name)
            %             if i < length(app.data.groupbar.class.name)
            %                 name_temp = app.data.groupbar.class.name(i);
            %                 app.data.groupbar.class.name(i) = app.data.groupbar.class.name(i+1);
            %                 app.data.groupbar.class.name(i+1) = name_temp;
            %                 break;
            %             end
            %         end
            %     end
            % 
            %     app.ClassListBox.Items = app.data.groupbar.class.name;
            % end
        end

        % Button pushed function: ClassRenameButton
        function ClassRenameButtonPushed(app, event)
            % class_name = char(app.ClassListBox.Value);
            nRow = app.data.barclass.selectedRow;
            
            if isempty(nRow)
                errordlg('Please select a class','error');
            else
                new_name = char(inputdlg('Input the new name：','ReName'));
                if ~isempty(new_name)

                    class_name = app.data.groupbar.class.name{nRow};
                    app.data.groupbar.class.name(nRow) = {new_name};
                    
                    % for i = 1:length(app.data.groupbar.class.name)
                    %     if strcmp(app.data.groupbar.class.name(i),class_name)
                    %         app.data.groupbar.class.name(i) = {new_name};
                    %         break;
                    %     end
                    % end
                    
                    eval(['app.data.groupbar.class.' new_name ' = app.data.groupbar.class.' class_name ';']);
                    eval(['app.data.groupbar.class = rmfield(app.data.groupbar.class, ''' class_name ''');']);
                    % app.ClassListBox.Items = app.data.groupbar.class.name;

                    app.ClassListTable.Data = app.data.groupbar.class.name;
                    % addStyle(app.ClassListTable,s1,'row',row_num);

                end
            end
        end

        % Button pushed function: GroupAddButton
        function GroupAddButtonPushed(app, event)
            var_name = char(app.VariableListBox.Value);
            feature_name = char(app.BarFeatureDropDown.Value);
            phase_name = char(app.BarPhaseDropDown.Value);
            if strcmp('whole',phase_name)
                phase_name = '';
            else
                phase_name = ['_', phase_name];
            end
            feature_name = [feature_name, phase_name];
            
            if isempty(var_name)
                errordlg('Please select a Variable','error');
            else
                var_name = [var_name, '.', feature_name];
                if isempty(app.data.groupbar.group.name)
                    app.data.groupbar.group.name = {var_name};
                    app.data.groupbar.group.name_re = {var_name};
%                     eval(['app.data.groupbar.group.' var_name ' = {var_name};']);
                else
                    app.data.groupbar.group.name(end+1) = {var_name};
                    app.data.groupbar.group.name_re(end+1) = {var_name};
%                     eval(['app.data.groupbar.group.' var_name ' = {var_name};']);
                end
                app.GroupListBox.Items = app.data.groupbar.group.name;
            end
        end

        % Button pushed function: GroupDeleteButton
        function GroupDeleteButtonPushed(app, event)
            group_name = char(app.GroupListBox.Value);
            
            if isempty(group_name)
                errordlg('Please select a group','error');
            else
                for i = 1:length(app.data.groupbar.group.name)
                    if strcmp(app.data.groupbar.group.name(i),group_name)
                        app.data.groupbar.group.name(i) = [];
                        app.data.groupbar.group.name_re(i) = [];
                        break;
                    end
                end
                
%                 eval(['app.data.groupbar.group = rmfield(app.data.groupbar.group, ''' group_name ''');']);
                app.GroupListBox.Items = app.data.groupbar.group.name;
            end
        end

        % Button pushed function: GroupClearButton
        function GroupClearButtonPushed(app, event)
            app.data.groupbar.group = struct;
            app.data.groupbar.group.name = {};
            app.data.groupbar.group.name_re = {};
            app.GroupListBox.Items = app.data.groupbar.group.name;
        end

        % Button pushed function: GroupUpButton
        function GroupUpButtonPushed(app, event)
            group_name = char(app.GroupListBox.Value);
            
            if isempty(group_name)
                errordlg('Please select a group','error');
            else
                for i = 1:length(app.data.groupbar.group.name)
                    if strcmp(app.data.groupbar.group.name(i),group_name)
                        if i > 1
                            name_temp = app.data.groupbar.group.name(i);
                            app.data.groupbar.group.name(i) = app.data.groupbar.group.name(i-1);
                            app.data.groupbar.group.name(i-1) = name_temp;
                            name_temp = app.data.groupbar.group.name_re(i);
                            app.data.groupbar.group.name_re(i) = app.data.groupbar.group.name_re(i-1);
                            app.data.groupbar.group.name_re(i-1) = name_temp;
                            break;
                        end
                    end
                end
                app.GroupListBox.Items = app.data.groupbar.group.name;
            end
        end

        % Button pushed function: GroupDownButton
        function GroupDownButtonPushed(app, event)
            group_name = char(app.GroupListBox.Value);
            
            if isempty(group_name)
                errordlg('Please select a group','error');
            else
                for i = 1:length(app.data.groupbar.group.name)
                    if strcmp(app.data.groupbar.group.name(i),group_name)
                        if i < length(app.data.groupbar.group.name)
                            name_temp = app.data.groupbar.group.name(i);
                            app.data.groupbar.group.name(i) = app.data.groupbar.group.name(i+1);
                            app.data.groupbar.group.name(i+1) = name_temp;
                            name_temp = app.data.groupbar.group.name_re(i);
                            app.data.groupbar.group.name_re(i) = app.data.groupbar.group.name_re(i+1);
                            app.data.groupbar.group.name_re(i+1) = name_temp;
                            break;
                        end
                    end
                end
                app.GroupListBox.Items = app.data.groupbar.group.name;
            end
        end

        % Button pushed function: GroupRenameButton
        function GroupRenameButtonPushed(app, event)
            group_name = char(app.GroupListBox.Value);
            
            if isempty(group_name)
                errordlg('Please select a group','error');
            else
                new_name = char(inputdlg('Input the new name：','ReName'));
                if ~isempty(new_name)
                    for i = 1:length(app.data.groupbar.group.name)
                        if strcmp(app.data.groupbar.group.name(i),group_name)
                            app.data.groupbar.group.name(i) = {new_name};
                            break;
                        end
                    end
                    
                    eval(['app.data.groupbar.group.' new_name ' = app.data.groupbar.group.' group_name ';']);
                    eval(['app.data.groupbar.group = rmfield(app.data.groupbar.group, ''' group_name ''');']);
                    app.GroupListBox.Items = app.data.groupbar.group.name;
                end
            end
        end

        % Callback function
        function ALTERNEWButton_5Pushed(app, event)
            figure('position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
            app.userfig = gca;
            hold on;
            h = gca;
            h.Box = 'off';
            h.FontName = 'Times New Roman';
            h.FontSize = 14;
            h = gcf;
            h.Color = [1,1,1];
            app.userpl = struct;
        end

        % Button pushed function: VarAlterPlotButton
        function VarAlterPlotButton_3Pushed(app, event)
            class_num = length(app.data.groupbar.class.name);
            group_num = length(app.data.groupbar.group.name);
            class_name = app.data.groupbar.class.name;
            group_name = app.data.groupbar.group.name;
            colormapname = app.ColorMapDropDown.Value;
            % colordata = app.othercolor(colormapname, class_num);
            colordata = app.data.barplot.colorlist;
            colordatastack = app.othercolor(colormapname, group_num);
%             feature = char(app.BarFeatureDropDown.Value);
            slectedFigureID = str2num(app.FigureDropDown.Value);
            flagEdge = app.BarEdgeCheckBox.Value;
            flagFixedWidth = app.BarFixedWidthCheckBox.Value;
            if flagFixedWidth
                widthBar = app.BarWidthEditField.Value;
            else
                widthBar = 0.8;
            end
            
            if class_num == 0
                errordlg('Please add class','error');
            elseif group_num == 0
                errordlg('Please add group','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                app.ClearAllButton.ButtonPushedFcn(app,1);
                
                data_mean = zeros(group_num,class_num);
                data_std  = zeros(group_num,class_num);
                
                for i = 1:class_num
                    eval(['data_name = char(app.data.groupbar.class.' char(class_name(i)) ');']);
                    for j = 1:group_num
%                         eval(['var_feature_name = char(app.data.groupbar.group.' char(group_name(j)) ');']);
                        var_feature_name = char(app.data.groupbar.group.name_re(j));
                        eval(['data_mean(j,i) = app.data.' data_name  '.' var_feature_name '.mean;']);
                        eval([' data_std(j,i) = app.data.' data_name  '.' var_feature_name '.std;']);
                    end
                end
                
                
                numgroups = group_num;
                numbars = class_num;
                bar_index = [1:numgroups]';

                if max(strcmp({'bar','error','stack','horizontal','horiz-stack'},app.BarTypeDropDown.Value))
                    bar_mode = 'grouped';
                    if max(strcmp({'stack','horiz-stack'},app.BarTypeDropDown.Value))
                        bar_mode = 'stacked';
                        data_mean = data_mean';
                    end
                    if max(strcmp({'horizontal'},app.BarTypeDropDown.Value))
                        data_mean = flip(data_mean);
                        data_mean = flip(data_mean,2);
                        b = barh(app.userfig, data_mean, widthBar, bar_mode, 'FaceColor', 'flat');
                    elseif max(strcmp({'horiz-stack'},app.BarTypeDropDown.Value))
                        b = barh(app.userfig, data_mean, widthBar, bar_mode, 'FaceColor', 'flat');
                    else
                        b = bar(app.userfig, data_mean, widthBar, bar_mode, 'FaceColor', 'flat');
                    end
                    if ~flagEdge
                        set(b,'edgecolor','none');
                    end
                    if numgroups == 1
                        b.CData = colordata;
                    else
                        for i = 1:length(b)
                            if max(strcmp({'stack','horiz-stack'},app.BarTypeDropDown.Value))
                                b(i).CData = repmat(colordatastack(i,:), class_num, 1);
                            elseif max(strcmp({'horizontal'},app.BarTypeDropDown.Value))
                                colordata_temp = flip(colordata);
                                b(i).CData = repmat(colordata_temp(i,:), group_num, 1);
                            else
                                b(i).CData = repmat(colordata(i,:), group_num, 1);
                            end
                        end
                    end
                    
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = b;']);
                    app.userbar = b;
                    
                    if strcmp(app.BarTypeDropDown.Value, 'error')
                        hold on;
                        groupwidth = min(0.8, numbars/(numbars+1.5)); 
                        if numgroups == 1
                            for i = 1:numbars
                                x = i;% Aligning error bar with individual bar
                                b = errorbar(app.userfig,x, data_mean(:,i), data_std(:,i), 'k', 'linestyle', 'none', 'LineWidth', 1, 'CapSize', 10);
                                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = b;']);
                            end
                        else
                            for i = 1:numbars
                                x = (1:numgroups) - groupwidth/2 + (2*i-1) * groupwidth / (2*numbars);% Aligning error bar with individual bar
                                b = errorbar(app.userfig,x, data_mean(:,i), data_std(:,i), 'k', 'linestyle', 'none', 'LineWidth', 1, 'CapSize', 10);
                                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = b;']);
                            end
                        end
                    end
                end
                
                if numgroups == 1 || max(strcmp({'stack','horiz-stack'},app.BarTypeDropDown.Value))
                    if max(strcmp({'horiz-stack'},app.BarTypeDropDown.Value))
                        set(app.userfig, 'ytick', [1:numbars]');
                        set(app.userfig, 'yticklabel', flip(class_name));
                        set(app.userfig, 'TickLabelInterpreter', 'none');
                    else
                        set(app.userfig, 'xtick', [1:numbars]');
                        set(app.userfig, 'xticklabel', class_name);
                        set(app.userfig, 'TickLabelInterpreter', 'none');
                    end
                    l = legend(group_name);
                else
                    if max(strcmp({'horizontal'},app.BarTypeDropDown.Value))
                        set(app.userfig, 'ytick', bar_index);
                        set(app.userfig, 'yticklabel', flip(group_name));
                        set(app.userfig, 'TickLabelInterpreter', 'none');
                        l = legend(flip(class_name));
                    else
                        set(app.userfig, 'xtick', bar_index);
                        set(app.userfig, 'xticklabel', group_name);
                        set(app.userfig, 'TickLabelInterpreter', 'none');
                        l = legend(class_name);
                    end
%                     if 
                    

                end
                if ~max(strcmp({'stack','horiz-stack'},app.BarTypeDropDown.Value))
                    app.GroupIndexDropDown.Items = class_name;
                else
                    app.GroupIndexDropDown.Items = group_name;
                end
                
                set(l, 'Box', 'off');
                set(l, 'Interpreter', 'none');
                h = gca;
                h.Box = 'off';
                h.FontName = 'Times New Roman';
                h.FontSize = 12;
                h = gcf;
                h.Color = [1,1,1];
            end
        end

        % Button pushed function: CHANGECOLORButton
        function CHANGECOLORButton_2Pushed(app, event)
%             app.setting.usercolor = uisetcolor(app.setting.usercolor);
            var_name = char(app.GroupIndexDropDown.Value);
            class_name = app.data.groupbar.class.name;
            group_name = app.data.groupbar.group.name;
            class_num = length(app.data.groupbar.class.name);
            group_num = length(app.data.groupbar.group.name);
            if group_num == 1
                for i = 1:length(class_name)
                    if strcmp(class_name(i),var_name)
                        app.userbar.CData(i,:) = app.setting.usercolor;
                    end
                end
            else
                if ~max(strcmp({'stack','horiz-stack'},app.BarTypeDropDown.Value))
                    for i = 1:length(class_name)
                            if strcmp(class_name(i),var_name)
                                app.userbar(i).CData = repmat(app.setting.usercolor, group_num, 1);
                            end
                    end
                else
                    for i = 1:length(group_name)
                            if strcmp(group_name(i),var_name)
                                app.userbar(i).CData = repmat(app.setting.usercolor, class_num, 1);
                            end
                    end
                end
            end
%             app.Lamp.BackgroundColor = app.setting.usercolor;
        end

        % Callback function
        function EndTimeSliderValueChanged(app, event)
            app.ClearLastButton.ButtonPushedFcn(app,1);
            app.ALTERStridePlotButton_2.ButtonPushedFcn(app,1);
        end

        % Button pushed function: ExtractSCONEDataButton
        function ExtractSCONEDataButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.sto','Select the SCONE STO file');
        
            if indx == 1
                data_name = file(1:end-4);
                scone_data = {};
                
                % 加载数据
                delimmiter = '	';
                filename = fullfile(path, file);
                nheaderlines = 7;
                DataStru = importdata(filename,delimmiter,nheaderlines);
                SconeData.name = reshape(DataStru.colheaders,[length(DataStru.colheaders) 1]);
                SconeData.data = DataStru.data;
                
                for var_index = 1:length(SconeData.name)
                    var_name = char(SconeData.name(var_index));
                    var_name = strrep(var_name,'.','_');
                    var_name = strrep(var_name,'-','_');
                    eval(['scone_data.name(var_index,1) = {var_name};']);
                    eval(['scone_data.' var_name ' = SconeData.data(:,var_index)'';']);
                end
                
                C = 1; %180/pi;
                
                time = scone_data.time';
                pelvis_tilt = scone_data.pelvis_tilt'*C;pelvis_tx = scone_data.pelvis_tx';pelvis_ty = scone_data.pelvis_ty';
                hip_flexion_r = scone_data.hip_flexion_r'*C;knee_angle_r = scone_data.knee_angle_r'*C;ankle_angle_r = scone_data.ankle_angle_r'*C;
                hip_flexion_l = scone_data.hip_flexion_l'*C;knee_angle_l = scone_data.knee_angle_l'*C;ankle_angle_l = scone_data.ankle_angle_l'*C;
%                 lumbar_extension = zeros(length(scone_data.time),1);
%                 pelvis_move = ((1:length(pelvis_tx))/length(pelvis_tx)*pelvis_tx(end))';
%                 pelvis_tx = pelvis_tx - pelvis_move;
%                 Kine_data = [time,pelvis_tilt,pelvis_tx,pelvis_ty,hip_flexion_r,knee_angle_r,ankle_angle_r,hip_flexion_l,knee_angle_l,ankle_angle_l,lumbar_extension];
%                 
%                 fp = fopen('sim_kine.sto','w+');
%                 fprintf(fp,'%s\r\n%s\r\n%s\r\n%s\r\n%s\r\n\r\n%s\r\n%s\r\n\r\n%s\r\n','Coordinates','version=1',['nRows=' num2str(length(time))],'nColumns=11','inDegrees=no','Units are S.I. units (second, meters, Newtons, ...)','Angles are in rad.','endheader');
%                 fprintf(fp,'%s %s %s %s %s %s %s %s %s %s %s\r\n','time','pelvis_tilt','pelvis_tx','pelvis_ty','hip_flexion_r','knee_angle_r','ankle_angle_r','hip_flexion_l','knee_angle_l','ankle_angle_l','lumbar_extension');
%                 fprintf(fp,'%f %f %f %f %f %f %f %f %f %f %f\r\n',Kine_data);
                Kine_data = [time,pelvis_tilt,pelvis_tx,pelvis_ty,hip_flexion_r,knee_angle_r,ankle_angle_r,hip_flexion_l,knee_angle_l,ankle_angle_l];
                
                fp = fopen('sim_kine.sto','w+');
                fprintf(fp,'%s\r\n%s\r\n%s\r\n%s\r\n%s\r\n\r\n%s\r\n%s\r\n\r\n%s\r\n','Coordinates','version=1',['nRows=' num2str(length(time))],'nColumns=10','inDegrees=no','Units are S.I. units (second, meters, Newtons, ...)','Angles are in rad.','endheader');
                fprintf(fp,'%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\r\n','time','pelvis_tilt','pelvis_tx','pelvis_ty','hip_flexion_r','knee_angle_r','ankle_angle_r','hip_flexion_l','knee_angle_l','ankle_angle_l');
                fprintf(fp,'%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\r\n',Kine_data');
                
                ground_force_vx = scone_data.leg1_r_grf_x';ground_force_vy = scone_data.leg1_r_grf_y';ground_force_vz = scone_data.leg1_r_grf_z';
                ground_force_px = scone_data.leg1_r_cop_x';ground_force_py = scone_data.leg1_r_cop_y';ground_force_pz = scone_data.leg1_r_cop_z';
                l_ground_force_vx = scone_data.leg0_l_grf_x';l_ground_force_vy = scone_data.leg0_l_grf_y';l_ground_force_vz = scone_data.leg0_l_grf_z';
                l_ground_force_px = scone_data.leg0_l_cop_x';l_ground_force_py = scone_data.leg0_l_cop_y';l_ground_force_pz = scone_data.leg0_l_cop_z';
                ground_torque_x = zeros(length(time),1);ground_torque_y = zeros(length(time),1);ground_torque_z = zeros(length(time),1);
                l_ground_torque_x = zeros(length(time),1);l_ground_torque_y = zeros(length(time),1);l_ground_torque_z = zeros(length(time),1);
%                 ground_torque_x = scone_data.foot_r_moment_x';ground_torque_y = scone_data.foot_r_moment_y';ground_torque_z = scone_data.foot_r_moment_z';
%                 l_ground_torque_x = scone_data.foot_l_moment_x';l_ground_torque_y = scone_data.foot_l_moment_y';l_ground_torque_z = scone_data.foot_l_moment_z';
%                 for i = 1:length(time)
%                     if ground_force_px(i) > 0.0001
%                         ground_force_px(i) = ground_force_px(i) - pelvis_move(i);
%                     end
%                     if l_ground_force_px(i) > 0.0001
%                         l_ground_force_px(i) = l_ground_force_px(i) - pelvis_move(i);
%                     end
%                 end
                Grf_data = [time, ground_force_vx, ground_force_vy, ground_force_vz, ground_force_px, ground_force_py, ground_force_pz,...
                    l_ground_force_vx, l_ground_force_vy, l_ground_force_vz, l_ground_force_px, l_ground_force_py, l_ground_force_pz,...
                    ground_torque_x, ground_torque_y, ground_torque_z, l_ground_torque_x, l_ground_torque_y, l_ground_torque_z];
                
                fp = fopen('sim_grf.mot','w+');
                fprintf(fp,'%s\r\n%s\r\n%s\r\n%s\r\n%s\r\n%s\r\n','sim_grf.mot','version=1',['nRows=' num2str(length(time))],'nColumns=19','inDegrees=no','endheader');
                fprintf(fp,'%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\r\n',...
                    'time', 'ground_force_vx', 'ground_force_vy', 'ground_force_vz', 'ground_force_px', 'ground_force_py', 'ground_force_pz',...
                    '1_ground_force_vx', '1_ground_force_vy', '1_ground_force_vz', '1_ground_force_px', '1_ground_force_py', '1_ground_force_pz',...
                    'ground_torque_x', 'ground_torque_y', 'ground_torque_z', '1_ground_torque_x', '1_ground_torque_y', '1_ground_torque_z');
                fprintf(fp,'%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\r\n',Grf_data');
            end
        end

        % Callback function
        function ALTERNEWButton_6Pushed(app, event)
            comp_num = length(app.data.comp.name);
            colormapname = app.ColorMapDropDown.Value;
            
            if comp_num == 0
                errordlg('Please add alter variables','error');
            else
                bar_data = struct;
                
                data_mean = zeros(length(app.data.comp.name),1);
                data_std = zeros(length(app.data.comp.name),1);
                data_name = app.data.comp.name;
                
                for i = 1:length(app.data.comp.name)
                    eval(['data_mean(i) = app.data.comp.' char(app.data.comp.name(i)) '.' app.BarFeatureDropDown.Value '.mean;']);
                    eval(['data_std(i) = app.data.comp.' char(app.data.comp.name(i)) '.' app.BarFeatureDropDown.Value '.std;']);
                end
                
                bar_data.name = data_name;
                bar_data.mean = data_mean';
                bar_data.std  = data_std';
                
                [file, path]=uiputfile('AnaBarData.mat','Save data name');
                if ~(isequal(file,0))  
                    save(fullfile(path, file),'bar_data');
                end
            
            end
        end

        % Button pushed function: ALTERNEWButton_9
        function ALTERNEWButton_7Pushed(app, event)
            class_num = length(app.data.groupbar.class.name);
            group_num = length(app.data.groupbar.group.name);
            class_name = app.data.groupbar.class.name;
            group_name = app.data.groupbar.group.name;
            feature = char(app.BarFeatureDropDown.Value);
            
            if class_num == 0
                errordlg('Please add class','error');
            elseif group_num == 0
                errordlg('Please add group','error');
            else
                app.ClearAllButton.ButtonPushedFcn(app,1);
                
                group_bar_data = struct;
                group_bar_data.name = {};
                data_mean = zeros(group_num,class_num);
                data_std  = zeros(group_num,class_num);
                
                for i = 1:class_num
                    eval(['data_name = char(app.data.groupbar.class.' char(class_name(i)) ');']);
                    for j = 1:group_num
                        eval(['var_name = char(app.data.groupbar.group.' char(group_name(j)) ');']);
                        group_bar_data.name(j,i) = {[data_name '_' var_name]};
                        eval(['data_mean(j,i) = app.data.' data_name  '.' var_name '.' feature '.mean;']);
                        eval([' data_std(j,i) = app.data.' data_name  '.' var_name '.' feature '.std;']);
                    end
                end
                
                group_bar_data.mean = data_mean;
                group_bar_data.std  = data_std;
                
                [file, path]=uiputfile('AnaGroupBarData.mat','Save data name');
                if ~(isequal(file,0))  
                    save(fullfile(path, file),'group_bar_data');
                end
                
            end
        end

        % Button pushed function: DeleteStepsButton
        function DeleteStepsButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            data_name_split = strsplit(data_name,'.');
            eval(['name_temp = app.data.' data_name '.name;']);
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            else
                
                eval(['step_num = size(app.data.' data_name '.DesTau.stride.each,1);']);
                step_name = {};
                for i = 1:step_num
                    step_name(i) = {['step_' num2str(i)]};
                end
                
                app.StepListBox.Items = step_name;
                app.ToolTabGroup.SelectedTab = app.StepSelectTab;
                
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            data_name_split = strsplit(data_name,'.');
            eval(['name_temp = app.data.' data_name '.name;']);
            
            stride_percent = app.setting.gaitPecent/100;
            lenGait = length(stride_percent);
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
%             app.moveui('app.Panel_4_4',1080,590,100,10);
            app.ToolTabGroup.SelectedTab = app.VarEditTab;
            
            if ~processflag
                errordlg('Please process data first','error');
            else
                
                step_del_value = app.StepListBox.Value;
                
                if isempty(step_del_value)
                    msgbox('No Delete');
                else
                    
                    eval(['var_name = app.data.' data_name '.name;'])
                    
                    step_del_num = length(step_del_value);
                    step_del_list = zeros(1,length(step_del_value));
                    
                    
                    for i = 1:step_del_num
                        step_del_value_this = regexp(step_del_value{i},'\d*','match');
                        step_del_list(i) = str2double(step_del_value_this);
                    end
                    
                    stride_time = app.data.dspace.(data_name_split{2}).step_time.max.each';
                    for i = 1:length(var_name)
                        
                        eval(['data_temp = app.data.' data_name '.' var_name{i} ';'])

                        app.VariableListBox.Value = name_temp(i); pause(0.001);
                        if strcmp(name_temp{i},'time')
                            continue;
                        end

                        app.data.dspace.(data_name_split{2}).(name_temp{i}) = ...
                            app.computeDataFeature(app.data.dspace.(data_name_split{2}).(name_temp{i}),stride_time,step_del_list);
   
                    end

                    msgbox('Delete Success');
                end
            end
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            data_name_split = strsplit(data_name,'.');
            eval(['name_temp = app.data.' data_name '.name;']);
            var_name_pick = char(app.VariableListBox.Value);

            stride_percent = app.setting.gaitPecent/100;
            lenGait = length(stride_percent);
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            app.ToolTabGroup.SelectedTab = app.VarEditTab;

            feature_name = app.FeatureDropDown.Value;
            phase_name = app.PhaseDropDown.Value;
            if strcmp({'whole'},phase_name)
                phase_name = '';
            else
                phase_name = ['_' phase_name];
            end
            
            if ~processflag
                errordlg('Please process data first','error');
            else
                eval(['stride_data = app.data.' data_name '.' var_name_pick '.' feature_name phase_name '.each;']);
                remove_base = app.BaselineEditField.Value;
                if app.BiggerButton.Value == true
                    step_del_list = find(stride_data>remove_base);
                else
                    step_del_list = find(stride_data<remove_base);
                end
                % disp(step_del_list);

                eval(['var_name = app.data.' data_name '.name;'])
                stride_time = app.data.dspace.(data_name_split{2}).step_time.max.each';
                for i = 1:length(var_name)
                    
                    eval(['data_temp = app.data.' data_name '.' var_name{i} ';'])

                    app.VariableListBox.Value = name_temp(i); pause(0.001);
                    if strcmp(name_temp{i},'time')
                        continue;
                    end

                    app.data.dspace.(data_name_split{2}).(name_temp{i}) = ...
                        app.computeDataFeature(app.data.dspace.(data_name_split{2}).(name_temp{i}),stride_time,step_del_list);

                end

                msgbox('Delete Success');
            end
        end

        % Button pushed function: CANCELButton
        function CANCELButtonPushed(app, event)
%             app.moveui('app.Panel_4_4',1080,590,100,10);
            app.ToolTabGroup.SelectedTab = app.LoadTab;
        end

        % Callback function
        function DiveideScanButtonPushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            data_name_split = strsplit(data_name,'.');
            eval(['name_temp = app.data.' data_name '.name;']);
            
            if strcmp(data_name_split{1}, 'dspace')
                if ismember(1,strcmp(name_temp, 'scan_step'))
                    eval(['scan_step = app.data.' data_name '.scan_step.origin;']);
                    if ~isempty(find(scan_step>0,1))
                        
                        scan_index = [];
                        
                        for i = 1:length(scan_step)-1
                            if scan_step(i) ~= scan_step(i+1)
                                scan_index = [scan_index, i];
                            end
                        end
                        
                        for ii = 1:length(scan_index)-1
                            data_name_scan = [data_name '_Scan_Mode_' num2str(ii)];
                            for i = 1:length(name_temp)
                                eval(['var_data = app.data.' data_name '.' char(name_temp(i)) '.origin;']);
                                eval(['app.data.' data_name_scan '.' char(name_temp(i)) '.origin = var_data((scan_index(ii)-10):(scan_index(ii+1)+10));']);
                            end
                            eval(['app.data.' data_name_scan '.name = name_temp;']);
                            eval(['app.data.' data_name_scan '.processFlag = false;']);
                            
                            if isempty(app.data.name)
                                app.data.name = {data_name_scan};
                            else
                                app.data.name(end+1) = {data_name_scan};
                            end
                        end
                        
                        for i = 1:length(app.data.name)
                            if strcmp(app.data.name(i),data_name)
                                app.data.name(i) = [];
                                break;
                            end
                        end
                        
                        data_name = strsplit(data_name,'.');
                        eval(['app.data.' data_name{1} ' = rmfield(app.data.' data_name{1} ', ''' data_name{2} ''');']);
                        app.DataListBox.Items = app.data.name;
                        
                        app.updateVarTable();

                    end
                end
            end
        end

        % Button pushed function: NEWSubButton
        function NEWSubButtonPushed(app, event)
            Row_num = str2num(char(app.SubRowDropDown.Value));
            Col_num = str2num(char(app.SubColDropDown.Value));
            app.setting.subplot_col = Col_num;
            app.setting.subplot_row = Row_num;
            
            slectedFigureID = str2num(app.FigureDropDown.Value);
            if isempty(slectedFigureID) || (slectedFigureID == length(app.figureList.list) && app.figureList.figThis == slectedFigureID)
                
                app.figureList.list = [app.figureList.list, length(app.figureList.list)+1];
                fighandle = figure(app.figureList.list(end));
    %                 fighandle = figure(app.figureList.list(end),'position',[0,app.setting.screen(2)-565,app.setting.FigureW,app.setting.FigureH]);
                eval(['app.figureList.figs.f' num2str(app.figureList.list(end)) ' = fighandle;']);
                set(fighandle,'position',[app.setting.FigureX,app.setting.FigureY,app.setting.FigureW,app.setting.FigureH]);
                fig_name = {};
                
                for i = 1:length(app.figureList.list)
                    fig_name(i) = {num2str(app.figureList.list(i))};
                end
                
                app.FigureDropDown.Items = fig_name;
                app.FigureDropDown.Value = fig_name{end};
                app.figureList.figThis = app.figureList.list(end);
                eval(['app.userpl.f' num2str(app.figureList.list(i)) ' = struct;']);
                
            elseif slectedFigureID ~= length(app.figureList.list) || app.figureList.figThis ~= slectedFigureID
                eval(['figureIsOpen = ishandle(app.figureList.figs.f' num2str(slectedFigureID) ');']);
                if figureIsOpen
                    eval(['figure(app.figureList.figs.f' num2str(slectedFigureID) ')']);
                else
                    errordlg('The figure is closed','error');
                end
                app.figureList.figThis = slectedFigureID;
            else
                disp("Create Figure Failed")
            end


            for i = 1:app.setting.subplot_row
                for j = 1:app.setting.subplot_col
                    subplot(app.setting.subplot_row,app.setting.subplot_col,(i-1)*app.setting.subplot_col+j);
                    app.userfig = gca;
                    hold on;
                    h = gca;
                    h.Box = 'off';
                    h.FontName = 'Times New Roman';
                    
                    h.LineWidth = app.setting.XAxisThickness;
                    h.FontSize = app.setting.FontSize;
                    if app.setting.Xdensity ~= 10
                        h.XTick = 0:app.setting.Xdensity:100;
                        xlim([0 100]);
                    end
                    
                    h = gcf;
                    h.Color = [1,1,1];
                    
                    eval(['app.usersubfigmap.R' num2str(i) 'C' num2str(j) ' = gca;']);
                    
                    if app.EdgeCheckBox.Value == 0
                        sub_axes_x = j*1/app.setting.subplot_col - 1/app.setting.subplot_col;
                        sub_axes_y = 1-i*1/app.setting.subplot_row; % y是从上往下的
                        sub_axes_w = 1/app.setting.subplot_col;
                        sub_axes_h = 1/app.setting.subplot_row;
                        set(gca, 'OuterPosition', [sub_axes_x, sub_axes_y, sub_axes_w, sub_axes_h]); % 重设OuterPosition
                         
                        % TightInset的位置
                        inset_vectior = get(gca, 'TightInset');
                        inset_x = inset_vectior(1);
                        inset_y = inset_vectior(2);
                        inset_w = inset_vectior(3);
                        inset_h = inset_vectior(4);
                         
                        % OuterPosition的位置
                        outer_vector = get(gca, 'OuterPosition');
                        pos_new_x = outer_vector(1) + inset_x; % 将Position的原点移到到TightInset的原点
                        pos_new_y = outer_vector(2) + inset_y;
                        pos_new_w = outer_vector(3) - inset_w - inset_x; % 重设Position的宽
                        pos_new_h = outer_vector(4) - inset_h - inset_y; % 重设Position的高
                        
                        % 重设Position
                        set(gca, 'Position', [pos_new_x, pos_new_y, pos_new_w, pos_new_h]);
                    end 
                end
            end
            
            app.userpl = struct;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            Row_num = str2num(char(app.SubRowDropDown.Value));
            Col_num = str2num(char(app.SubColDropDown.Value));
            
            eval(['app.userfig = app.usersubfigmap.R' num2str(Row_num) 'C' num2str(Col_num) ';']);

        end

        % Callback function
        function TRAJETOPTButtonPushed(app, event)
            app.AnaDataGroup.SelectedTab = app.PLOTALLTab;
            app.PlotGroup.SelectedTab = app.OPTTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
        end

        % Button pushed function: ALTERStridePlotButton_3
        function ALTERStridePlotButton_3Pushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
%             colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            stepinterval = str2num(char(app.IntervalStepsDropDown.Value));
            stride_percent = app.setting.gaitPecent;
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else
                eval(['data_temp =   app.data.' data_name '.' var_name '.stride;']);
                
                % 横坐标转化为时间
                XTimeFlag = app.TimeCheckBox.Value;
                if XTimeFlag
                    xlabel('Time (s)');
                end
                
                if app.ReverseCheckBox.Value == 1
                    stride_data = -data_temp.each;
                else
                    stride_data = data_temp.each;
                end
                
                datalen = size(stride_data,1);
                plotlen = 1+floor((datalen-1)/stepinterval);
                linewidth = char(app.LineWidthDropDown.Value);
                colorname = char(app.ColorMapDropDown.Value);
                colordata = app.othercolor(colorname,plotlen);
                for stride_index = 1:stepinterval:datalen
                    if XTimeFlag
                        eval(['time_temp = app.data.' data_name '.time.stride.each(stride_index,:);']);
                        stride_time = stride_percent / 100 * (max(time_temp)-min(time_temp));
                        eval(['p = plot(app.userfig, stride_time, stride_data(stride_index,:),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata(1+floor((stride_index-1)/stepinterval),:) );']);
                    else
                        eval(['p = plot(app.userfig, stride_percent, stride_data(stride_index,:),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata(1+floor((stride_index-1)/stepinterval),:) );']);
                    end
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
            end
        end

        % Button pushed function: ALTERStridePlotButton_4
        function ALTERStridePlotButton_4Pushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            stepinterval = str2num(char(app.StepsDropDown.Value));
            colortype = char(app.LinecolorDropDown.Value);
            plottype = char(app.PlotTypeDropDown.Value);
            stride_percent = app.setting.gaitPecent;
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else
                eval(['data_temp =   app.data.' data_name '.' var_name '.stride;']);
                
                if app.ReverseCheckBox.Value == 1
                    stride_data = -data_temp.each;
                else
                    stride_data = data_temp.each;
                end
                
                [peak_torque,peak_time] = max(stride_data');
                peak_time = peak_time/10;
                
                peak_torque = smooth(peak_torque,stepinterval);
                peak_time = smooth(peak_time,stepinterval);
                datalen = size(stride_data,1);
%                 plotlen = 1+floor((datalen-1)/stepinterval);
                plotlen = datalen;
                linewidth = char(app.LineWidthDropDown.Value);
                
                stepinterval = 1;
                
                if strcmp(plottype,'BOTH')
                    if strcmp(colortype,'Changed')
                        colorname = char(app.ColorMapDropDown.Value);
                        colordata = app.othercolor(colorname,plotlen);
                        for stride_index = 1:(datalen-stepinterval)
                            eval(['p = plot(app.userfig, peak_time([stride_index,stride_index+stepinterval]), peak_torque([stride_index,stride_index+stepinterval]),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata(1+floor((stride_index-1)/stepinterval),:) );']);
                            eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                            eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                        end
                    else
                        eval(['p = plot(app.userfig, peak_time(1:stepinterval:datalen), peak_torque(1:stepinterval:datalen),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata );']);
                        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                    end
                elseif strcmp(plottype,'PeakTime')
                    eval(['p = plot(app.userfig, (1:stepinterval:datalen), peak_time(1:stepinterval:datalen),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata );']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                elseif strcmp(plottype,'PeakTorque')
                    if strcmp(colortype,'Changed')
                        colorname = char(app.ColorMapDropDown.Value);
                        colordata = app.othercolor(colorname,plotlen);
                        dataX = (1:stepinterval:datalen);
                        for stride_index = 1:(datalen-stepinterval)
                            eval(['p = plot(app.userfig, dataX([stride_index,stride_index+stepinterval]), peak_torque([stride_index,stride_index+stepinterval]),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata(1+floor((stride_index-1)/stepinterval),:) );']);
                            eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                            eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                        end
                    else
                        eval(['p = plot(app.userfig, (1:stepinterval:datalen), peak_torque(1:stepinterval:datalen),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata );']);
                        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                    end
                end
                
            end
        end

        % Button pushed function: Lamp
        function LampPushed3(app, event)
            app.setting.usercolor = uisetcolor(app.setting.usercolor);
            app.Lamp.BackgroundColor = app.setting.usercolor;
        end

        % Button pushed function: ALTERStridePlotButton_5
        function ALTERStridePlotButton_5Pushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            stepnum = str2num(char(app.StepsNumDropDown.Value));
            averagenum = str2num(char(app.AverageNumDropDown.Value));
            stride_percent = app.setting.gaitPecent;
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif isempty(var_name)
                errordlg('Please select a variable','error');
            else
                eval(['data_temp =   app.data.' data_name '.' var_name '.stride;']);
                
                % 横坐标转化为时间
                XTimeFlag = app.TimeCheckBox.Value;
                if XTimeFlag
                    eval(['time_temp =   app.data.' data_name '.time.stride.mean;']);
                    stride_percent = stride_percent / 100 * (max(time_temp)-min(time_temp));
                    xlabel('Time (s)');
                end
                
                if app.ReverseCheckBox.Value == 1
                    stride_data = -data_temp.each;
                else
                    stride_data = data_temp.each;
                end
                
                if stepnum > averagenum + 1
                    steplist = (stepnum-averagenum):stepnum;
                else
                    steplist = stepnum:stepnum+averagenum;
                end

                eval(['p = plot(app.userfig, stride_percent, mean(stride_data(steplist,:)),''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata );']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);

            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            data_name_split = strsplit(data_name,'.');
            eval(['name_temp = app.data.' data_name '.name;']);
            
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~processflag
                errordlg('Please process data first','error');
            else
                
                eval(['step_num = size(app.data.' data_name '.time.stride.each,1);']);
                step_name = {};
                for i = 1:step_num
                    step_name(i) = {num2str(i)};
                end
                
                app.StepsNumDropDown.Items = step_name;
                
            end
        end

        % Callback function
        function Image2Clicked(app, event)
            app.moveui('app.Panel_1',0,6,30,30);
        end

        % Callback function
        function Image2_2Clicked(app, event)
            app.moveui('app.Panel_1',-90,6,30,30);
        end

        % Callback function
        function Image3_2Clicked(app, event)
            app.moveui('app.Panel_4',1080,590,200,10);
            app.moveui('app.Panel_6',1080,590,200,10);
            app.moveui('app.Panel_5',296,590,200,10);
        end

        % Callback function
        function Image3_4Clicked(app, event)
            app.moveui('app.Panel_5',1080,590,200,10);
            app.moveui('app.Panel_6',1080,590,200,10);
            app.moveui('app.Panel_4',296,590,200,10);
        end

        % Callback function
        function Image3_3Clicked(app, event)
            app.moveui('app.Panel_4',1080,590,200,10);
            app.moveui('app.Panel_5',1080,590,200,10);
            app.moveui('app.Panel_6',296,590,200,10);
        end

        % Callback function
        function Image2_3Clicked(app, event)
            app.moveui('app.Panel_3',35,-520,10,200);
            app.moveui('app.Panel_2',35,30,10,200);
        end

        % Callback function
        function Image2_4Clicked(app, event)
            app.moveui('app.Panel_2',35,-520,10,200);
            app.moveui('app.Panel_3',35,30,10,200);
        end

        % Value changed function: KeyVariableListBox
        function KeyVariableListBoxValueChanged(app, event)
            value = app.KeyVariableListBox.Value;
%             if ~isempty(value)
                app.VariableListBox.Value = value;
%             end
        end

        % Callback function
        function LOADDATAButton_2Pushed(app, event)
            app.moveui('app.Panel_1',0,6,30,30);
        end

        % Callback function
        function LOADDATAButton_3Pushed(app, event)
            app.moveui('app.Panel_1',-90,6,30,30);
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.moveui('app.Panel_3',35,-520,10,200);
            app.moveui('app.Panel_2',25,30,220,480);
        end

        % Button pushed function: ListButton
        function ListButtonPushed(app, event)
            app.moveui('app.Panel_2',35,-520,10,200);
            app.moveui('app.Panel_3',25,30,220,480);
        end

        % Callback function
        function LOADDATAButton_9Pushed(app, event)
            app.moveui('app.Panel_5',1080,590,200,10);
            app.moveui('app.Panel_6',1080,590,200,10);
            app.moveui('app.Panel_4',296,590,200,10);
        end

        % Callback function
        function LOADDATAButton_11Pushed(app, event)
            app.moveui('app.Panel_4',1080,590,200,10);
            app.moveui('app.Panel_5',1080,590,200,10);
            app.moveui('app.Panel_6',296,590,200,10);
        end

        % Callback function
        function LOADDATAButton_12Pushed(app, event)
            app.moveui('app.Panel_5',1080,590,200,10);
            app.moveui('app.Panel_6',1080,590,200,10);
            app.moveui('app.Panel_4',296,590,200,10);
        end

        % Callback function
        function LOADDATAButton_13Pushed(app, event)
            app.moveui('app.Panel_4',1080,590,200,10);
            app.moveui('app.Panel_6',1080,590,200,10);
            app.moveui('app.Panel_5',296,590,200,10);
        end

        % Callback function
        function VarAlterPlotGROUPPLOTButtonPushed(app, event)
            
            class_num = length(app.data.groupbar.class.name);
            class_name = app.data.groupbar.class.name;
            var_name = char(app.VariableListBox.Value);
            
            colormapname = app.ColorMapDropDown.Value;
            colordata = app.othercolor(colormapname,class_num);
            slectedFigureID = str2num(app.FigureDropDown.Value);

            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            
            stride_percent = app.setting.gaitPecent;
            
            if class_num == 0
                errordlg('Please add class','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                
                for i = 1:class_num
                    
                    eval(['data_name = char(app.data.groupbar.class.' char(class_name(i)) ');']);
                    eval(['data_mean =   app.data.' data_name '.' var_name '.stride.mean;']);
                    
                    % 横坐标转化为时间
                    XTimeFlag = app.TimeCheckBox.Value;
                    if XTimeFlag
                        eval(['time_temp =   app.data.' data_name '.time.stride.mean;']);
                        stride_percent = stride_percent / 100 * (max(time_temp)-min(time_temp));
                        xlabel('Time (s)');
                    end
                    
                    if app.ReverseCheckBox.Value == 1
                        data_mean = -data_mean;
                    end
                    
                    eval(['p = plot(app.userfig, stride_percent, data_mean'',''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata(i,:));']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                    
                end
                
            
            end
            
        end

        % Button pushed function: FITTINGButton
        function FITTINGButtonPushed(app, event)
%             app.moveui('app.Panel_7',276,-576,10,200);
%             app.moveui('app.Panel_8',276,-576,10,200);
%             app.moveui('app.Panel_13',276,-576,10,200);
% %             app.moveui('app.Panel_2',35,30,10,200);
%             app.moveui('app.Panel_12',276,2,10,200);
%             app.moveui('app.Panel_4',1080,590,200,10);
%             app.moveui('app.Panel_6',1080,590,200,10);
%             app.moveui('app.Panel_5',296,590,200,10);
            for i = 1:length(app.PlotButtonGroup.Children)
                app.PlotButtonGroup.Children(i).BackgroundColor = app.setting.backcolorset.defult(1,:);
            end
            app.FITTINGButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            
            app.PlotGroup.SelectedTab = app.FitTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
        end

        % Button pushed function: ClassAddButton_4
        function ClassAddButton_2Pushed(app, event)
            data_name = char(app.DataListBox.Value);
            var_name = char(app.VariableListBox.Value);
            feature = char(app.FitFeatureDropDown.Value);
            phase_name = app.FitPhaseDropDown.Value;
            fitGain = app.FitGainEditField.Value;
            if strcmp({'whole'},phase_name)
                phase_name = '';
            else
                phase_name = ['_' phase_name];
            end
            feature = [feature phase_name];
            if isempty(data_name)
                errordlg('Please select a Data','error');
            else
                
                if size(data_name,1) == 1
                    
                    eval(['processflag = app.data.' data_name '.processFlag;']);
                    eval(['var_struct = app.data.' data_name '.' var_name ';']);
                    if ~isfield(var_struct, feature)
                        errordlg('Not exist this feature','error');return;
                    end
                    
                    eval(['feature_data = fitGain*app.data.' data_name '.' var_name '.' feature '.mean;']);
                    if ~processflag
                        errordlg('Please process data first','error');
                    else
                        if isempty(app.FittingTable.Data)
                            app.FittingTable.Data = {[data_name '-' var_name '-' feature],[feature_data],[],[]};
                        else
                            app.FittingTable.Data(end+1,:) = {[data_name '-' var_name '-' feature],[feature_data],[],[]};
                        end
                    end
                else
                    for i = 1:size(data_name,1)
                        
                        eval(['processflag = app.data.' data_name(i,:) '.processFlag;']);
                        eval(['var_struct = app.data.' data_name(i,:) '.' var_name ';']);
                        if ~isfield(var_struct, feature)
                            errordlg('Not exist this feature','error');return;
                        end
                        eval(['feature_data = fitGain*app.data.' data_name(i,:) '.' var_name '.' feature '.mean;']);
                        
                        if ~processflag
                            errordlg('Please process data first','error');
                        else
                            if isempty(app.FittingTable.Data)
                                app.FittingTable.Data = {[data_name(i,:) '-' var_name '-' feature],[feature_data],[],[]};
                            else
                                app.FittingTable.Data(end+1,:) = {[data_name(i,:) '-' var_name '-' feature],[feature_data],[],[]};
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: ClassDeleteButton_4
        function ClassDeleteButton_2Pushed(app, event)
            app.FittingTable.Data(app.data.fit.selectedRow,:) = [];
        end

        % Button pushed function: ClassClearButton_4
        function ClassClearButton_2Pushed(app, event)
            app.FittingTable.Data = [];
        end

        % Callback function
        function FittingButton1Pushed(app, event)
            fitdata = cell2mat(app.FittingTable.Data(:,2));
            dataX = [];
            for i = 1:length(app.FittingTable.Data(:,3))
                dataX(i,1) = str2double(app.FittingTable.Data(i,3));
            end
            fitmethod = app.FittingMethodDropDown1.Value;
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            
            if length(fitdata) ~=  length(dataX)
                errordlg('Please complete LabelX data','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                xi = linspace(1.2*min(dataX) - 0.2*max(dataX),1.2*max(dataX)-0.2*min(dataX),100)';
                yi = interp1(dataX,fitdata,xi,fitmethod);
                eval(['p = plot(app.userfig, xi, yi,''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''color'', colordata);']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                eval(['p = plot(app.userfig, dataX, fitdata,''.k'',''MarkerSize'',' markersize ');']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
            end

        end

        % Cell selection callback: FittingTable
        function FittingTableCellSelection(app, event)
            indices = event.Indices;
            % app.data.fit.selectedRow = indices(1);
        end

        % Button pushed function: FittingButton1
        function FittingButton1Pushed2(app, event)
            fitdata = cell2mat(app.FittingTable.Data(:,2));
            dataX = [];
            for i = 1:length(app.FittingTable.Data(:,3))
                s = string(app.FittingTable.Data(i,3));
                dataX(i,1) = str2double(s);
            end

            fitmethod = app.FittingMethodDropDown1.Value;
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            if length(fitdata) ~=  length(dataX)
                errordlg('Please complete LabelX data','error');
            else
                [f,eva]=fit(dataX,fitdata,fitmethod);
                disp(f);
                xi = linspace(1.2*min(dataX) - 0.2*max(dataX),1.2*max(dataX)-0.2*min(dataX),100)';
                yi = feval(f,xi);
                
%                 plot(app.userfig,f);
%                 plot(f,dataX,fitdata);
                eval(['p = plot(app.userfig, xi, yi,''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''color'', colordata);']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                eval(['p = plot(app.userfig, dataX, fitdata,''.k'',''MarkerSize'',' markersize ');']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                title(['Fitting R^2 = ' num2str(eva.rsquare)]);
            end
        end

        % Button pushed function: FittingButton2
        function FittingButton2Pushed(app, event)
            % fitdata = cell2mat(app.FittingTable.Data(:,2));
            fitdata = zeros(length(app.FittingTable.Data(:,2)),1);
            for i = 1:length(app.FittingTable.Data(:,2))
                fitdata(i) = str2num(app.FittingTable.Data{i,2});
            end
            dataX = [];
            for i = 1:length(app.FittingTable.Data(:,3))
                if isempty(app.FittingTable.Data(i,3))
                    errordlg('Please complete LabelX data','error');
                    return;
                else
                    dataX(i,1) = str2double(app.FittingTable.Data(i,3));
                end
            end
            dataY = [];
            for i = 1:length(app.FittingTable.Data(:,4))
                if isempty(app.FittingTable.Data(i,4))
                    errordlg('Please complete LabelY data','error');
                    return;
                else
                    dataY(i,1) = str2double(app.FittingTable.Data(i,4));
                end
            end
            fitmethod = app.FittingMethodDropDown2.Value;
            linewidth = str2double(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = str2double(app.MarkerSizeDropDown.Value);
            if linewidth < 5
                linewidth = 8;
            end
            markertype = char(app.MarkerTypeDropDown.Value);
%             colordata = app.setting.usercolor;
            colormapname = app.ColorMapDropDown.Value;
            colordata = app.othercolor(colormapname,linewidth);
%             colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            if length(fitdata) ~=  length(dataX)
                errordlg('Please complete LabelX data','error');
            else
                [f,eva]=fit([dataX,dataY],fitdata,fitmethod,'Normalize',"on");
%                 xi = linspace(1.2*min(dataX) - 0.2*max(dataX),1.2*max(dataX)-0.2*min(dataX),50)';
%                 yi = linspace(1.2*min(dataY) - 0.2*max(dataY),1.2*max(dataY)-0.2*min(dataY),50)';
%                 [X,Y] = meshgrid(xi,yi);
%                 
%                 Z = feval(f,X,Y);
                
%                 figure;
                clf;
                s = plot(f,[dataX,dataY],fitdata);
                colormap(colordata);
                s(1).EdgeColor = 'none';
                s(1).FaceColor = 'interp';
                s(2).MarkerFaceColor = app.setting.usercolor;
                s(2).MarkerSize = markersize;
                view(2);
%                 title(['Fitting R^2 = ' num2str(eva.rsquare)]);
                h = gca;
                h.FontName = 'Times New Roman';
%                 h.LineWidth = app.setting.XAxisThickness;
                h.FontSize = app.setting.FontSize;
%                 eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = s;']);
%                 s = surface(app.userfig,X,Y,Z,'FaceAlpha',0.8,'FaceColor',"interp");
%                 scatter3(app.userfig,dataX,dataY,fitdata,'filled','MarkerFaceColor','k');
                app.userfig = gca;
%                 plot(app.userfig,f);
%                 plot(f,dataX,fitdata);
%                 eval(['p = plot(app.userfig, xi, yi,''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''color'', colordata);']);
%                 eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
%                 eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
%                 eval(['p = plot(app.userfig, dataX, fitdata,''.k'',''MarkerSize'',' markersize ');']);
%                 eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
%                 eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
%                 title(['Fitting R^2 = ' num2str(eva.rsquare)]);
            end
        end

        % Button pushed function: ALTERNEWButton_10
        function ALTERNEWButton_8Pushed(app, event)
            
            dataZ = cell2mat(app.FittingTable.Data(:,2));
            dataX = [];
            for i = 1:length(app.FittingTable.Data(:,3))
                if isempty(app.FittingTable.Data(i,3))
                    errordlg('Please complete LabelX data','error');
                    return;
                else
                    dataX(i,1) = str2double(app.FittingTable.Data(i,3));
                end
            end
            dataY = [];
            for i = 1:length(app.FittingTable.Data(:,4))
                if isempty(app.FittingTable.Data(i,4))
                    errordlg('Please complete LabelY data','error');
                    return;
                else
                    dataY(i,1) = str2double(app.FittingTable.Data(i,4));
                end
            end
            [file, path]=uiputfile('AnaFitData.mat','Save data name');
            if ~(isequal(file,0))  
                save(fullfile(path, file),"dataX","dataY","dataZ");
            end
        end

        % Button pushed function: SIGNFIButton
        function SIGNFIButtonPushed(app, event)
%             app.moveui('app.Panel_7',276,-576,10,200);
%             app.moveui('app.Panel_8',276,-576,10,200);
%             app.moveui('app.Panel_12',276,-576,10,200);
% %             app.moveui('app.Panel_2',35,30,10,200);
%             app.moveui('app.Panel_13',276,2,10,200);
%             app.moveui('app.Panel_4',1080,590,200,10);
%             app.moveui('app.Panel_6',1080,590,200,10);
%             app.moveui('app.Panel_5',296,590,200,10);
            for i = 1:length(app.PlotButtonGroup.Children)
                app.PlotButtonGroup.Children(i).BackgroundColor = app.setting.backcolorset.defult(1,:);
            end
            app.SIGNFIButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            
            app.PlotGroup.SelectedTab = app.SignifTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
        end

        % Button pushed function: AddGroupButton
        function AddGroupButtonPushed(app, event)
            new_name = char(inputdlg('Input the new group name：','Creat Group'));
                if ~isempty(new_name)
                    if isempty(app.SigTable.Data)
                        app.SigTable.Data = {[new_name],[]};
                        app.data.sig.name = {[new_name]};
                        eval(['app.data.sig.' new_name ' = [];']);
                        app.data.sig.selectedGroup = new_name;
                        app.updateGroupData();
                    else
                        app.SigTable.Data(end+1,:) = {[new_name],[]};
                        app.data.sig.name(end+1) = {[new_name]};
                        eval(['app.data.sig.' new_name ' = [];']);
                        app.data.sig.selectedGroup = new_name;
                        app.updateGroupData();
                    end
                end
        end

        % Button pushed function: AddDataButton1
        function AddDataButton1Pushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            group_name =app.data.sig.selectedGroup;
            feature = char(app.SigFeatureDropDown.Value);
            phase_name = app.SigPhaseDropDown.Value;
            if strcmp({'whole'},phase_name)
                phase_name = '';
            else
                phase_name = ['_' phase_name];
            end
            feature = [feature phase_name];
            
            if ~isempty(app.data.sig.selectedGroup)
                eval(['app.data.sig.' group_name ' = [app.data.sig.' group_name '; app.data.' data_name '.' var_name '.' feature '.mean ];']);
                app.updateGroupData();
            end
        end

        % Cell selection callback: SigTable
        function SigTableCellSelection(app, event)
            indices = event.Indices;
%             app.SigTable.Data{indices(1),1}
            app.data.sig.selectedID = indices(1);
            app.data.sig.selectedGroup = app.SigTable.Data{indices(1),1};
            app.updateGroupData();
            
        end

        % Button pushed function: DeleteGroupButton
        function DeleteGroupButtonPushed(app, event)
            app.SigTable.Data(app.data.sig.selectedID,:) = [];
            eval(['app.data.sig = rmfield(app.data.sig, ''' app.data.sig.selectedGroup ''');']);
            for i = 1:length(app.data.sig.name)
                if strcmp(app.data.sig.name(i),app.data.sig.selectedGroup)
                    app.data.sig.name(i) = [];
                end
            end
            app.data.sig.selectedGroup = [];
            app.updateGroupData();
        end

        % Button pushed function: ClearGroupButton
        function ClearGroupButtonPushed(app, event)
            app.SigTable.Data = {};
            app.data.sig = {};
            app.data.sig.name = {};
            app.data.sig.selectedGroup = [];
            app.data.sig.selectedID = 1;
        end

        % Button pushed function: AddDataButton2
        function AddDataButton2Pushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            group_name =app.data.sig.selectedGroup;
            feature = char(app.SigFeatureDropDown.Value);
            phase_name = app.SigPhaseDropDown.Value;
            if strcmp({'whole'},phase_name)
                phase_name = '';
            else
                phase_name = ['_' phase_name];
            end
            feature = [feature phase_name];
            
            if ~isempty(app.data.sig.selectedGroup)
                eval(['app.data.sig.' group_name ' = [app.data.sig.' group_name '; app.data.' data_name '.' var_name '.' feature '.each'' ];']);
                app.updateGroupData();
            end
        end

        % Button pushed function: AddDataButton2_2
        function AddDataButton2_2Pushed(app, event)
            group_name =app.data.sig.selectedGroup;
            if ~isempty(app.data.sig.selectedGroup)
                eval(['app.data.sig.' group_name ' = [];']);
                app.updateGroupData();
            end
        end

        % Button pushed function: ClassUpButton_4
        function ClassUpButton_2Pushed(app, event)
            if app.data.sig.selectedID > 1
                data_temp = app.SigTable.Data(app.data.sig.selectedID,:);
                app.SigTable.Data(app.data.sig.selectedID,:) = app.SigTable.Data(app.data.sig.selectedID-1,:);
                app.SigTable.Data(app.data.sig.selectedID-1,:) = data_temp;
                app.data.sig.selectedID = app.data.sig.selectedID - 1;
                app.data.sig.selectedGroup = app.SigTable.Data{app.data.sig.selectedID,1};
                app.updateGroupData();
            end
        end

        % Button pushed function: NormalityAnaButton
        function NormalityAnaButtonPushed(app, event)
            if isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                group_name = app.data.sig.selectedGroup;
                eval(['group_data = app.data.sig.' group_name ';']);
                [hp,p] = lillietest(group_data);
                h = histogram(app.userfig,group_data',15);
                h.Normalization = 'probability';
                title(['Normality Test P-Value = ' num2str(p)]);
            end
        end

        % Button pushed function: VarAnaButton
        function VarAnaButtonPushed(app, event)
            slectedFigureID = str2num(app.FigureDropDown.Value);
            if isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                group_num = length(app.data.sig.name);
                all_group_data = [];
                all_group_label = {};
                group_var = [];
                group_label = {};
                
                for i = 1:group_num
                    group_name = app.SigTable.Data{i,1};
                    eval(['group_data = app.data.sig.' group_name ';']);
                    all_group_data = [all_group_data; group_data];
                    group_var = [group_var, std(group_data)];
                    group_label{end+1} = group_name;
                    for j = 1:length(group_data)
                        all_group_label{end+1} = group_name;
                    end
                end
                
                p = vartestn(all_group_data,all_group_label,'off');
                
                b = bar(app.userfig, group_var, 'grouped', 'FaceColor', 'flat');
                set(b,'edgecolor','none');
                set(app.userfig, 'xtick', [1:group_num]');
                set(app.userfig, 'xticklabel', group_label);
                set(app.userfig, 'TickLabelInterpreter', 'none');
                title(['Variance Test P-Value = ' num2str(p)]);
            end
            
        end

        % Button pushed function: VarAnaButton_2
        function VarAnaButton_2Pushed(app, event)
            group_num = length(app.data.sig.name);
            group_name = app.SigTable.Data{1,1};
            eval(['group_data_x = app.data.sig.' group_name ';']);
            app.SigTable.Data{1,2} = '-';
            for i = 2:group_num
                group_name = app.SigTable.Data{i,1};
                eval(['group_data_y = app.data.sig.' group_name ';']);
                [~,p] = ttest2(group_data_x,group_data_y);
                app.SigTable.Data{i,2} = num2str(p);
            end
        end

        % Button pushed function: VarAnaButton_3
        function VarAnaButton_3Pushed(app, event)
            group_num = length(app.data.sig.name);
            all_group_data = [];
            all_group_label = {};
            
            for i = 1:group_num
                group_name = app.SigTable.Data{i,1};
                eval(['group_data = app.data.sig.' group_name ';']);
                all_group_data = [all_group_data; group_data];
                for j = 1:length(group_data)
                    all_group_label{end+1} = group_name;
                end
                app.SigTable.Data{i,2} = '-';
            end
            
            p = anova1(all_group_data,all_group_label);
            app.SigTable.Data{1,2} = num2str(p);
        end

        % Button pushed function: VarAnaButton_5
        function VarAnaButton_5Pushed(app, event)
            group_num = length(app.data.sig.name);
            group_name = app.SigTable.Data{1,1};
            eval(['group_data_x = app.data.sig.' group_name ';']);
            app.SigTable.Data{1,2} = '-';
            for i = 2:group_num
                group_name = app.SigTable.Data{i,1};
                eval(['group_data_y = app.data.sig.' group_name ';']);
                p = ranksum(group_data_x,group_data_y);
                app.SigTable.Data{i,2} = num2str(p);
            end
        end

        % Button pushed function: VarAnaButton_4
        function VarAnaButton_4Pushed(app, event)
            group_num = length(app.data.sig.name);
            all_group_data = [];
            all_group_label = {};
            
            for i = 1:group_num
                group_name = app.SigTable.Data{i,1};
                eval(['group_data = app.data.sig.' group_name ';']);
                all_group_data = [all_group_data; group_data];
                for j = 1:length(group_data)
                    all_group_label{end+1} = group_name;
                end
                app.SigTable.Data{i,2} = '-';
            end
            
            p = kruskalwallis(all_group_data,all_group_label);
            app.SigTable.Data{1,2} = num2str(p);
        end

        % Button pushed function: LoadTabButton
        function LoadTabButtonPushed(app, event)
            app.LoadTabButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            app.VarEditTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.PlotSetTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.GaitPhaseTab.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.ToolTabGroup.SelectedTab = app.LoadTab;
        end

        % Button pushed function: VarEditTabButton
        function VarEditTabButtonPushed(app, event)
            app.LoadTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.VarEditTabButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            app.PlotSetTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.GaitPhaseTab.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.ToolTabGroup.SelectedTab = app.VarEditTab;
        end

        % Button pushed function: PlotSetTabButton
        function PlotSetTabButtonPushed(app, event)
            app.LoadTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.VarEditTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.PlotSetTabButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            app.GaitPhaseTab.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.ToolTabGroup.SelectedTab = app.PlotSetTab;
        end

        % Button pushed function: SetBalckColor
        function SetBalckColorButtonPushed(app, event)
            app.SetBackGroundSolor(app.setting.backcolorset.dark);
        end

        % Button pushed function: SetBalckColor_2
        function SetBalckColor_2ButtonPushed(app, event)
            app.SetBackGroundSolor(app.setting.backcolorset.blue);
        end

        % Button pushed function: SetBalckColor_3
        function SetBalckColor_3ButtonPushed(app, event)
            app.SetBackGroundSolor(app.setting.backcolorset.green);
        end

        % Button pushed function: SetBalckColor_4
        function SetBalckColor_4ButtonPushed(app, event)
            app.SetBackGroundSolor(app.setting.backcolorset.pink);
        end

        % Button pushed function: SetBalckColor_5
        function SetBalckColor_5ButtonPushed(app, event)
            app.SetBackGroundSolor(app.setting.backcolorset.purple);
        end

        % Callback function
        function MultiSelectButtonPushed(app, event)
            if app.MultiSelectButton.BackgroundColor == app.setting.backcolorset.defult(1,:)
                app.MultiSelectButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
                app.DataListBox.Multiselect = 'on';
            else
                app.MultiSelectButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
                app.DataListBox.Multiselect = 'off';
            end
            
        end

        % Button pushed function: SaveFigure
        function SaveFigureButtonPushed(app, event)
            if isempty(app.userfig)
                errordlg('No figure','error');
            else
                figure_name = char(inputdlg('Input figure name：','FigName'));
                
                saveas(app.userfig,[figure_name '.fig'],'fig');
                saveas(app.userfig,[figure_name '.png'],'png');
                saveas(app.userfig,[figure_name '.eps'],'eps');
                
            end
        end

        % Button pushed function: RESPButton
        function RESPButtonPushed(app, event)
            for i = 1:length(app.PlotButtonGroup.Children)
                app.PlotButtonGroup.Children(i).BackgroundColor = app.setting.backcolorset.defult(1,:);
            end
            app.RESPButton.BackgroundColor = app.setting.backcolorset.defult(2,:);
            
            app.PlotGroup.SelectedTab = app.RespTab;
            app.DataListBox.Items = app.data.name;
            app.updateKeyVarTable();
        end

        % Callback function
        function BODEPLOTButton_3Pushed(app, event)
            [file,path,indx] = uigetfile('*.mat','Select the dSPACE MAT file','MultiSelect', 'off');
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            
            if indx == 1
                % 加载数据
                BodeData = load(fullfile(path, file));
                if isempty(app.userfig) || ~isvalid(app.userfig)
                    errordlg('Please create a new figure','error');
                else
                    set(app.userfig,'xscale','log')
                    set(app.userfig,'XTick',[1 2 3 5 8 10 12 15 18 20]);
                    set(app.userfig,'YTick',[-3 -2 -1 -0.5 0 0.5 1]);
                    ylim([-5,2]);xlim([0,25]);
                    
                    eval(['p = semilogx(BodeData.FreqRes.Freq, BodeData.FreqRes.Ap.Mean'',''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''Color'', colordata);']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
            end
        end

        % Callback function
        function BODEPLOTButton_4Pushed(app, event)
            [file,path,indx] = uigetfile('*.mat','Select the dSPACE MAT file','MultiSelect', 'off');
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            
            if indx == 1
                % 加载数据
                BodeData = load(fullfile(path, file));
                if isempty(app.userfig) || ~isvalid(app.userfig)
                    errordlg('Please create a new figure','error');
                else
                    set(app.userfig,'xscale','log')
                    set(app.userfig,'XTick',[1 2 3 5 8 10 12 15 18 20 25]);
                    set(app.userfig,'YTick',[-180 -150 -90 -30 0]);
                    ylim([-180,0]);xlim([0,25]);
                    
                    eval(['p = semilogx(BodeData.FreqRes.Freq, BodeData.FreqRes.Ph.Mean'',''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''Color'', colordata);']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
            end
        end

        % Button pushed function: STEPPLOTButton_1
        function STEPPLOTButton_1Pushed(app, event)
            data_name_list = char(app.DataListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
    %         disp(app.FreqSlider.Value);
            FreqLim = app.FreqSlider.Value;
            
            for i = 1:size(data_name_list,1)
                data_name = data_name_list(i,:);
                
                eval(['data_time = app.data.' data_name '.StepTime;']);
                eval(['data_step = app.data.' data_name '.Step;']);
                
                eval(['p = plot(data_time, data_step,' ...
                           '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                           '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                           '''Color'', colordata);']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
            end
        end

        % Button pushed function: STEPPLOTButton_2
        function STEPPLOTButton_2Pushed(app, event)
            data_name_list = char(app.DataListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
    %         disp(app.FreqSlider.Value);
            FreqLim = app.FreqSlider.Value;
            
            for i = 1:size(data_name_list,1)
                data_name = data_name_list(i,:);
                
                eval(['data_time = app.data.' data_name '.StepTime;']);
                eval(['data_response = app.data.' data_name '.StepResponse;']);
                
                eval(['p = plot(data_time, data_response,' ...
                           '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                           '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                           '''Color'', colordata);']);
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
            end
        end

        % Button pushed function: STEPPLOTButton_3
        function STEPPLOTButton_3Pushed(app, event)
            data_name_list = char(app.DataListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
    %         disp(app.FreqSlider.Value);
            FreqLim = app.FreqSlider.Value;
            
            for i = 1:size(data_name_list,1)
                data_name = data_name_list(i,:);
                
                eval(['data_time = app.data.' data_name '.StepTime;']);
                eval(['data_response_each = app.data.' data_name '.StepResponseEach;']);
                for j = 1:size(data_response_each,1)
                    eval(['p = plot(data_time, data_response_each(j,:),' ...
                               '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                               '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                               '''Color'', colordata);']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                end
            end
        end

        % Button pushed function: FPIPLOTButton
        function FPIPLOTButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
%             colordata = app.setting.usercolor;
            GaitPercent = app.GaitCycleEditField.Value;
            colorname = char(app.ColorMapDropDown.Value);
%             colordata = app.othercolor(colorname,100);
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            eval(['name_temp = app.data.' data_name '.name;']);
            eval(['processflag = app.data.' data_name '.processFlag;']);


            if ~ismember(1,strcmp(name_temp, 'FPI_Pressure_R_00'))
                errordlg('No FPI Data!','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif ~processflag
                errordlg('Please process data first','error');
            else
                PosX = 10 + [300, 220, 150, 80,...
                                370, 280, 200, 100,...
                                380, 370, 290, 200,...
                                360, 230, 360, 230]';
                PosY = 1330 - [200, 160, 160, 160,...
                                380, 415, 415, 380,...
                                550, 750, 700, 680,...
                                940, 940, 1135, 1135]';
                CutList1 = [500;500;500;255;228;209;195;185;177;170;167;164;163;161;160;160;159;159;158;158;...
                            158;158;158;159;159;159;159;159;159;159;159;159;158;158;157;156;155;154;153;151;...
                            148;145;142;139;135;130;126;121;115;109;102;95;87;80;73;66;61;55;50;45;41;37;34;31;...
                            29;26;24;23;22;21;20;20;19;19;20;20;21;22;24;25;29;33;38;46;55;66;82;106;145;...
                            500;500;500;500;500;500;500;500;500;500;500;500];
                CutList2 = [0;0;0;365;392;410;423;433;441;447;450;452;454;455;456;457;457;458;458;458;458;457;...
                            457;457;457;456;456;456;455;455;455;454;454;455;455;456;456;457;458;460;461;463;465;...
                            467;469;472;475;477;479;481;482;483;484;484;484;484;484;483;483;482;480;479;477;474;...
                            471;467;463;459;455;450;444;439;433;426;419;412;403;394;386;376;365;355;342;329;317;...
                            302;286;261;221;0;0;0;0;0;0;0;0;0;0;0;0];
                sigmaX = 100;
                sigmaY = 150; % 125
                
                PressureData = zeros(16,1);
                for i=0:9
                    eval(['PressureData(i+1) =   app.data.' data_name '.FPI_Pressure_R_0' num2str(i) '.stride.mean(ceil(GaitPercent));']);
                end
                for i=10:15
                    eval(['PressureData(i+1) =   app.data.' data_name '.FPI_Pressure_R_' num2str(i) '.stride.mean(ceil(GaitPercent));']);
                end
                
                [xq,yq] = meshgrid(0:5:500,0:15:1500);
                zq = 0 * exp(-(xq.^2+yq.^2));
                
                for i = 1:16
                    zq = zq + PressureData(i) * exp(-(((xq-PosX(i))./sigmaX).^2+((yq-PosY(i))./sigmaY).^2));
                end
                
                zq = zq+10;

                for i = 1:size(xq,2)
                    for j = 1:size(xq,1)
                        if xq(i,j) < CutList1(i) || xq(i,j) > CutList2(i)
                            zq(i,j) = -1;
                        end
                    end
                end


                axis([0 500 0 1500 0 3000]);
%                 colordata = app.othercolor(colorname,100);
%                 colormap([1,1,1; flip(colordata)]);
%                 colormap(colordata);
                colormap(jet);
                caxis([-10 2000]);
                axis off;
                daspect([1 1 5]);
                
                if app.ViewSwitch.Value == "2D"
                    view(2);
                else
                    view([1,-1,1]);
                end
                
                p = mesh(app.userfig,xq,yq,zq,'FaceColor','interp','EdgeColor','none');
                
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
            end     
        end

        % Button pushed function: FPIPLOTButton_2
        function FPIPLOTButton_2Pushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            var_name = char(app.VariableListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            eval(['name_temp = app.data.' data_name '.name;']);
            eval(['processflag = app.data.' data_name '.processFlag;']);
            
            if ~ismember(1,strcmp(name_temp, 'FPI_Pressure_R_00'))
                errordlg('No FPI Data!','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            elseif ~processflag
                errordlg('Please process data first','error');
            else
%                 eval(['MaxPressure = max(app.data.' data_name '.FPI_Pressure_R_0' num2str(i) '.stride.mean(ceil(GaitPercent)));']);
                PosX = 10 + [300, 220, 150, 80,...
                                370, 280, 200, 100,...
                                380, 370, 290, 200,...
                                360, 230, 360, 230]';
                PosY = 1330 - [200, 160, 160, 160,...
                                380, 415, 415, 380,...
                                550, 750, 700, 680,...
                                940, 940, 1135, 1135]';
                CutList1 = [500;500;500;255;228;209;195;185;177;170;167;164;163;161;160;160;159;159;158;158;...
                            158;158;158;159;159;159;159;159;159;159;159;159;158;158;157;156;155;154;153;151;...
                            148;145;142;139;135;130;126;121;115;109;102;95;87;80;73;66;61;55;50;45;41;37;34;31;...
                            29;26;24;23;22;21;20;20;19;19;20;20;21;22;24;25;29;33;38;46;55;66;82;106;145;...
                            500;500;500;500;500;500;500;500;500;500;500;500];
                CutList2 = [0;0;0;365;392;410;423;433;441;447;450;452;454;455;456;457;457;458;458;458;458;457;...
                            457;457;457;456;456;456;455;455;455;454;454;455;455;456;456;457;458;460;461;463;465;...
                            467;469;472;475;477;479;481;482;483;484;484;484;484;484;483;483;482;480;479;477;474;...
                            471;467;463;459;455;450;444;439;433;426;419;412;403;394;386;376;365;355;342;329;317;...
                            302;286;261;221;0;0;0;0;0;0;0;0;0;0;0;0];
                sigmaX = 100;
                sigmaY = 150; % 125
                
                axis([0 500 0 1500 0 3000]);
                caxis([-10 2000]);
                axis off;
                daspect([1 1 5]);
                colormap(jet);
                if app.ViewSwitch.Value == "2D"
                    view(2);
                else
                    view([1,-1,1]);
                end
                p = [];
                
                for GaitPercent = 1:2:100
                    PressureData = zeros(16,1);
                    for i=0:9
                        eval(['PressureData(i+1) =   app.data.' data_name '.FPI_Pressure_R_0' num2str(i) '.stride.mean(ceil(GaitPercent));']);
                    end
                    for i=10:15
                        eval(['PressureData(i+1) =   app.data.' data_name '.FPI_Pressure_R_' num2str(i) '.stride.mean(ceil(GaitPercent));']);
                    end
                    
                    [xq,yq] = meshgrid(0:5:500,0:15:1500);
                    zq = 0 * exp(-(xq.^2+yq.^2));
                    
                    for i = 1:16
                        zq = zq + PressureData(i) * exp(-(((xq-PosX(i))./sigmaX).^2+((yq-PosY(i))./sigmaY).^2));
                    end
                    
                    zq = zq+10;
    
                    for i = 1:size(xq,2)
                        for j = 1:size(xq,1)
                            if xq(i,j) < CutList1(i) || xq(i,j) > CutList2(i)
                                zq(i,j) = -1;
                            end
                        end
                    end
                    if ~isempty(p)
                        delete(p);
                    end
                    p = mesh(app.userfig,xq,yq,zq,'FaceColor','interp','EdgeColor','none');
                    pause(0.2);
                end
                
                eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                
            end 
        end

        % Value changed function: RightCheckBox
        function RightCheckBoxValueChanged(app, event)
      value = app.RightCheckBox.Value;
      disp(value);
      if value == 1
          yyaxis right
      else
          yyaxis left
      end
        end

        % Callback function
        function SetBalckColor_6ButtonPushed(app, event)
      app.SetBackGroundSolor(app.setting.backcolorset.JYChen_Color);
        end

        % Button pushed function: SetBalckColor_6
        function SetBalckColor_6ButtonPushed2(app, event)
      app.setting.gaitPecent = 0:1:100;
        end

        % Button pushed function: SetBalckColor_7
        function SetBalckColor_7ButtonPushed(app, event)
      app.setting.gaitPecent = 0:0.1:100;
        end

        % Callback function
        function WidthDropDownValueChanged(app, event)
      value = app.WidthDropDown.Value;
      app.setting.FigureW = str2num(value);
        end

        % Callback function
        function HighDropDownValueChanged(app, event)
      value = app.HighDropDown.Value;
      app.setting.FigureH = str2num(value);
        end

        % Button pushed function: ColorSetButton1, ColorSetButton1_10, 
        % ...and 37 other components
        function ColorSetButtonPushed(app, event)
%         disp(event.Source.Icon);
        colorSetName = event.Source.Icon;
        colorSetName = colorSetName(1:end-4);
%         disp(colorSetName);

        app.ColorMapDropDown.Value = colorSetName;
        
        app.upColorMap();
        app.setting.setting.ColorIndex = 0;
        eval(['app.PlotGroup.SelectedTab = app.' app.setting.LastTab ';']);
        end

        % Button pushed function: ColorButton, ColorSetButton1_32
        function ColorButtonPushed(app, event)
        app.setting.LastTab = app.PlotGroup.SelectedTab.Title;
        
        app.PlotGroup.SelectedTab = app.CloorTab;
        app.DataListBox.Items = app.data.name;
        app.updateKeyVarTable();
        end

        % Button pushed function: BODEPLOTButton_3
        function BODEPLOTButton_3Pushed2(app, event)
        data_name_list = char(app.DataListBox.Value);

        eval(['name_temp = app.data.' data_name_list(1,:) '.name;']);
        [InputIndx,~] = listdlg('PromptString',{'Select the input signal.'},'SelectionMode','single','ListString',name_temp);
        inputVarName = name_temp{InputIndx};
        [OutputIndx,~] = listdlg('PromptString',{'Select the output signal.'},'SelectionMode','single','ListString',name_temp);
        outputVarName = name_temp{OutputIndx};
        
        smoothLen = str2double(inputdlg({'Smooth Length'}));
        smoothLen = floor(smoothLen);
        if smoothLen < 1
            smoothLen = 1;
        end

%         inputVarName = 'DesTau';
%         outputVarName = 'Motor_R_1';
%         smoothLen = 20;
        
        for i = 1:size(data_name_list,1)
            data_name = data_name_list(i,:);
            %% Pick Up Data
            eval(['data_input = app.data.' data_name '.' inputVarName '.origin;']);
            eval(['data_output = app.data.' data_name '.' outputVarName '.origin;']);
            eval(['data_time = app.data.' data_name '.time.origin;']);
            
            T = mean(data_time(2:end)-data_time(1:end-1)); % Sampling period  
            Fs = 1/T;                      % Sampling frequency                              
            data_len = length(data_time);         % Length of signal
            freqList = Fs*(0:floor(data_len/2-1))/data_len;
            eval(['app.data.' data_name '.Freq = freqList;']);
    
            FFT_Complex_IN = fft(data_input);
            Amp_Real_IN = abs(FFT_Complex_IN/data_len);
            Amp_Real_IN = Amp_Real_IN(1:floor(data_len/2));
            Amp_Real_IN(2:end-1) = smooth(2*Amp_Real_IN(2:end-1),smoothLen);
            Pha_Rad_IN = angle(FFT_Complex_IN);
%             Pha_Deg_IN = app.reCalPhase(Pha_Rad_IN(1:floor(data_len/2)));
            Pha_Rad_IN = Pha_Rad_IN(1:floor(data_len/2));
            
            FFT_Complex_OUT = fft(data_output);
            Amp_Real_OUT = abs(FFT_Complex_OUT/data_len);
            Amp_Real_OUT = Amp_Real_OUT(1:floor(data_len/2));
            Amp_Real_OUT(2:end-1) = smooth(2*Amp_Real_OUT(2:end-1),smoothLen);
            Pha_Rad_OUT = angle(FFT_Complex_OUT);
%             Pha_Deg_OUT = app.reCalPhase(Pha_Rad_OUT(1:floor(data_len/2)));
            Pha_Rad_OUT = Pha_Rad_OUT(1:floor(data_len/2));
            
            FreqAmpRes = 20*log10(Amp_Real_OUT./Amp_Real_IN);
%             Pha_Rad_OUT = app.reCalPhase(Pha_Rad_OUT);
%             Pha_Rad_IN = app.reCalPhase(Pha_Rad_IN);
            FreqPhaRes = (Pha_Rad_OUT-Pha_Rad_IN);
            FreqPhaRes = app.reCalPhase(FreqPhaRes)/pi*180;
%             FreqPhaRes = FreqPhaRes - 360*ceil(FreqPhaRes(1)/360);
            FreqPhaRes = smooth(FreqPhaRes,smoothLen);
            
            eval(['app.data.' data_name '.AmpRes = FreqAmpRes;']);
            eval(['app.data.' data_name '.PhaRes = FreqPhaRes;']);
        end
        
        msgbox('FFT Completed');
        
        end

        % Button pushed function: BODEPLOTButton_1
        function BODEPLOTButton_1Pushed(app, event)
        data_name_list = char(app.DataListBox.Value);
        linewidth = char(app.LineWidthDropDown.Value);
        linetype = char(app.LineTypeDropDown.Value);
        markersize = char(app.MarkerSizeDropDown.Value);
        markertype = char(app.MarkerTypeDropDown.Value);
        colordata = app.setting.usercolor;
        slectedFigureID = str2num(app.FigureDropDown.Value);
%         disp(app.FreqSlider.Value);
        FreqLim = app.CutOffFreqEditField.Value;
        
        for i = 1:size(data_name_list,1)
            data_name = data_name_list(i,:);
            
            eval(['data_freq = app.data.' data_name '.Freq;']);
            eval(['data_amp_res = app.data.' data_name '.AmpRes;']);
            
            eval(['p = semilogx(data_freq, data_amp_res,' ...
                       '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                       '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                       '''Color'', colordata);']);
            eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
            eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
        end
            
        xlabel('Frequence (Hz)');
        ylabel('Gain (dB)');
        set(app.userfig,'xscale','log')
        set(app.userfig,'XTick',[1 2 3 5 8 10 12 15 20 25 30 35 40 45 50]);
        set(app.userfig,'YTick',[-5 -3 -2 -1 -0.5 0 0.5 1]);
        ylim([-5,2]);xlim([1,FreqLim]);

        end

        % Button pushed function: BODEPLOTButton_2
        function BODEPLOTButton_2Pushed(app, event)
        data_name_list = char(app.DataListBox.Value);
        linewidth = char(app.LineWidthDropDown.Value);
        linetype = char(app.LineTypeDropDown.Value);
        markersize = char(app.MarkerSizeDropDown.Value);
        markertype = char(app.MarkerTypeDropDown.Value);
        colordata = app.setting.usercolor;
        slectedFigureID = str2num(app.FigureDropDown.Value);
        FreqLim = app.CutOffFreqEditField.Value;
        
        for i = 1:size(data_name_list,1)
            data_name = data_name_list(i,:);
            
            eval(['data_freq = app.data.' data_name '.Freq;']);
            eval(['data_pha_res = app.data.' data_name '.PhaRes;']);
            
            eval(['p = semilogx(data_freq, data_pha_res,' ...
                       '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                       '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                       '''Color'', colordata);']);
            eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
            eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
        end
            
        xlabel('Frequence (Hz)');
        ylabel('Phase (deg)');
        set(app.userfig,'xscale','log')
        set(app.userfig,'XTick',[1 2 3 5 8 10 12 15 20 25 30 35 40 45 50]);
        set(app.userfig,'YTick',[-180 -150 -90 -60 -30 0]);
        ylim([-180,0]);xlim([1,FreqLim]);
        end

        % Button pushed function: BODEPLOTButton_4
        function BODEPLOTButton_4Pushed2(app, event)
        data_name_list = char(app.DataListBox.Value);
        linewidth = char(app.LineWidthDropDown.Value);
        linetype = char(app.LineTypeDropDown.Value);
        markersize = char(app.MarkerSizeDropDown.Value);
        markertype = char(app.MarkerTypeDropDown.Value);
        colordata = app.setting.usercolor;
        slectedFigureID = str2num(app.FigureDropDown.Value);
%         disp(app.FreqSlider.Value);
        FreqLim = app.CutOffFreqEditField.Value;
        FreqLen = 10000;
        
        eval(['data_freq = app.data.' data_name_list(1,:) '.Freq;']);
        contract_index = ceil(linspace(1,length(data_freq),FreqLen));
        data_freq = data_freq(contract_index);
        data_amp_res_avg = zeros(1,FreqLen);
        
        for i = 1:size(data_name_list,1)
            data_name = data_name_list(i,:);
            
%             eval(['data_freq = app.data.' data_name '.Freq;']);
            eval(['data_amp_res = app.data.' data_name '.AmpRes;']);
            contract_index = ceil(linspace(1,length(data_amp_res),FreqLen));
            data_amp_res_avg = data_amp_res_avg + data_amp_res(contract_index);
            
        end
        
        data_amp_res_avg = data_amp_res_avg/size(data_name_list,1);
        
        eval(['p = semilogx(data_freq, data_amp_res_avg,' ...
                   '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                   '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                   '''Color'', colordata);']);
        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
        
        xlabel('Frequence (Hz)');
        ylabel('Gain (dB)');
        set(app.userfig,'xscale','log')
        set(app.userfig,'XTick',[1 2 3 5 8 10 12 15 20 25 30 35 40 45 50]);
        set(app.userfig,'YTick',[-5 -3 -2 -1 -0.5 0 0.5 1]);
        ylim([-5,2]);xlim([1,FreqLim]);
        end

        % Button pushed function: BODEPLOTButton_5
        function BODEPLOTButton_5Pushed(app, event)
        data_name_list = char(app.DataListBox.Value);
        linewidth = char(app.LineWidthDropDown.Value);
        linetype = char(app.LineTypeDropDown.Value);
        markersize = char(app.MarkerSizeDropDown.Value);
        markertype = char(app.MarkerTypeDropDown.Value);
        colordata = app.setting.usercolor;
        slectedFigureID = str2num(app.FigureDropDown.Value);
%         disp(app.FreqSlider.Value);
        FreqLim = app.CutOffFreqEditField.Value;
        FreqLen = 10000;
        
        eval(['data_freq = app.data.' data_name_list(1,:) '.Freq;']);
        contract_index = ceil(linspace(1,length(data_freq),FreqLen));
        data_freq = data_freq(contract_index);
        data_pha_res_avg = zeros(1,FreqLen);
        
        for i = 1:size(data_name_list,1)
            data_name = data_name_list(i,:);
            
%             eval(['data_freq = app.data.' data_name '.Freq;']);
            eval(['data_pha_res = app.data.' data_name '.PhaRes;']);
            contract_index = ceil(linspace(1,length(data_pha_res),FreqLen));
            data_pha_res_avg = data_pha_res_avg + data_pha_res(contract_index);
            
        end
        
        data_pha_res_avg = data_pha_res_avg/size(data_name_list,1);
        disp(size(data_pha_res_avg));
        
        eval(['p = semilogx(data_freq, data_pha_res_avg,' ...
                   '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                   '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                   '''Color'', colordata);']);
        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
        
        xlabel('Frequence (Hz)');
        ylabel('Phase (deg)');
        set(app.userfig,'xscale','log')
        set(app.userfig,'XTick',[1 2 3 5 8 10 12 15 20 25 30 35 40 45 50]);
        set(app.userfig,'YTick',[-180 -150 -90 -60 -30 0]);
        ylim([-180,0]);xlim([1,FreqLim]);
        end

        % Button pushed function: FPIPLOTButton_4
        function FPIPLOTButton_4Pushed(app, event)
        data_name_list = char(app.DataListBox.Value);
        linewidth = char(app.LineWidthDropDown.Value);
        linetype = char(app.LineTypeDropDown.Value);
        markersize = char(app.MarkerSizeDropDown.Value);
        markertype = char(app.MarkerTypeDropDown.Value);
        colordata = app.setting.usercolor;
        slectedFigureID = str2num(app.FigureDropDown.Value);
        
        data_name = data_name_list(1,:);

        eval(['name_temp = app.data.' data_name '.name;']);
        XVarName = app.XDropDown.Value;
        YVarName = app.YDropDown.Value;
        % [XvarIndx,~] = listdlg('PromptString',{'Select the X-axis signal.'},'SelectionMode','single','ListString',name_temp);
        % XVarName = name_temp{XvarIndx};
        % [YvarIndx,~] = listdlg('PromptString',{'Select the Y-axis signal.'},'SelectionMode','single','ListString',name_temp);
        % YVarName = name_temp{YvarIndx};
        
        eval(['data_X_Var = app.data.' data_name '.' XVarName '.origin;']);
        eval(['data_Y_Var = app.data.' data_name '.' YVarName '.origin;']);
        
        eval(['p = plot(data_X_Var, data_Y_Var,' ...
                   '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                   '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                   '''Color'', colordata);']);
        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
        
        
        end

        % Button pushed function: FPIPLOTButton_3
        function FPIPLOTButton_3Pushed(app, event)
        data_name_list = char(app.DataListBox.Value);
        linewidth = char(app.LineWidthDropDown.Value);
        linetype = char(app.LineTypeDropDown.Value);
        markersize = char(app.MarkerSizeDropDown.Value);
        markertype = char(app.MarkerTypeDropDown.Value);
        colordata = app.setting.usercolor;
        slectedFigureID = str2num(app.FigureDropDown.Value);
        
        data_name = data_name_list(1,:);

        eval(['name_temp = app.data.' data_name '.name;']);
        XVarName = app.XDropDown.Value;
        YVarName = app.YDropDown.Value;
        % [XvarIndx,~] = listdlg('PromptString',{'Select the X-axis signal.'},'SelectionMode','single','ListString',name_temp);
        % XVarName = name_temp{XvarIndx};
        % [YvarIndx,~] = listdlg('PromptString',{'Select the Y-axis signal.'},'SelectionMode','single','ListString',name_temp);
        % YVarName = name_temp{YvarIndx};
        
        eval(['data_X_Var = app.data.' data_name '.' XVarName '.stride.mean;']);
        eval(['data_Y_Var = app.data.' data_name '.' YVarName '.stride.mean;']);
        
        eval(['p = plot(data_X_Var, data_Y_Var,' ...
                   '''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',' ...
                   '''Marker'',''' markertype ''',''MarkerSize'',' markersize ',' ...
                   '''Color'', colordata);']);
        eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
        eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
        end

        % Value changed function: LineWidthDropDown_2
        function LineWidthDropDown_2ValueChanged(app, event)
      value = app.LineWidthDropDown_2.Value;
      app.setting.XAxisThickness = str2double(value);
        end

        % Value changed function: FontSizeDropDown
        function FontSizeDropDownValueChanged(app, event)
      value = app.FontSizeDropDown.Value;
      app.setting.FontSize = str2double(value);
        end

        % Value changed function: XDensityDropDown
        function XDensityDropDownValueChanged(app, event)
      value = app.XDensityDropDown.Value;
      app.setting.Xdensity = str2double(value);
        end

        % Window button down function: LabExoAnalystUIFigure
        function LabExoAnalystUIFigureWindowButtonDown(app, event)
            MousePoint = app.ColorMapAxes.CurrentPoint;
            MouseXPos = MousePoint(1,1)/10;
            MouseYPos = MousePoint(1,2);
%             disp(MousePoint)
            if MouseXPos >=0 && MouseXPos <= 1 && MouseYPos >= 0 && MouseYPos <= 1 && app.ToolTabGroup.SelectedTab == app.PlotSetTab;
                colormapname = app.ColorMapDropDown.Value;
                colordata = app.othercolor(colormapname,0);
                colornum = size(colordata,1);
                app.setting.ColorIndex = floor(MouseXPos*colornum)+1;
                
                app.setting.usercolor = colordata(app.setting.ColorIndex,:);
                app.Lamp.BackgroundColor = app.setting.usercolor;
            end
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.mat','Select MAT file containing list Vector FitData','MultiSelect', 'off');
            
            if indx == 1
                load(fullfile(path, file));
                if length(FitData) == size(app.FittingTable.Data,1)
                    for i = 1:length(FitData)
                        app.FittingTable.Data(i,2) = {num2str(FitData(i))};
                    end
                else
                    errordlg('Error Data Length','error');
                end
            end
%             fitdata = cell2mat(app.FittingTable.Data(:,2));
%             dataX = [];
%             for i = 1:length(app.FittingTable.Data(:,3))
%                 s = string(app.FittingTable.Data(i,3));
%                 dataX(i,1) = str2double(s);
%             end
%             fitmethod = app.FittingMethodDropDown1.Value;
%             linewidth = char(app.LineWidthDropDown.Value);
% %             linetype = char(app.LineTypeDropDown.Value);
%             markersize = char(app.MarkerSizeDropDown.Value);
%             markertype = char(app.MarkerTypeDropDown.Value);
%             colordata = app.setting.usercolor;
%             slectedFigureID = str2num(app.FigureDropDown.Value);
% 
%             if length(fitdata) ~=  length(dataX)
%                 errordlg('Please complete LabelX data','error');
%             else
% %                 [f,eva]=fit(dataX,fitdata,fitmethod);
% %                 xi = linspace(1.2*min(dataX) - 0.2*max(dataX),1.2*max(dataX)-0.2*min(dataX),100)';
% %                 yi = feval(f,xi);
% 
% %                 plot(app.userfig,f);
% %                 plot(f,dataX,fitdata);
% %                 eval(['p = plot(app.userfig, xi, yi,''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''color'', colordata);']);
% %                 eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
% %                 eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
% 
%                 eval(['p = plot(app.userfig, dataX, fitdata,''.'',''MarkerSize'',' markersize ',''MarkerEdgeColor'', colordata);']);
%                 eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
%                 eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
% %                 title(['Fitting R^2 = ' num2str(eva.rsquare)]);
%             end  


        end

        % Button pushed function: AddLabelXButton
        function AddLabelXButtonPushed(app, event)
        [file,path,indx] = uigetfile('*.mat','Select the dSPACE MAT file','MultiSelect', 'off');
            
        if indx == 1
            load(fullfile(path, file));
            
            for i = 1:length(XLabel)
                app.FittingTable.Data(i,3) = {num2str(XLabel(i))};
            end
        end
        end

        % Callback function
        function StrideShiftDropDownValueChanged(app, event)
          value = app.StrideShiftDropDown.Value;
          app.setting.StrideShift = str2double(value);
        end

        % Button pushed function: ClassAddButton_5
        function ClassAddButton_5Pushed(app, event)
            data_name = char(app.DataListBox.Value);
            var_name = char(app.VariableListBox.Value);
            feature = char(app.FitFeatureDropDown.Value);
            phase_name = app.FitPhaseDropDown.Value;
            fitGain = app.FitGainEditField.Value;
            if strcmp({'whole'},phase_name)
                phase_name = '';
            else
                phase_name = ['_' phase_name];
            end
            feature = [feature phase_name];
            
            if isempty(data_name)
                errordlg('Please select a Data','error');
            else
                
                if size(data_name,1) == 1
                    
                    eval(['processflag = app.data.' data_name '.processFlag;']);
                    eval(['var_struct = app.data.' data_name '.' var_name ';']);
                    if ~isfield(var_struct, feature)
                        errordlg('Not exist this feature','error');return;
                    end
                    
                    eval(['feature_data = fitGain*app.data.' data_name '.' var_name '.' feature '.mean;']);
                    if ~processflag
                        errordlg('Please process data first','error');
                    else
                        if isempty(app.FittingTable.Data)
                            app.FittingTable.Data = {[data_name '-' var_name '-' feature],[feature_data],[],[]};
                        else
                            app.FittingTable.Data(end+1,:) = {[data_name '-' var_name '-' feature],[feature_data],[],[]};
                        end
                    end
                else
                    for i = 1:size(data_name,1)
                        
                        eval(['processflag = app.data.' data_name(i,:) '.processFlag;']);
                        eval(['var_struct = app.data.' data_name(i,:) '.' var_name ';']);
                        if ~isfield(var_struct, feature)
                            errordlg('Not exist this feature','error');return;
                        end
                        eval(['feature_data = fitGain*app.data.' data_name(i,:) '.' var_name '.' feature '.mean;']);
                        
                        if ~processflag
                            errordlg('Please process data first','error');
                        else
                            if isempty(app.FittingTable.Data)
                                app.FittingTable.Data = {[data_name(i,:) '-' var_name '-' feature],[feature_data],[],[]};
                            else
                                app.FittingTable.Data{i,2} = app.FittingTable.Data{i,2} + feature_data;
                            end
                        end
                    end
                end
            end
        end

        % Callback function
        function CreateStateButtonPushed(app, event)
            data_name_list = char(app.DataListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            data_name = data_name_list(1,:);
    
            eval(['name_temp = app.data.' data_name '.name;']);
            [GRFVarIndx,~] = listdlg('PromptString',{'Select the GRF Signal to Detect the Heel Strike'},'SelectionMode','single','ListString',name_temp);
            GRFVarName = name_temp{GRFVarIndx};
            force_baseline = str2double(inputdlg(['Input grf baseline：'],'BaseLine'));
            
            for i = 1:size(data_name_list,1)
                data_name = data_name_list(i,:);
                eval(['name_temp = app.data.' data_name '.name;']);
                eval(['grf_data = app.data.' data_name '.' GRFVarName '.origin;']);
                state_data = app.grf2state(grf_data,force_baseline);
                if ismember(1,strcmp(name_temp,'state'))
                    eval(['app.data.' data_name '.state.origin = state_data;']);
                else
                    name_temp(end+1,1) = {'state'};
                    eval(['app.data.' data_name '.state.origin = state_data;']);
                    eval(['app.data.' data_name '.name = name_temp;']);
                end
            end
            
        end

        % Button pushed function: RenameDataButton
        function RenameDataButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            
            
            if ~isempty(data_name)
                data_name_split = strsplit(data_name,'.');
                new_data_name = char(inputdlg('Input new data name：','Rename'));
                new_data_name = [data_name_split{1} '.' new_data_name];
                name_temp = app.data.name;
                if ismember(1,strcmp(name_temp, new_data_name))
                    errordlg('input name has existed','error');
                    return;
                end
                
                eval(['app.data.' new_data_name ' = app.data.' data_name ';']);
                eval(['app.data.' data_name_split{1} ' = rmfield(app.data.' data_name_split{1} ', ''' data_name_split{2} ''');']);
                
                for i = 1:length(name_temp)
                    if strcmp(name_temp{i}, data_name)
                        name_temp(i) = {new_data_name};
                        break;
                    end
                end
                
                eval(['app.data.name = name_temp;']);

                app.DataListBox.Items = app.data.name;
                app.updateVarTable();
                
            end
        end

        % Button pushed function: DataUpButton
        function DataUpButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            
            if isempty(data_name)
                errordlg('Please select a data','error');
            else
                for i = 1:length(app.data.name)
                    if strcmp(app.data.name(i),data_name)
                        if i > 1
                            name_temp = app.data.name(i);
                            app.data.name(i) = app.data.name(i-1);
                            app.data.name(i-1) = name_temp;
                            break;
                        end
                    end
                end
                app.DataListBox.Items = app.data.name;
                app.updateVarTable();
            end
        end

        % Button pushed function: DataDownButton
        function DataDownButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            
            if isempty(data_name)
                errordlg('Please select a data','error');
            else
                for i = 1:length(app.data.name)
                    if strcmp(app.data.name(i),data_name)
                        if i < length(app.data.name)
                            name_temp = app.data.name(i);
                            app.data.name(i) = app.data.name(i+1);
                            app.data.name(i+1) = name_temp;
                            break;
                        end
                    end
                end
                app.DataListBox.Items = app.data.name;
                app.updateVarTable();
            end
        end

        % Button pushed function: PlotSetTabButton_2
        function PlotSetTabButton_2Pushed(app, event)
            app.LoadTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.VarEditTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.PlotSetTabButton.BackgroundColor = app.setting.backcolorset.defult(1,:);
            app.GaitPhaseTab.BackgroundColor = app.setting.backcolorset.defult(2,:);
            app.ToolTabGroup.SelectedTab = app.GaitPhaseTab;
        end

        % Button pushed function: SelectVarButton
        function SelectVarButtonPushed(app, event)
            data_name_list = char(app.DataListBox.Value);
            eval(['var_name_temp = app.data.' data_name_list(1,:) '.name;']);
            [RemainedIndx,~] = listdlg('PromptString',{'Select the input signal.'},'SelectionMode','multiple','ListString',var_name_temp);
            RemainedVarName = var_name_temp(RemainedIndx);

            for i = 1:size(data_name_list,1)
                data_name = data_name_list(i,:);
                data_temp = {};
                for j = 1:length(RemainedIndx)
                    var_name = RemainedVarName{j};
                    eval(['data_temp.' var_name ' = app.data.' data_name '.' var_name ';']);
                end
                eval(['data_temp.processFlag   = app.data.' data_name '.processFlag;']);
                data_temp.name = RemainedVarName;
                eval(['app.data.' data_name ' = data_temp;']);
                app.updateVarTable();
            end
        end

        % Button pushed function: AddLabelYButton
        function AddLabelYButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.mat','Select the dSPACE MAT file','MultiSelect', 'off');
            
            if indx == 1
                load(fullfile(path, file));
                
                for i = 1:length(YLabel)
                    app.FittingTable.Data(i,4) = {num2str(YLabel(i))};
                end
            end
        end

        % Button pushed function: CreateStateButton
        function CreateStateButtonPushed2(app, event)
            data_name_list = char(app.DataListBox.Value);
            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            colordata = app.setting.usercolor;
            slectedFigureID = str2num(app.FigureDropDown.Value);
            
            data_name = data_name_list(1,:);
    
            eval(['name_temp = app.data.' data_name '.name;']);
            [GRFVarIndx,~] = listdlg('PromptString',{'Select the GRF Signal to Detect the Heel Strike'},'SelectionMode','single','ListString',name_temp);
            GRFVarName = name_temp{GRFVarIndx};
            force_baseline = str2double(inputdlg(['Input grf baseline：'],'BaseLine'));
            
            for i = 1:size(data_name_list,1)
                data_name = data_name_list(i,:);
                eval(['name_temp = app.data.' data_name '.name;']);
                eval(['grf_data = app.data.' data_name '.' GRFVarName '.origin;']);
                state_data = app.grf2state(grf_data,force_baseline);
                if ismember(1,strcmp(name_temp,'state'))
                    eval(['app.data.' data_name '.state.origin = state_data;']);
                else
                    name_temp(end+1,1) = {'state'};
                    eval(['app.data.' data_name '.state.origin = state_data;']);
                    eval(['app.data.' data_name '.name = name_temp;']);
                end
            end
        end

        % Button pushed function: NormVarButton
        function NormVarButtonPushed(app, event)
            
            data_name = char(app.DataListBox.Value);      
            var_name = char(app.VariableListBox.Value);
            
            if size(data_name,1) == 1
                data_name = {data_name};
            end
            
            if ~isempty(data_name) && ~isempty(var_name)
%                 if isnumeric(bias)
%                     if ~isempty(bias) && ~isnan(bias)
                eval(['var_min = app.data.' data_name(1,:) '.' var_name '.min.mean;']);
                for i = 1:size(data_name,1)
%                     eval(['app.data.' data_name(i,:) '.' var_name '.origin = app.data.' data_name(i,:) '.' var_name '.origin - var_min;']);
                    eval(['app.data.' data_name(i,:) '.' var_name '.stride.each = app.data.' data_name(i,:) '.' var_name '.stride.each - var_min;']);
                    app.processOneVar(data_name(i,:), var_name);
                end
                eval(['var_max = app.data.' data_name(1,:) '.' var_name '.rms.mean;']);
                for i = 1:size(data_name,1)
%                     eval(['app.data.' data_name(i,:) '.' var_name '.origin = 1/var_max * app.data.' data_name(i,:) '.' var_name '.origin;']);
                    eval(['app.data.' data_name(i,:) '.' var_name '.stride.each = 1/var_max * app.data.' data_name(i,:) '.' var_name '.stride.each;']);
                    app.processOneVar(data_name(i,:), var_name);
                end
                msgbox('Process Completed');
                return
%                     end
%                 end
            end
            msgbox('No Processing');
        end

        % Button pushed function: GROUPPLOTButton
        function GROUPPLOTButtonPushed(app, event)
            
            class_num = length(app.GroupPlotTable.Data);
            class_name = app.GroupPlotTable.Data;
            var_name = char(app.VariableListBox.Value);
            colordata = app.data.groupplot.colorlist;
            
            slectedFigureID = str2num(app.FigureDropDown.Value);

            linewidth = char(app.LineWidthDropDown.Value);
            linetype = char(app.LineTypeDropDown.Value);
            markersize = char(app.MarkerSizeDropDown.Value);
            markertype = char(app.MarkerTypeDropDown.Value);
            
            stride_percent = app.setting.gaitPecent;
            
            if class_num == 0
                errordlg('Please add group data','error');
            elseif isempty(app.userfig) || ~isvalid(app.userfig)
                errordlg('Please create a new figure','error');
            else
                
                for i = 1:class_num
                    
%                     eval(['data_name = char(class_name(i));']);
%                     disp(data_name);
                    data_name = char(class_name(i));
                    eval(['data_mean =   app.data.' data_name '.' var_name '.stride.mean;']);
                    
                    % 横坐标转化为时间
                    XTimeFlag = app.TimeCheckBox.Value;
                    if XTimeFlag
                        eval(['time_temp =   app.data.' data_name '.time.stride.mean;']);
                        stride_percent = stride_percent / 100 * (max(time_temp)-min(time_temp));
                        xlabel('Time (s)');
                    end
                    
                    if app.ReverseCheckBox.Value == 1
                        data_mean = -data_mean;
                    end
                    
                    eval(['p = plot(app.userfig, stride_percent, data_mean'',''LineStyle'',''' linetype ''',''LineWidth'',' linewidth ',''Marker'',''' markertype ''',''MarkerSize'',' markersize ',''color'', colordata(i,:));']);
                    eval(['pl_num = length(fieldnames(app.userpl.f' num2str(slectedFigureID) '));']);
                    eval(['app.userpl.f' num2str(slectedFigureID) '.p' num2str(pl_num+1) ' = p;']);
                    
                end
            end
        end

        % Button pushed function: GroupPlotAdd
        function GroupPlotAddButtonPushed(app, event)
            
            data_name = char(app.DataListBox.Value);
            if size(data_name,1) > 1
                data_name = data_name(1,:);
            end
            
            colordata = app.setting.usercolor;
            s1 = uistyle('BackgroundColor', colordata);
            
            if isempty(data_name)
                errordlg('Please select a Data','error');
            else
                if isempty(app.GroupPlotTable.Data)
                    app.GroupPlotTable.Data = {[data_name]};
                    app.data.groupplot.colorlist = colordata;
                else
                    app.GroupPlotTable.Data(end+1,:) = {[data_name]};
                    app.data.groupplot.colorlist = [app.data.groupplot.colorlist; colordata];
                end
                row_num = size(app.GroupPlotTable.Data,1);
                addStyle(app.GroupPlotTable,s1,'row',[row_num]);
            end
        end

        % Button pushed function: GroupPlotRemove
        function GroupPlotRemoveButtonPushed(app, event)
            app.GroupPlotTable.Data(app.data.groupplot.selectedRow,:) = [];
            app.data.groupplot.colorlist(app.data.groupplot.selectedRow,:) = [];
            for i = 1:size(app.data.groupplot.colorlist,1)
                s1 = uistyle('BackgroundColor', app.data.groupplot.colorlist(i,:));
%                 row_num = size(app.GroupPlotTable.Data,1);
                addStyle(app.GroupPlotTable,s1,'row',i);
            end
        end

        % Cell selection callback: GroupPlotTable
        function GroupPlotTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.data.groupplot.selectedRow = indices(1);
            end
        end

        % Button pushed function: GroupPlotClear
        function GroupPlotClearButtonPushed(app, event)
            app.GroupPlotTable.Data = [];
            app.data.groupplot.colorlist = [];
        end

        % Button pushed function: GroupPlotSetColor
        function GroupPlotSetColorButtonPushed(app, event)
            colordata = app.setting.usercolor;
            s1 = uistyle('BackgroundColor', colordata);
            app.data.groupplot.colorlist(app.data.groupplot.selectedRow,:) = colordata;
            addStyle(app.GroupPlotTable,s1,'row',[app.data.groupplot.selectedRow]);
        end

        % Button pushed function: BODEPLOTButton_6
        function BODEPLOTButton_6Pushed(app, event)
            data_name_list = char(app.DataListBox.Value);

            eval(['name_temp = app.data.' data_name_list(1,:) '.name;']);
            [InputIndx,~] = listdlg('PromptString',{'Select the Step signal.'},'SelectionMode','single','ListString',name_temp);
            inputVarName = name_temp{InputIndx};
            [OutputIndx,~] = listdlg('PromptString',{'Select the Response signal.'},'SelectionMode','single','ListString',name_temp);
            outputVarName = name_temp{OutputIndx};
            
            triggerValue = str2double(inputdlg({'Trigger Value'}));
            triggerValue = floor(triggerValue);
            shiftTime = str2double(inputdlg({'Shift Time (seconds)'}));
            shiftTime = shiftTime;
            
            for i = 1:size(data_name_list,1)
                data_name = data_name_list(i,:);
                %% Pick Up Data
                eval(['data_step = app.data.' data_name '.' inputVarName '.origin;']);
                eval(['data_response = app.data.' data_name '.' outputVarName '.origin;']);
                eval(['data_time = app.data.' data_name '.time.origin;']);
                
                timeInterval = data_time(2)-data_time(1);
                shiftIndex = floor(shiftTime/timeInterval);
                stride_index = [];
                num = 0;
                for index_i=1:length(data_step)-1
                    if data_step(index_i) < triggerValue && data_step(index_i+1) > triggerValue
                        if index_i > shiftIndex
                            num = num + 1;
                            stride_index(num) = index_i-shiftIndex;
                        end
                    end
                end
                minInterval = min(stride_index(2:end)-stride_index(1:end-1));
                
                stepEach = zeros(num-1,minInterval);
                responseEach = zeros(num-1,minInterval);
                stepTime = 0:timeInterval:(minInterval-1)*timeInterval;
                for j = 1:num-1
                    stepEach(j,:) = data_step(stride_index(j)+1:stride_index(j)+minInterval);
                    responseEach(j,:) = data_response(stride_index(j)+1:stride_index(j)+minInterval);
                end
                step = mean(stepEach,1);
                response = mean(responseEach,1);
                
                eval(['app.data.' data_name '.StepTime = stepTime;']);
                eval(['app.data.' data_name '.Step = step;']);
                eval(['app.data.' data_name '.StepResponseEach = responseEach;']);
                eval(['app.data.' data_name '.StepResponse = response;']);
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            colormapname = app.ColorMapDropDown.Value;
            colordata = app.othercolor(colormapname,0);
            colornum = size(colordata,1);
            
            app.setting.ColorIndex = app.setting.ColorIndex - 1;
            if app.setting.ColorIndex < 1
                app.setting.ColorIndex = colornum;
            end
            
            app.setting.usercolor = colordata(app.setting.ColorIndex,:);
            app.Lamp.BackgroundColor = app.setting.usercolor;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            colormapname = app.ColorMapDropDown.Value;
            colordata = app.othercolor(colormapname,0);
            colornum = size(colordata,1);
            
            app.setting.ColorIndex = app.setting.ColorIndex + 1;
            if app.setting.ColorIndex > colornum
                app.setting.ColorIndex = 1;
            end

            app.setting.usercolor = colordata(app.setting.ColorIndex,:);
            app.Lamp.BackgroundColor = app.setting.usercolor;
        end

        % Cell selection callback: C3DTable
        function C3DTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.bioMech.selectedRowC3D = indices(1);
            end
        end

        % Cell selection callback: MATTable
        function MATTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.bioMech.selectedRowMAT = indices(1);
            end
        end

        % Button pushed function: AddC3DButton
        function AddC3DButtonPushed(app, event)
            [file_c3d,path_c3d,indx] = uigetfile('*.c3d','Select the C3D Marker file','MultiSelect', 'on');
            
            if indx == 1
                % 加载数据
                if ischar(file_c3d)
                    file_c3d = {file_c3d};
                end
                app.bioMech.C3DFilePath = path_c3d;
                app.bioMech.C3DFileList = file_c3d';
                app.C3DTable.Data = app.bioMech.C3DFileList;
            end

        end

        % Button pushed function: AddMATButton
        function AddMATButtonPushed(app, event)
            [file_mat,path_mat,indx] = uigetfile('*.mat','Select the dSPACE MAT file','MultiSelect', 'on');
            
            if indx == 1
                % 加载数据
                if ischar(file_mat)
                    file_mat = {file_mat};
                end
                app.bioMech.MATFilePath = path_mat;
                app.bioMech.MATFileList = file_mat';
                app.MATTable.Data = app.bioMech.MATFileList;
            end
        end

        % Button pushed function: DeleteC3DButton
        function DeleteC3DButtonPushed(app, event)
            app.bioMech.C3DFileList(app.bioMech.selectedRowC3D) = [];
            app.C3DTable.Data = app.bioMech.C3DFileList;
        end

        % Button pushed function: DeleteMATButton
        function DeleteMATButtonPushed(app, event)
            app.bioMech.MATFileList(app.bioMech.selectedRowMAT) = [];
            app.MATTable.Data = app.bioMech.MATFileList;
        end

        % Button pushed function: UpC3DButton
        function UpC3DButtonPushed(app, event)
            if app.bioMech.selectedRowC3D > 1
                app.bioMech.C3DFileList(app.bioMech.selectedRowC3D-1:app.bioMech.selectedRowC3D)=...
                app.bioMech.C3DFileList([app.bioMech.selectedRowC3D,app.bioMech.selectedRowC3D-1]);
                app.C3DTable.Data = app.bioMech.C3DFileList;
            end
        end

        % Button pushed function: DownC3DButton
        function DownC3DButtonPushed(app, event)
            if app.bioMech.selectedRowC3D < length(app.bioMech.C3DFileList)
                app.bioMech.C3DFileList(app.bioMech.selectedRowC3D:app.bioMech.selectedRowC3D+1)=...
                app.bioMech.C3DFileList([app.bioMech.selectedRowC3D+1,app.bioMech.selectedRowC3D]);
                app.C3DTable.Data = app.bioMech.C3DFileList;
            end
        end

        % Button pushed function: UpMATButton
        function UpMATButtonPushed(app, event)
            if app.bioMech.selectedRowMAT > 1
                app.bioMech.MATFileList(app.bioMech.selectedRowMAT-1:app.bioMech.selectedRowMAT)=...
                app.bioMech.MATFileList([app.bioMech.selectedRowMAT,app.bioMech.selectedRowMAT-1]);
                app.MATTable.Data = app.bioMech.MATFileList;
            end
        end

        % Button pushed function: DownMATButton
        function DownMATButtonPushed(app, event)
            if app.bioMech.selectedRowMAT < length(app.bioMech.MATFileList)
                app.bioMech.MATFileList(app.bioMech.selectedRowMAT:app.bioMech.selectedRowMAT+1)=...
                app.bioMech.MATFileList([app.bioMech.selectedRowMAT+1,app.bioMech.selectedRowMAT]);
                app.MATTable.Data = app.bioMech.MATFileList;
            end
        end

        % Button pushed function: ExportTRCButton
        function ExportTRCButtonPushed(app, event)
            for c3d_file = app.bioMech.C3DFileList'
                read_path = fullfile(app.bioMech.C3DFilePath,c3d_file{:}); 
                export_path = [read_path(1:end-3), 'trc'];
                c3d = OsimC3D102(read_path,1); c3d.convertMillimeters2Meters();
                c3d.writeTRC(export_path);
            end
        end

        % Button pushed function: ExportMOTButton
        function ExportMOTButtonPushed(app, event)
            CalibON = 1; CalibOFF = 0;
            for i = 1:length(app.bioMech.MATFileList)
                c3d_file = app.bioMech.C3DFileList{i};
                mat_file = app.bioMech.MATFileList{i};
                GRF = app.getGRFfromMAT(fullfile(app.bioMech.MATFilePath,mat_file),app.GRFPositionMethodDropDown.Value, CalibOFF);
                c3d = OsimC3D102(fullfile(app.bioMech.C3DFilePath,c3d_file),1); 
                % c3d.convertMillimeters2Meters();
                c3d.setForces(GRF);
                c3d.writeMOT(fullfile(app.bioMech.MATFilePath,[mat_file(1:end-3), 'mot']));
            end
        end

        % Button pushed function: ExportOneMOTButton
        function ExportOneMOTButtonPushed(app, event)
            CalibON = 1; CalibOFF = 0;
            mat_file = app.bioMech.MATFileList{app.bioMech.selectedRowMAT};
            c3d_file = app.bioMech.C3DFileList{app.bioMech.selectedRowMAT};
            GRF = app.getGRFfromMAT(fullfile(app.bioMech.MATFilePath,mat_file),app.GRFPositionMethodDropDown.Value, CalibON);
            c3d = OsimC3D102(fullfile(app.bioMech.C3DFilePath,c3d_file),1); 
            c3d.setForces(GRF);
            c3d.writeMOT(fullfile(app.bioMech.MATFilePath,[mat_file(1:end-3), 'mot']));
        end

        % Button pushed function: CheckGRFButton
        function CheckGRFButtonPushed(app, event)
            % 地反力检查
            CalibON = 1; CalibOFF = 0;trigBias = app.GRFTrigBiasEditField.Value/100;
            GRF = app.getGRFfromMAT(fullfile(app.bioMech.MATFilePath,app.bioMech.MATFileList{app.bioMech.selectedRowMAT}),'Treadmill Origin', CalibON);
            figure('Name','Ground Reaction Force');
            subplot(2,2,1);hold on;title('Right Forcce');plot(GRF.time,GRF.ground_force_r_vx);plot(GRF.time,GRF.ground_force_r_vy);plot(GRF.time,GRF.ground_force_r_vz);xlim([0 3]);plot(GRF.time,trigBias*max(GRF.ground_force_r_vy)*ones(length(GRF.time),1),'--','Color','k');
            subplot(2,2,2);hold on;title('Right Moment');plot(GRF.time,GRF.ground_torque_r_x);plot(GRF.time,GRF.ground_torque_r_y);plot(GRF.time,GRF.ground_torque_r_z);xlim([0 3]);
            subplot(2,2,3);hold on;title('Left Forcce');plot(GRF.time,GRF.ground_force_l_vx);plot(GRF.time,GRF.ground_force_l_vy);plot(GRF.time,GRF.ground_force_l_vz);xlim([0 3]);plot(GRF.time,trigBias*max(GRF.ground_force_l_vy)*ones(length(GRF.time),1),'--','Color','k');
            subplot(2,2,4);hold on;title('Left Moment');plot(GRF.time,GRF.ground_torque_l_x);plot(GRF.time,GRF.ground_torque_l_y);plot(GRF.time,GRF.ground_torque_l_z);xlim([0 3]);
        end

        % Button pushed function: CheckCOPButton
        function CheckCOPButtonPushed(app, event)
            % 足底压力中心检查
            CalibON = 1; CalibOFF = 0;
            GRF = app.getGRFfromMAT(fullfile(app.bioMech.MATFilePath,app.bioMech.MATFileList{app.bioMech.selectedRowMAT}),'Center of Pressure',CalibON);
            figure('Name','Ground Reaction Force');
            subplot(2,2,1);hold on;title('Right COP X');plot(GRF.time,GRF.ground_force_r_px);xlim([0 3]);
            subplot(2,2,2);hold on;title('Right COP Z');plot(GRF.time,GRF.ground_force_r_pz);xlim([0 3]);
            subplot(2,2,3);hold on;title('Left COP X');plot(GRF.time,GRF.ground_force_l_px);xlim([0 3]);
            subplot(2,2,4);hold on;title('Left COP Z');plot(GRF.time,GRF.ground_force_l_pz);xlim([0 3]);
        end

        % Button pushed function: GROUPPLOTButton_2
        function GROUPPLOTButton_2Pushed(app, event)
            var_name_list = {'GRF_vertical_r','GRF_fore_aft_r','Qs_pelvis_ty','Qdots_pelvis_ty','Qdots_pelvis_tx',...
                'Qs_hip_flexion_r','Qs_knee_angle_r','Qs_ankle_angle_r','Tau_hip_flexion_r','Tau_knee_angle_r','Tau_ankle_angle_r',...
                'Power_hip_flexion_r','Power_knee_angle_r','Power_ankle_angle_r',...
                'Act_glut_max1_r','Act_glut_med1_r','Act_iliacus_r','Act_psoas_r',...
                'Act_rect_fem_r','Act_vas_med_r','Act_vas_int_r','Act_vas_lat_r',...
                'Act_semimem_r','Act_semiten_r','Act_bifemlh_r','Act_bifemsh_r',...
                'Act_med_gas_r','Act_lat_gas_r','Act_soleus_r','Act_tib_ant_r'};
            dis_name_list = {'Vertical GRF', 'Frontal GRF','Pelvis Height','Pelvis Vertical Velocity','Pelvis Frontal Velocity',...
                'Hip Flexion Angle','Knee Angle','Ankle Angle','Hip Flexion Moment','Knee Moment','Ankle Moment','Hip Flexion Power','Knee Moment','Ankle Power',...
                'Glut_{max}','Glut_{med}','Iliacus','Psoas','RF','VAS_m','VAS_i','VAS_l','Semimem','Semiten','Bifemlh','Bifemsh',...
                'GAS_m','GAS_l','Soleus','TA'};

            N_vars = size(var_name_list,2);
%             slectedFigureID = str2num(app.FigureDropDown.Value);

            for fig_index = 1:N_vars
                
                app.VariableListBox.Value = var_name_list{fig_index};
                slectedFigureID = str2num(app.FigureDropDown.Value);
                
                if isempty(slectedFigureID) || (slectedFigureID == length(app.figureList.list) && app.figureList.figThis == slectedFigureID)
                    
                    app.NEWButtonPushed2();
                    app.ClearAllButtonPushed();
                    app.GROUPPLOTButtonPushed();

                else
                    
                    app.NEWButtonPushed2();
                    app.ClearAllButtonPushed();
                    app.GROUPPLOTButtonPushed();
                    if slectedFigureID+1 <= length(app.figureList.list)
                        app.FigureDropDown.Value = num2str(slectedFigureID+1);
                    end
                end
                ylabel(dis_name_list{fig_index});
            end
        end

        % Button pushed function: UpdatePhaaseButton
        function UpdatePhaaseButtonPushed(app, event)
            data_name = char(app.DataListBox.Value);
            if ~isempty(data_name)
                eval(['name_temp = app.data.' data_name '.name;']);
                app.XDropDown.Items = name_temp;
                app.YDropDown.Items = name_temp;
            end
        end

        % Cell selection callback: ClassListTable
        function ClassListTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.data.barclass.selectedRow = indices(1);
            end
        end

        % Button pushed function: ClassSetColor
        function ClassSetColorButtonPushed(app, event)
            colordata = app.setting.usercolor;
            s1 = uistyle('BackgroundColor', colordata);
            app.data.barplot.colorlist(app.data.barclass.selectedRow,:) = colordata;
            addStyle(app.ClassListTable,s1,'row',[app.data.barclass.selectedRow]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabExoAnalystUIFigure = uifigure('Visible', 'off');
            app.LabExoAnalystUIFigure.Name = '102Lab ExoAnalyst';
            app.LabExoAnalystUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @LabExoAnalystUIFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.LabExoAnalystUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.LabDataProcessKitLabel = uilabel(app.MainTab);
            app.LabDataProcessKitLabel.Text = {'102 Lab'; 'Data Process Kit'};
            app.ProcessAnalysisButton = uibutton(app.MainTab, 'state');
            app.ProcessAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @ProcessAnalysisButtonValueChanged, true);
            app.ProcessAnalysisButton.Text = {'Process'; 'Analysis'};
            app.GlobalSettingButton = uibutton(app.MainTab, 'state');
            app.GlobalSettingButton.ValueChangedFcn = createCallbackFcn(app, @GlobalSettingButtonValueChanged, true);
            app.GlobalSettingButton.Text = {'Global'; 'Setting'};
            app.BioMechProcessButton = uibutton(app.MainTab, 'state');
            app.BioMechProcessButton.ValueChangedFcn = createCallbackFcn(app, @BioMechProcessButtonValueChanged, true);
            app.BioMechProcessButton.Text = {'BioMech'; 'Process'};
            app.AnaDataTab = uitab(app.TabGroup);
            app.AnaDataTab.Title = 'AnaData';
            app.DataListBox = uilistbox(app.AnaDataTab);
            app.DataListBox.Items = {'NULL'};
            app.DataListBox.Multiselect = 'on';
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @DataListBoxValueChanged2, true);
            app.DataListBox.Value = {'NULL'};
            app.DataLabel = uilabel(app.AnaDataTab);
            app.DataLabel.Text = 'Data';
            app.Panel_2 = uipanel(app.AnaDataTab);
            app.KeyVariableListBox = uilistbox(app.Panel_2);
            app.KeyVariableListBox.Items = {};
            app.KeyVariableListBox.ValueChangedFcn = createCallbackFcn(app, @KeyVariableListBoxValueChanged, true);
            app.KeyVariableListBox.Value = {};
            app.EditKey = uieditfield(app.Panel_2, 'text');
            app.EditKey.ValueChangedFcn = createCallbackFcn(app, @EditKeyValueChanged, true);
            app.EditKey.Value = 'NULL';
            app.Panel_3 = uipanel(app.AnaDataTab);
            app.VariableListBox = uilistbox(app.Panel_3);
            app.VariableListBox.Items = {'NULL'};
            app.VariableListBox.Multiselect = 'on';
            app.VariableListBox.Value = {'NULL'};
            app.VariableLabel = uilabel(app.Panel_3);
            app.VariableLabel.Text = 'Variable';
            app.SearchButton = uibutton(app.AnaDataTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Icon = 'search1.png';
            app.SearchButton.Tooltip = {'Add Data'};
            app.SearchButton.Text = '';
            app.ListButton = uibutton(app.AnaDataTab, 'push');
            app.ListButton.ButtonPushedFcn = createCallbackFcn(app, @ListButtonPushed, true);
            app.ListButton.Icon = 'list.png';
            app.ListButton.Tooltip = {'Add Data'};
            app.ListButton.Text = '';
            app.PlotGroup = uitabgroup(app.AnaDataTab);
            app.PlotTab = uitab(app.PlotGroup);
            app.PlotTab.Title = 'PlotTab';
            app.PLOTTESTPanel = uipanel(app.PlotTab);
            app.PLOTTESTPanel.Title = 'PLOT TEST';
            app.TIMEPLOTButton = uibutton(app.PLOTTESTPanel, 'push');
            app.TIMEPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @TIMEPLOTButtonPushed, true);
            app.TIMEPLOTButton.Text = 'Time Plot';
            app.FEATUREPLOTPanel = uipanel(app.PlotTab);
            app.FEATUREPLOTPanel.Title = 'FEATURE PLOT';
            app.FEATUREPLOTButton = uibutton(app.FEATUREPLOTPanel, 'push');
            app.FEATUREPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @FEATUREPLOTButtonPushed, true);
            app.FEATUREPLOTButton.Text = 'SCATTER';
            app.FeatureDropDown_5Label = uilabel(app.FEATUREPLOTPanel);
            app.FeatureDropDown_5Label.Text = 'Feature';
            app.FeatureDropDown = uidropdown(app.FEATUREPLOTPanel);
            app.FeatureDropDown.Items = {'rms', 'mean', 'max', 'min', 'pt', 'pos_mean', 'neg_mean'};
            app.FeatureDropDown.Value = 'rms';
            app.SmoothDropDownLabel = uilabel(app.FEATUREPLOTPanel);
            app.SmoothDropDownLabel.Text = 'Smooth';
            app.SmoothDropDown = uidropdown(app.FEATUREPLOTPanel);
            app.SmoothDropDown.Items = {'1', '2', '5', '10', '20', '30'};
            app.SmoothDropDown.Value = '1';
            app.PhaseDropDownLabel = uilabel(app.FEATUREPLOTPanel);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.FEATUREPLOTPanel);
            app.PhaseDropDown.Items = {'whole', 'stance', 'swing', 'st1', 'st2', 'st3', 'st4', 'sw1', 'sw2', 'sw3', 'ph1', 'ph2', 'ph3', 'ph4'};
            app.PhaseDropDown.Value = 'whole';
            app.STRIDEPLOTPanel = uipanel(app.PlotTab);
            app.STRIDEPLOTPanel.Title = 'STRIDE PLOT';
            app.STRIDEPLOTButton = uibutton(app.STRIDEPLOTPanel, 'push');
            app.STRIDEPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @STRIDEPLOTButtonPushed, true);
            app.STRIDEPLOTButton.Text = 'PLOT';
            app.meanCheckBox = uicheckbox(app.STRIDEPLOTPanel);
            app.meanCheckBox.Text = 'mean';
            app.meanCheckBox.Value = true;
            app.stdCheckBox = uicheckbox(app.STRIDEPLOTPanel);
            app.stdCheckBox.Text = 'std';
            app.eachCheckBox = uicheckbox(app.STRIDEPLOTPanel);
            app.eachCheckBox.Text = 'each';
            app.STRIDEINTERVALPLOTPanel = uipanel(app.PlotTab);
            app.STRIDEINTERVALPLOTPanel.Title = 'STRIDE INTERVAL PLOT';
            app.ALTERStridePlotButton_3 = uibutton(app.STRIDEINTERVALPLOTPanel, 'push');
            app.ALTERStridePlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @ALTERStridePlotButton_3Pushed, true);
            app.ALTERStridePlotButton_3.Text = 'PLOT';
            app.IntervalStepsDropDownLabel = uilabel(app.STRIDEINTERVALPLOTPanel);
            app.IntervalStepsDropDownLabel.Text = 'Interval Steps';
            app.IntervalStepsDropDown = uidropdown(app.STRIDEINTERVALPLOTPanel);
            app.IntervalStepsDropDown.Items = {'1', '2', '5', '10', '20', '30', '60'};
            app.IntervalStepsDropDown.Value = '1';
            app.SIGNELSTRIDEPLOTPanel = uipanel(app.PlotTab);
            app.SIGNELSTRIDEPLOTPanel.Title = 'SIGNEL STRIDE PLOT';
            app.ALTERStridePlotButton_5 = uibutton(app.SIGNELSTRIDEPLOTPanel, 'push');
            app.ALTERStridePlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @ALTERStridePlotButton_5Pushed, true);
            app.ALTERStridePlotButton_5.Text = 'PLOT';
            app.StepsNumDropDownLabel = uilabel(app.SIGNELSTRIDEPLOTPanel);
            app.StepsNumDropDownLabel.Text = 'Steps Num';
            app.StepsNumDropDown = uidropdown(app.SIGNELSTRIDEPLOTPanel);
            app.StepsNumDropDown.Items = {'1', '2', '5', '10', '20', '30', '60'};
            app.StepsNumDropDown.Value = '1';
            app.AverageNumDropDownLabel = uilabel(app.SIGNELSTRIDEPLOTPanel);
            app.AverageNumDropDownLabel.Text = 'Average Num';
            app.AverageNumDropDown = uidropdown(app.SIGNELSTRIDEPLOTPanel);
            app.AverageNumDropDown.Items = {'1', '2', '5', '10', '20', '30'};
            app.AverageNumDropDown.Value = '1';
            app.UpdateButton = uibutton(app.SIGNELSTRIDEPLOTPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Icon = '1_refresh.png';
            app.UpdateButton.Tooltip = {'Update Steps'};
            app.UpdateButton.Text = '';
            app.TrajectoryPLOTPanel = uipanel(app.PlotTab);
            app.TrajectoryPLOTPanel.Title = 'Trajectory PLOT';
            app.ALTERStridePlotButton_4 = uibutton(app.TrajectoryPLOTPanel, 'push');
            app.ALTERStridePlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @ALTERStridePlotButton_4Pushed, true);
            app.ALTERStridePlotButton_4.Text = 'PLOT';
            app.StepsDropDownLabel = uilabel(app.TrajectoryPLOTPanel);
            app.StepsDropDownLabel.Text = 'Steps';
            app.StepsDropDown = uidropdown(app.TrajectoryPLOTPanel);
            app.StepsDropDown.Items = {'1', '2', '5', '10', '20', '30'};
            app.StepsDropDown.Value = '1';
            app.LinecolorDropDownLabel = uilabel(app.TrajectoryPLOTPanel);
            app.LinecolorDropDownLabel.Text = 'Linecolor';
            app.LinecolorDropDown = uidropdown(app.TrajectoryPLOTPanel);
            app.LinecolorDropDown.Items = {'Fixed', 'Changed'};
            app.LinecolorDropDown.Value = 'Fixed';
            app.PlotTypeDropDownLabel = uilabel(app.TrajectoryPLOTPanel);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.TrajectoryPLOTPanel);
            app.PlotTypeDropDown.Items = {'PeakTime', 'PeakTorque', 'BOTH'};
            app.PlotTypeDropDown.Value = 'BOTH';
            app.GROUPPLOTPanel = uipanel(app.PlotTab);
            app.GROUPPLOTPanel.Title = 'GROUP PLOT';
            app.GROUPPLOTButton = uibutton(app.GROUPPLOTPanel, 'push');
            app.GROUPPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @GROUPPLOTButtonPushed, true);
            app.GROUPPLOTButton.Text = 'PLOT';
            app.GroupPlotTable = uitable(app.GROUPPLOTPanel);
            app.GroupPlotTable.ColumnName = '';
            app.GroupPlotTable.CellSelectionCallback = createCallbackFcn(app, @GroupPlotTableCellSelection, true);
            app.GroupPlotAdd = uibutton(app.GROUPPLOTPanel, 'push');
            app.GroupPlotAdd.ButtonPushedFcn = createCallbackFcn(app, @GroupPlotAddButtonPushed, true);
            app.GroupPlotAdd.Icon = 'add (1).png';
            app.GroupPlotAdd.Text = '';
            app.GroupPlotClear = uibutton(app.GROUPPLOTPanel, 'push');
            app.GroupPlotClear.ButtonPushedFcn = createCallbackFcn(app, @GroupPlotClearButtonPushed, true);
            app.GroupPlotClear.Icon = 'clear.png';
            app.GroupPlotClear.Text = '';
            app.GroupPlotRemove = uibutton(app.GROUPPLOTPanel, 'push');
            app.GroupPlotRemove.ButtonPushedFcn = createCallbackFcn(app, @GroupPlotRemoveButtonPushed, true);
            app.GroupPlotRemove.Icon = 'minus-square.png';
            app.GroupPlotRemove.Text = '';
            app.GroupPlotUp = uibutton(app.GROUPPLOTPanel, 'push');
            app.GroupPlotUp.Icon = 'caret-up.png';
            app.GroupPlotUp.Enable = 'off';
            app.GroupPlotUp.Text = '';
            app.GroupPlotDown = uibutton(app.GROUPPLOTPanel, 'push');
            app.GroupPlotDown.Icon = 'caret-down.png';
            app.GroupPlotDown.Enable = 'off';
            app.GroupPlotDown.Text = '';
            app.GroupPlotSetColor = uibutton(app.GROUPPLOTPanel, 'push');
            app.GroupPlotSetColor.ButtonPushedFcn = createCallbackFcn(app, @GroupPlotSetColorButtonPushed, true);
            app.GroupPlotSetColor.Icon = 'color_Change.png';
            app.GroupPlotSetColor.Text = '';
            app.GROUPPLOTButton_2 = uibutton(app.GROUPPLOTPanel, 'push');
            app.GROUPPLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @GROUPPLOTButton_2Pushed, true);
            app.GROUPPLOTButton_2.Text = 'PLOT Sim';
            app.BarTab = uitab(app.PlotGroup);
            app.BarTab.Title = 'BarTab';
            app.FEATURESBARPLOTPanel = uipanel(app.BarTab);
            app.FEATURESBARPLOTPanel.Title = 'FEATURES BAR PLOT';
            app.GroupListBox = uilistbox(app.FEATURESBARPLOTPanel);
            app.GroupListBox.Items = {};
            app.GroupListBox.Value = {};
            app.ClassLabel = uilabel(app.FEATURESBARPLOTPanel);
            app.ClassLabel.Text = 'Class';
            app.GroupLabel = uilabel(app.FEATURESBARPLOTPanel);
            app.GroupLabel.Text = 'Group';
            app.Panel_14 = uipanel(app.FEATURESBARPLOTPanel);
            app.ClassAddButton_3 = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ClassAddButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClassAddButtonPushed, true);
            app.ClassAddButton_3.Icon = 'add (1).png';
            app.ClassAddButton_3.Text = '';
            app.ClassClearButton_3 = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ClassClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClassClearButtonPushed, true);
            app.ClassClearButton_3.Icon = 'clear.png';
            app.ClassClearButton_3.Text = '';
            app.ClassDeleteButton_3 = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ClassDeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClassDeleteButtonPushed, true);
            app.ClassDeleteButton_3.Icon = 'minus-square.png';
            app.ClassDeleteButton_3.Text = '';
            app.ClassUpButton_3 = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ClassUpButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClassUpButtonPushed, true);
            app.ClassUpButton_3.Icon = 'caret-up.png';
            app.ClassUpButton_3.Text = '';
            app.ClassRenameButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ClassRenameButton.ButtonPushedFcn = createCallbackFcn(app, @ClassRenameButtonPushed, true);
            app.ClassRenameButton.Icon = 'edit-fill.png';
            app.ClassRenameButton.Text = '';
            app.ClassDownButton_3 = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ClassDownButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClassDownButtonPushed, true);
            app.ClassDownButton_3.Icon = 'caret-down.png';
            app.ClassDownButton_3.Text = '';
            app.GroupAddButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.GroupAddButton.ButtonPushedFcn = createCallbackFcn(app, @GroupAddButtonPushed, true);
            app.GroupAddButton.Icon = 'add (1).png';
            app.GroupAddButton.Text = '';
            app.GroupClearButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.GroupClearButton.ButtonPushedFcn = createCallbackFcn(app, @GroupClearButtonPushed, true);
            app.GroupClearButton.Icon = 'clear.png';
            app.GroupClearButton.Text = '';
            app.GroupDeleteButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.GroupDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @GroupDeleteButtonPushed, true);
            app.GroupDeleteButton.Icon = 'minus-square.png';
            app.GroupDeleteButton.Text = '';
            app.GroupUpButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.GroupUpButton.ButtonPushedFcn = createCallbackFcn(app, @GroupUpButtonPushed, true);
            app.GroupUpButton.Icon = 'caret-up.png';
            app.GroupUpButton.Text = '';
            app.GroupRenameButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.GroupRenameButton.ButtonPushedFcn = createCallbackFcn(app, @GroupRenameButtonPushed, true);
            app.GroupRenameButton.Icon = 'edit-fill.png';
            app.GroupRenameButton.Text = '';
            app.GroupDownButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.GroupDownButton.ButtonPushedFcn = createCallbackFcn(app, @GroupDownButtonPushed, true);
            app.GroupDownButton.Icon = 'caret-down.png';
            app.GroupDownButton.Text = '';
            app.VarAlterPlotButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.VarAlterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @VarAlterPlotButton_3Pushed, true);
            app.VarAlterPlotButton.Text = {'BAR'; 'PLOT'};
            app.CHANGECOLORButton = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.CHANGECOLORButton.ButtonPushedFcn = createCallbackFcn(app, @CHANGECOLORButton_2Pushed, true);
            app.CHANGECOLORButton.Icon = 'color_Change.png';
            app.CHANGECOLORButton.Text = '';
            app.FeatureDropDown_2Label_5 = uilabel(app.FEATURESBARPLOTPanel);
            app.FeatureDropDown_2Label_5.Text = 'Feature';
            app.BarFeatureDropDown = uidropdown(app.FEATURESBARPLOTPanel);
            app.BarFeatureDropDown.Items = {'rms', 'mean', 'max', 'min', 'pt', 'pos_mean', 'neg_mean'};
            app.BarFeatureDropDown.Value = 'rms';
            app.ChangeColorLabel = uilabel(app.FEATURESBARPLOTPanel);
            app.ChangeColorLabel.Text = 'Change Color';
            app.GroupIndexDropDown = uidropdown(app.FEATURESBARPLOTPanel);
            app.GroupIndexDropDown.Items = {};
            app.GroupIndexDropDown.Value = {};
            app.BarPlotTypeLabel = uilabel(app.FEATURESBARPLOTPanel);
            app.BarPlotTypeLabel.Text = 'Bar Plot Type';
            app.BarTypeDropDown = uidropdown(app.FEATURESBARPLOTPanel);
            app.BarTypeDropDown.Items = {'bar', 'error', 'horizontal', 'stack', 'horiz-stack'};
            app.BarTypeDropDown.Value = 'bar';
            app.ALTERNEWButton_9 = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ALTERNEWButton_9.ButtonPushedFcn = createCallbackFcn(app, @ALTERNEWButton_7Pushed, true);
            app.ALTERNEWButton_9.Enable = 'off';
            app.ALTERNEWButton_9.Text = {'Export'; 'Data'};
            app.PhaseDropDown_2Label = uilabel(app.FEATURESBARPLOTPanel);
            app.PhaseDropDown_2Label.Text = 'Phase';
            app.BarPhaseDropDown = uidropdown(app.FEATURESBARPLOTPanel);
            app.BarPhaseDropDown.Items = {'whole', 'stance', 'swing', 'st1', 'st2', 'st3', 'st4', 'sw1', 'sw2', 'sw3', 'ph1', 'ph2', 'ph3', 'ph4'};
            app.BarPhaseDropDown.Value = 'whole';
            app.Panel_16 = uipanel(app.FEATURESBARPLOTPanel);
            app.BarEdgeCheckBox = uicheckbox(app.FEATURESBARPLOTPanel);
            app.BarEdgeCheckBox.Text = 'Edge';
            app.BarFixedWidthCheckBox = uicheckbox(app.FEATURESBARPLOTPanel);
            app.BarFixedWidthCheckBox.Text = 'Fixed Width';
            app.BarWidthEditField = uieditfield(app.FEATURESBARPLOTPanel, 'numeric');
            app.BarWidthEditField.Limits = [0 1];
            app.BarWidthEditField.ValueDisplayFormat = '%.1f';
            app.BarWidthEditField.Value = 0.8;
            app.ClassListTable = uitable(app.FEATURESBARPLOTPanel);
            app.ClassListTable.ColumnName = '';
            app.ClassListTable.RowName = {};
            app.ClassListTable.CellSelectionCallback = createCallbackFcn(app, @ClassListTableCellSelection, true);
            app.ClassSetColor = uibutton(app.FEATURESBARPLOTPanel, 'push');
            app.ClassSetColor.ButtonPushedFcn = createCallbackFcn(app, @ClassSetColorButtonPushed, true);
            app.ClassSetColor.Icon = 'color_Change.png';
            app.ClassSetColor.Text = '';
            app.FitTab = uitab(app.PlotGroup);
            app.FitTab.Title = 'FitTab';
            app.FITTINGANALYSISPanel = uipanel(app.FitTab);
            app.FITTINGANALYSISPanel.Title = 'FITTING ANALYSIS';
            app.ClassAddButton_4 = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.ClassAddButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClassAddButton_2Pushed, true);
            app.ClassAddButton_4.Icon = 'Right Arrow.png';
            app.ClassAddButton_4.Text = '';
            app.ClassClearButton_4 = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.ClassClearButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClassClearButton_2Pushed, true);
            app.ClassClearButton_4.Icon = 'clear.png';
            app.ClassClearButton_4.Text = '';
            app.ClassDeleteButton_4 = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.ClassDeleteButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClassDeleteButton_2Pushed, true);
            app.ClassDeleteButton_4.Icon = '错.png';
            app.ClassDeleteButton_4.Text = '';
            app.FeatureDropDown_2Label_6 = uilabel(app.FITTINGANALYSISPanel);
            app.FeatureDropDown_2Label_6.Text = 'Feature';
            app.FitFeatureDropDown = uidropdown(app.FITTINGANALYSISPanel);
            app.FitFeatureDropDown.Items = {'rms', 'mean', 'max', 'min', 'pt', 'pos_mean', 'neg_mean'};
            app.FitFeatureDropDown.Value = 'rms';
            app.ALTERNEWButton_10 = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.ALTERNEWButton_10.ButtonPushedFcn = createCallbackFcn(app, @ALTERNEWButton_8Pushed, true);
            app.ALTERNEWButton_10.Text = {'Export'; 'Data'};
            app.FittingTable = uitable(app.FITTINGANALYSISPanel);
            app.FittingTable.ColumnName = {'Name'; 'Value'; 'LabelX'; 'LabelY'};
            app.FittingTable.RowName = {''};
            app.FittingTable.ColumnEditable = [false true true true];
            app.FittingTable.CellSelectionCallback = createCallbackFcn(app, @FittingTableCellSelection, true);
            app.AddLabelXButton = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.AddLabelXButton.ButtonPushedFcn = createCallbackFcn(app, @AddLabelXButtonPushed, true);
            app.AddLabelXButton.Text = 'Add LabelX';
            app.AddLabelYButton = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.AddLabelYButton.ButtonPushedFcn = createCallbackFcn(app, @AddLabelYButtonPushed, true);
            app.AddLabelYButton.Text = {'Add LabelY'; ''};
            app.FittingButton1 = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.FittingButton1.ButtonPushedFcn = createCallbackFcn(app, @FittingButton1Pushed2, true);
            app.FittingButton1.Text = '1-D Fit & Interp';
            app.FittingMethodDropDown1 = uidropdown(app.FITTINGANALYSISPanel);
            app.FittingMethodDropDown1.Items = {'linearinterp', 'cubicinterp', 'smoothingspline', 'poly1', 'poly2', 'poly3', 'sin1', 'sin2', 'sin3', 'power1', 'power2', 'exp1', 'exp2', 'gauss1', 'gauss2', 'gauss3', 'fourier1', 'fourier2', 'fourier3', 'weibull', 'cubicspline', 'smoothingspline'};
            app.FittingMethodDropDown1.Value = 'poly1';
            app.FittingMethodDropDown2 = uidropdown(app.FITTINGANALYSISPanel);
            app.FittingMethodDropDown2.Items = {'linearinterp', 'cubicinterp', 'biharmonicinterp', 'thinplateinterp', 'nearestinterp', 'poly11', 'poly21', 'poly12', 'poly22', 'poly23', 'poly32', 'lowess', 'loess'};
            app.FittingMethodDropDown2.Value = 'biharmonicinterp';
            app.FittingButton2 = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.FittingButton2.ButtonPushedFcn = createCallbackFcn(app, @FittingButton2Pushed, true);
            app.FittingButton2.Text = {'2-D  Fit & Interp'; ''};
            app.AddDataButton = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.ClassAddButton_5 = uibutton(app.FITTINGANALYSISPanel, 'push');
            app.ClassAddButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClassAddButton_5Pushed, true);
            app.ClassAddButton_5.Icon = 'add (1).png';
            app.ClassAddButton_5.Text = '';
            app.PhaseDropDown_2Label_2 = uilabel(app.FITTINGANALYSISPanel);
            app.PhaseDropDown_2Label_2.Text = 'Phase';
            app.FitPhaseDropDown = uidropdown(app.FITTINGANALYSISPanel);
            app.FitPhaseDropDown.Items = {'whole', 'stance', 'swing', 'st1', 'st2', 'st3', 'st4', 'sw1', 'sw2', 'sw3', 'ph1', 'ph2', 'ph3', 'ph4'};
            app.FitPhaseDropDown.Value = 'whole';
            app.FitGainEditField = uieditfield(app.FITTINGANALYSISPanel, 'numeric');
            app.FitGainEditField.Limits = [-1000 1000];
            app.FitGainEditField.ValueDisplayFormat = '%.1f';
            app.FitGainEditField.Value = 1;
            app.PhaseDropDown_2Label_4 = uilabel(app.FITTINGANALYSISPanel);
            app.PhaseDropDown_2Label_4.Text = 'Gain';
            app.SignifTab = uitab(app.PlotGroup);
            app.SignifTab.Title = 'SignifTab';
            app.Panel_15 = uipanel(app.SignifTab);
            app.AddGroupButton = uibutton(app.Panel_15, 'push');
            app.AddGroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddGroupButtonPushed, true);
            app.AddGroupButton.Icon = 'add (1).png';
            app.AddGroupButton.Text = '';
            app.ClearGroupButton = uibutton(app.Panel_15, 'push');
            app.ClearGroupButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGroupButtonPushed, true);
            app.ClearGroupButton.Icon = 'clear.png';
            app.ClearGroupButton.Text = '';
            app.DeleteGroupButton = uibutton(app.Panel_15, 'push');
            app.DeleteGroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteGroupButtonPushed, true);
            app.DeleteGroupButton.Icon = '错.png';
            app.DeleteGroupButton.Text = '';
            app.SigTable = uitable(app.Panel_15);
            app.SigTable.ColumnName = {'Group'; 'P-Value'};
            app.SigTable.RowName = {};
            app.SigTable.CellSelectionCallback = createCallbackFcn(app, @SigTableCellSelection, true);
            app.AddDataButton1 = uibutton(app.Panel_15, 'push');
            app.AddDataButton1.ButtonPushedFcn = createCallbackFcn(app, @AddDataButton1Pushed, true);
            app.AddDataButton1.Text = 'Add Average Data';
            app.AddDataButton2 = uibutton(app.Panel_15, 'push');
            app.AddDataButton2.ButtonPushedFcn = createCallbackFcn(app, @AddDataButton2Pushed, true);
            app.AddDataButton2.Text = 'Add Step Data';
            app.ClassUpButton_4 = uibutton(app.Panel_15, 'push');
            app.ClassUpButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClassUpButton_2Pushed, true);
            app.ClassUpButton_4.Icon = 'caret-up.png';
            app.ClassUpButton_4.Text = '';
            app.ClassDownButton_4 = uibutton(app.Panel_15, 'push');
            app.ClassDownButton_4.Icon = 'caret-down.png';
            app.ClassDownButton_4.Text = '';
            app.GroupDataListBox = uilistbox(app.Panel_15);
            app.GroupDataListBox.Items = {};
            app.GroupDataListBox.Value = {};
            app.AddDataButton2_2 = uibutton(app.Panel_15, 'push');
            app.AddDataButton2_2.ButtonPushedFcn = createCallbackFcn(app, @AddDataButton2_2Pushed, true);
            app.AddDataButton2_2.Text = 'Clear Data';
            app.FeatureDropDown_2Label_7 = uilabel(app.Panel_15);
            app.FeatureDropDown_2Label_7.Text = 'Feature';
            app.SigFeatureDropDown = uidropdown(app.Panel_15);
            app.SigFeatureDropDown.Items = {'rms', 'mean', 'max', 'min', 'pt', 'pos_mean', 'neg_mean'};
            app.SigFeatureDropDown.Value = 'rms';
            app.NormalityAnaButton = uibutton(app.Panel_15, 'push');
            app.NormalityAnaButton.ButtonPushedFcn = createCallbackFcn(app, @NormalityAnaButtonPushed, true);
            app.NormalityAnaButton.Text = 'Lillietest';
            app.VarAnaButton = uibutton(app.Panel_15, 'push');
            app.VarAnaButton.ButtonPushedFcn = createCallbackFcn(app, @VarAnaButtonPushed, true);
            app.VarAnaButton.Text = 'Vartestn';
            app.VarAnaButton_2 = uibutton(app.Panel_15, 'push');
            app.VarAnaButton_2.ButtonPushedFcn = createCallbackFcn(app, @VarAnaButton_2Pushed, true);
            app.VarAnaButton_2.Text = 'T Test';
            app.VarAnaButton_3 = uibutton(app.Panel_15, 'push');
            app.VarAnaButton_3.ButtonPushedFcn = createCallbackFcn(app, @VarAnaButton_3Pushed, true);
            app.VarAnaButton_3.Text = 'Anova';
            app.VarAnaButton_4 = uibutton(app.Panel_15, 'push');
            app.VarAnaButton_4.ButtonPushedFcn = createCallbackFcn(app, @VarAnaButton_4Pushed, true);
            app.VarAnaButton_4.Text = {'Kruskal'; 'Wallis'};
            app.VarAnaButton_5 = uibutton(app.Panel_15, 'push');
            app.VarAnaButton_5.ButtonPushedFcn = createCallbackFcn(app, @VarAnaButton_5Pushed, true);
            app.VarAnaButton_5.Text = {'Mann'; 'Whhitney'; 'Wilcoxon'};
            app.PhaseDropDown_2Label_3 = uilabel(app.Panel_15);
            app.PhaseDropDown_2Label_3.Text = 'Phase';
            app.SigPhaseDropDown = uidropdown(app.Panel_15);
            app.SigPhaseDropDown.Items = {'whole', 'stance', 'swing', 'st1', 'st2', 'st3', 'st4', 'sw1', 'sw2', 'sw3', 'ph1', 'ph2', 'ph3', 'ph4'};
            app.SigPhaseDropDown.Value = 'whole';
            app.RespTab = uitab(app.PlotGroup);
            app.RespTab.Title = 'RespTab';
            app.FrequencyAnalysisPanel = uipanel(app.RespTab);
            app.FrequencyAnalysisPanel.Title = 'Frequency Analysis';
            app.BODEPLOTButton_1 = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BODEPLOTButton_1.ButtonPushedFcn = createCallbackFcn(app, @BODEPLOTButton_1Pushed, true);
            app.BODEPLOTButton_1.Text = 'PLOT Amp Res';
            app.BODEPLOTButton_2 = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BODEPLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @BODEPLOTButton_2Pushed, true);
            app.BODEPLOTButton_2.Text = 'PLOT Pha Res';
            app.BODEPLOTButton_3 = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BODEPLOTButton_3.ButtonPushedFcn = createCallbackFcn(app, @BODEPLOTButton_3Pushed2, true);
            app.BODEPLOTButton_3.Text = 'FFT';
            app.BODEPLOTButton_4 = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BODEPLOTButton_4.ButtonPushedFcn = createCallbackFcn(app, @BODEPLOTButton_4Pushed2, true);
            app.BODEPLOTButton_4.Text = 'PLOT Mean';
            app.BODEPLOTButton_5 = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BODEPLOTButton_5.ButtonPushedFcn = createCallbackFcn(app, @BODEPLOTButton_5Pushed, true);
            app.BODEPLOTButton_5.Text = 'PLOT Mean';
            app.CutOffFreqEditFieldLabel = uilabel(app.FrequencyAnalysisPanel);
            app.CutOffFreqEditFieldLabel.Text = 'Cut Off Freq';
            app.CutOffFreqEditField = uieditfield(app.FrequencyAnalysisPanel, 'numeric');
            app.CutOffFreqEditField.Limits = [0 100];
            app.CutOffFreqEditField.ValueDisplayFormat = '%.1f';
            app.CutOffFreqEditField.Value = 25;
            app.StepPLOTPanel = uipanel(app.RespTab);
            app.StepPLOTPanel.Title = 'Step PLOT';
            app.STEPPLOTButton_1 = uibutton(app.StepPLOTPanel, 'push');
            app.STEPPLOTButton_1.ButtonPushedFcn = createCallbackFcn(app, @STEPPLOTButton_1Pushed, true);
            app.STEPPLOTButton_1.Text = 'PLOT Step Signal';
            app.STEPPLOTButton_3 = uibutton(app.StepPLOTPanel, 'push');
            app.STEPPLOTButton_3.ButtonPushedFcn = createCallbackFcn(app, @STEPPLOTButton_3Pushed, true);
            app.STEPPLOTButton_3.Text = 'PLOT Step Response Each';
            app.STEPPLOTButton_2 = uibutton(app.StepPLOTPanel, 'push');
            app.STEPPLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STEPPLOTButton_2Pushed, true);
            app.STEPPLOTButton_2.Text = 'PLOT Step Response Mean';
            app.BODEPLOTButton_6 = uibutton(app.StepPLOTPanel, 'push');
            app.BODEPLOTButton_6.ButtonPushedFcn = createCallbackFcn(app, @BODEPLOTButton_6Pushed, true);
            app.BODEPLOTButton_6.Text = {'Steps'; 'Division'};
            app.FPIPLOTPanel = uipanel(app.RespTab);
            app.FPIPLOTPanel.Title = 'FPI PLOT';
            app.FPIPLOTButton = uibutton(app.FPIPLOTPanel, 'push');
            app.FPIPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @FPIPLOTButtonPushed, true);
            app.FPIPLOTButton.Text = 'PLOT FPI Hot ';
            app.ViewSwitch = uiswitch(app.FPIPLOTPanel, 'slider');
            app.ViewSwitch.Items = {'3D', '2D'};
            app.ViewSwitch.Value = '3D';
            app.GaitCycleEditFieldLabel = uilabel(app.FPIPLOTPanel);
            app.GaitCycleEditFieldLabel.Text = {'Gait Cycle'; ''};
            app.GaitCycleEditField = uieditfield(app.FPIPLOTPanel, 'numeric');
            app.GaitCycleEditField.Limits = [0 100];
            app.GaitCycleEditField.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField.Value = 50;
            app.FPIPLOTButton_2 = uibutton(app.FPIPLOTPanel, 'push');
            app.FPIPLOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @FPIPLOTButton_2Pushed, true);
            app.FPIPLOTButton_2.Text = 'Animation';
            app.PhaseAnalysisPanel = uipanel(app.RespTab);
            app.PhaseAnalysisPanel.Title = 'Phase Analysis';
            app.FPIPLOTButton_3 = uibutton(app.PhaseAnalysisPanel, 'push');
            app.FPIPLOTButton_3.ButtonPushedFcn = createCallbackFcn(app, @FPIPLOTButton_3Pushed, true);
            app.FPIPLOTButton_3.Text = 'PLOT AVG';
            app.FPIPLOTButton_4 = uibutton(app.PhaseAnalysisPanel, 'push');
            app.FPIPLOTButton_4.ButtonPushedFcn = createCallbackFcn(app, @FPIPLOTButton_4Pushed, true);
            app.FPIPLOTButton_4.Text = 'PLOT ALL';
            app.XDropDownLabel = uilabel(app.PhaseAnalysisPanel);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.PhaseAnalysisPanel);
            app.XDropDown.Items = {'Null'};
            app.XDropDown.Value = 'Null';
            app.YDropDownLabel = uilabel(app.PhaseAnalysisPanel);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.PhaseAnalysisPanel);
            app.YDropDown.Items = {'Null'};
            app.YDropDown.Value = 'Null';
            app.UpdatePhaaseButton = uibutton(app.PhaseAnalysisPanel, 'push');
            app.UpdatePhaaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePhaaseButtonPushed, true);
            app.UpdatePhaaseButton.Icon = '1_refresh.png';
            app.UpdatePhaaseButton.Tooltip = {'Update Steps'};
            app.UpdatePhaaseButton.Text = '';
            app.CloorTab = uitab(app.PlotGroup);
            app.CloorTab.Title = 'CloorTab';
            app.ColorMapsPanel = uipanel(app.CloorTab);
            app.ColorMapsPanel.Title = 'Color Maps';
            app.ColorSetButton1 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1.Icon = 'ColorSet1.png';
            app.ColorSetButton1.Text = '';
            app.ColorSetButton1_2 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_2.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_2.Icon = 'ColorSet2.png';
            app.ColorSetButton1_2.Text = '';
            app.ColorSetButton1_3 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_3.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_3.Icon = 'ColorSet3.png';
            app.ColorSetButton1_3.Text = '';
            app.ColorSetButton1_4 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_4.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_4.Icon = 'ColorSet4.png';
            app.ColorSetButton1_4.Text = '';
            app.ColorSetButton1_5 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_5.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_5.Icon = 'ColorSet5.png';
            app.ColorSetButton1_5.Text = '';
            app.ColorSetButton1_6 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_6.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_6.Icon = 'StepSeq_25.png';
            app.ColorSetButton1_6.Text = '';
            app.ColorSetButton1_7 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_7.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_7.Icon = 'Paired12.png';
            app.ColorSetButton1_7.Text = '';
            app.ColorSetButton1_8 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_8.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_8.Icon = 'Spectral11.png';
            app.ColorSetButton1_8.Text = '';
            app.ColorSetButton1_9 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_9.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_9.Icon = 'ColorSingle1.png';
            app.ColorSetButton1_9.Text = '';
            app.ColorSetButton1_10 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_10.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_10.Icon = 'ColorSingle2.png';
            app.ColorSetButton1_10.Text = '';
            app.ColorSetButton1_11 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_11.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_11.Icon = 'ColorSingle3.png';
            app.ColorSetButton1_11.Text = '';
            app.ColorSetButton1_12 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_12.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_12.Icon = 'ColorSingle4.png';
            app.ColorSetButton1_12.Text = '';
            app.ColorSetButton1_13 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_13.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_13.Icon = 'ColorSingle5.png';
            app.ColorSetButton1_13.Text = '';
            app.ColorSetButton1_14 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_14.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_14.Icon = 'ColorSingle6.png';
            app.ColorSetButton1_14.Text = '';
            app.ColorSetButton1_15 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_15.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_15.Icon = 'ColorSingle7.png';
            app.ColorSetButton1_15.Text = '';
            app.ColorSetButton1_16 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_16.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_16.Icon = 'ColorSingle8.png';
            app.ColorSetButton1_16.Text = '';
            app.ColorSetButton1_17 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_17.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_17.Icon = 'ColorSingle9.png';
            app.ColorSetButton1_17.Text = '';
            app.ColorSetButton1_18 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_18.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_18.Icon = 'ColorSingle10.png';
            app.ColorSetButton1_18.Text = '';
            app.ColorSetButton1_19 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_19.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_19.Icon = 'ColorSingle11.png';
            app.ColorSetButton1_19.Text = '';
            app.ColorSetButton1_20 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_20.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_20.Icon = 'ColorSingle12.png';
            app.ColorSetButton1_20.Text = '';
            app.ColorSetButton1_21 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_21.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_21.Icon = 'ColorSingle13.png';
            app.ColorSetButton1_21.Text = '';
            app.ColorSetButton1_22 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_22.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_22.Icon = 'ColorSingle14.png';
            app.ColorSetButton1_22.Text = '';
            app.ColorSetButton1_23 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_23.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_23.Icon = 'ColorSingle15.png';
            app.ColorSetButton1_23.Text = '';
            app.ColorSetButton1_24 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_24.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_24.Icon = 'ColorSingle16.png';
            app.ColorSetButton1_24.Text = '';
            app.ColorSetButton1_25 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_25.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_25.Icon = 'ColorGradient1.png';
            app.ColorSetButton1_25.Text = '';
            app.ColorSetButton1_26 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_26.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_26.Icon = 'ColorGradient2.png';
            app.ColorSetButton1_26.Text = '';
            app.ColorSetButton1_27 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_27.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_27.Icon = 'ColorGradient3.png';
            app.ColorSetButton1_27.Text = '';
            app.ColorSetButton1_28 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_28.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_28.Icon = 'ColorGradient4.png';
            app.ColorSetButton1_28.Text = '';
            app.ColorSetButton1_29 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_29.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_29.Icon = 'ColorGradient5.png';
            app.ColorSetButton1_29.Text = '';
            app.ColorSetButton1_30 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_30.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_30.Icon = 'ColorGradient6.png';
            app.ColorSetButton1_30.Text = '';
            app.ColorSetButton1_31 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_31.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_31.Icon = 'ColorGradient7.png';
            app.ColorSetButton1_31.Text = '';
            app.ColorSetButton1_32 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_32.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorSetButton1_32.Icon = 'ColorGradient8.png';
            app.ColorSetButton1_32.Text = '';
            app.ColorSetButton1_33 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_33.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_33.Icon = 'ColorGradient9.png';
            app.ColorSetButton1_33.Text = '';
            app.ColorSetButton1_34 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_34.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_34.Icon = 'ColorGradient10.png';
            app.ColorSetButton1_34.Text = '';
            app.ColorSetButton1_35 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_35.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_35.Icon = 'ColorGradient11.png';
            app.ColorSetButton1_35.Text = '';
            app.ColorSetButton1_36 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_36.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_36.Icon = 'ColorGradient12.png';
            app.ColorSetButton1_36.Text = '';
            app.ColorSetButton1_37 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_37.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_37.Icon = 'ColorGradient13.png';
            app.ColorSetButton1_37.Text = '';
            app.ColorSetButton1_38 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_38.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_38.Icon = 'ColorGradient14.png';
            app.ColorSetButton1_38.Text = '';
            app.ColorSetButton1_39 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_39.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_39.Icon = 'ColorGradient15.png';
            app.ColorSetButton1_39.Text = '';
            app.ColorSetButton1_40 = uibutton(app.ColorMapsPanel, 'push');
            app.ColorSetButton1_40.ButtonPushedFcn = createCallbackFcn(app, @ColorSetButtonPushed, true);
            app.ColorSetButton1_40.Icon = 'ColorGradient16.png';
            app.ColorSetButton1_40.Text = '';
            app.ToolButtonGroup = uibuttongroup(app.AnaDataTab);
            app.LoadTabButton = uibutton(app.ToolButtonGroup, 'push');
            app.LoadTabButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTabButtonPushed, true);
            app.LoadTabButton.Icon = 'file (1).png';
            app.LoadTabButton.Tooltip = {'Add Data'};
            app.LoadTabButton.Text = '';
            app.VarEditTabButton = uibutton(app.ToolButtonGroup, 'push');
            app.VarEditTabButton.ButtonPushedFcn = createCallbackFcn(app, @VarEditTabButtonPushed, true);
            app.VarEditTabButton.Icon = 'edit.png';
            app.VarEditTabButton.Tooltip = {'Add Data'};
            app.VarEditTabButton.Text = '';
            app.PlotSetTabButton = uibutton(app.ToolButtonGroup, 'push');
            app.PlotSetTabButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSetTabButtonPushed, true);
            app.PlotSetTabButton.Icon = 'album.png';
            app.PlotSetTabButton.Tooltip = {'Add Data'};
            app.PlotSetTabButton.Text = '';
            app.PlotSetTabButton_2 = uibutton(app.ToolButtonGroup, 'push');
            app.PlotSetTabButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotSetTabButton_2Pushed, true);
            app.PlotSetTabButton_2.Icon = 'wave-square-solid.png';
            app.PlotSetTabButton_2.Tooltip = {'Add Data'};
            app.PlotSetTabButton_2.Text = '';
            app.ToolTabGroup = uitabgroup(app.AnaDataTab);
            app.LoadTab = uitab(app.ToolTabGroup);
            app.LoadTab.Title = 'LoadTab';
            app.LOADDATAButton = uibutton(app.LoadTab, 'push');
            app.LOADDATAButton.ButtonPushedFcn = createCallbackFcn(app, @LOADDATAButtonPushed, true);
            app.LOADDATAButton.Icon = 'plus-circle-fill.png';
            app.LOADDATAButton.Tooltip = {'Add Data'};
            app.LOADDATAButton.Text = '';
            app.DELETEDATAButton = uibutton(app.LoadTab, 'push');
            app.DELETEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEDATAButtonPushed, true);
            app.DELETEDATAButton.Icon = 'delete_fill.png';
            app.DELETEDATAButton.Tooltip = {'Delete Data'};
            app.DELETEDATAButton.Text = '';
            app.SAVEVARButton = uibutton(app.LoadTab, 'push');
            app.SAVEVARButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEVARButtonPushed, true);
            app.SAVEVARButton.Icon = 'save1.png';
            app.SAVEVARButton.Tooltip = {'Save Data'};
            app.SAVEVARButton.Text = '';
            app.ExtractSCONEDataButton = uibutton(app.LoadTab, 'push');
            app.ExtractSCONEDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSCONEDataButtonPushed, true);
            app.ExtractSCONEDataButton.Icon = 'extract.png';
            app.ExtractSCONEDataButton.Tooltip = {'Extract SCONE Data'};
            app.ExtractSCONEDataButton.Text = '';
            app.RenameDataButton = uibutton(app.LoadTab, 'push');
            app.RenameDataButton.ButtonPushedFcn = createCallbackFcn(app, @RenameDataButtonPushed, true);
            app.RenameDataButton.Icon = 'rename.png';
            app.RenameDataButton.Tooltip = {'Rename Variable'};
            app.RenameDataButton.Text = '';
            app.DataUpButton = uibutton(app.LoadTab, 'push');
            app.DataUpButton.ButtonPushedFcn = createCallbackFcn(app, @DataUpButtonPushed, true);
            app.DataUpButton.Icon = 'caret-up.png';
            app.DataUpButton.Text = '';
            app.DataDownButton = uibutton(app.LoadTab, 'push');
            app.DataDownButton.ButtonPushedFcn = createCallbackFcn(app, @DataDownButtonPushed, true);
            app.DataDownButton.Icon = 'caret-down.png';
            app.DataDownButton.Text = '';
            app.SelectVarButton = uibutton(app.LoadTab, 'push');
            app.SelectVarButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVarButtonPushed, true);
            app.SelectVarButton.Icon = '筛选.png';
            app.SelectVarButton.Text = '';
            app.VarEditTab = uitab(app.ToolTabGroup);
            app.VarEditTab.Title = 'VarEditTab';
            app.DeleteStepsButton = uibutton(app.VarEditTab, 'push');
            app.DeleteStepsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStepsButtonPushed, true);
            app.DeleteStepsButton.Icon = 'table-row-remove.png';
            app.DeleteStepsButton.Tooltip = {'Delete STEPS'};
            app.DeleteStepsButton.Text = '';
            app.DivideDataButton = uibutton(app.VarEditTab, 'push');
            app.DivideDataButton.ButtonPushedFcn = createCallbackFcn(app, @DivideDataButtonPushed, true);
            app.DivideDataButton.Icon = 'divide.png';
            app.DivideDataButton.Tooltip = {'Divide Data'};
            app.DivideDataButton.Text = '';
            app.MergeDataButton = uibutton(app.VarEditTab, 'push');
            app.MergeDataButton.ButtonPushedFcn = createCallbackFcn(app, @MergeDataButtonPushed, true);
            app.MergeDataButton.Icon = 'merge.png';
            app.MergeDataButton.Tooltip = {'Merge Data'};
            app.MergeDataButton.Text = '';
            app.RenameVarButton = uibutton(app.VarEditTab, 'push');
            app.RenameVarButton.ButtonPushedFcn = createCallbackFcn(app, @RenameVarButtonPushed, true);
            app.RenameVarButton.Icon = 'rename.png';
            app.RenameVarButton.Tooltip = {'Rename Variable'};
            app.RenameVarButton.Text = '';
            app.DelVarButton = uibutton(app.VarEditTab, 'push');
            app.DelVarButton.ButtonPushedFcn = createCallbackFcn(app, @DelVarButtonPushed, true);
            app.DelVarButton.Icon = 'remove-sign.png';
            app.DelVarButton.Tooltip = {'Delete Variable'};
            app.DelVarButton.Text = '';
            app.NormalVarButton = uibutton(app.VarEditTab, 'push');
            app.NormalVarButton.ButtonPushedFcn = createCallbackFcn(app, @NormalVarButtonPushed, true);
            app.NormalVarButton.Icon = 'divideCal.png';
            app.NormalVarButton.Tooltip = {'Normalize Variable'};
            app.NormalVarButton.Text = '';
            app.PROCESSButton = uibutton(app.VarEditTab, 'push');
            app.PROCESSButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSButtonPushed, true);
            app.PROCESSButton.Icon = 'outline-story.png';
            app.PROCESSButton.Tooltip = {'Process Data'};
            app.PROCESSButton.Text = {''; ''};
            app.BiasVarButton = uibutton(app.VarEditTab, 'push');
            app.BiasVarButton.ButtonPushedFcn = createCallbackFcn(app, @BiasVarButtonPushed, true);
            app.BiasVarButton.Icon = 'minus-square.png';
            app.BiasVarButton.Tooltip = {'Normalize Variable'};
            app.BiasVarButton.Text = '';
            app.CreateStateButton = uibutton(app.VarEditTab, 'push');
            app.CreateStateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateStateButtonPushed2, true);
            app.CreateStateButton.Icon = 'wave-square-solid.png';
            app.CreateStateButton.Tooltip = {'Create State From GRF'};
            app.CreateStateButton.Text = '';
            app.NormVarButton = uibutton(app.VarEditTab, 'push');
            app.NormVarButton.ButtonPushedFcn = createCallbackFcn(app, @NormVarButtonPushed, true);
            app.NormVarButton.Icon = 'Normalization.png';
            app.NormVarButton.Tooltip = {'Normalize Variable'};
            app.NormVarButton.Text = '';
            app.PROCESSALLButton = uibutton(app.VarEditTab, 'push');
            app.PROCESSALLButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSALLButtonPushed, true);
            app.PROCESSALLButton.Icon = 'un-Process-fill.png';
            app.PROCESSALLButton.Tooltip = {'Process All'};
            app.PROCESSALLButton.Text = '';
            app.PlotSetTab = uitab(app.ToolTabGroup);
            app.PlotSetTab.Title = 'PlotSetTab';
            app.ColorMapAxes = uiaxes(app.PlotSetTab);
            app.ColorMapAxes.XAxisLocation = 'origin';
            app.ColorMapAxes.YAxisLocation = 'origin';
            app.ColorMapAxes.Visible = 'off';
            app.Lamp = uibutton(app.PlotSetTab, 'push');
            app.Lamp.ButtonPushedFcn = createCallbackFcn(app, @LampPushed3, true);
            app.Lamp.Tooltip = {''};
            app.Lamp.Text = '';
            app.Button = uibutton(app.PlotSetTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Arrow, right, next (1).png';
            app.Button.Tooltip = {''};
            app.Button.Text = '';
            app.Button_3 = uibutton(app.PlotSetTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'Arrow, left, back.png';
            app.Button_3.Tooltip = {''};
            app.Button_3.Text = '';
            app.LineWidthDropDownLabel = uilabel(app.PlotSetTab);
            app.LineWidthDropDownLabel.Text = 'LineWidth';
            app.LineWidthDropDown = uidropdown(app.PlotSetTab);
            app.LineWidthDropDown.Items = {'0.5', '1', '2', '3', '4', '5', '8', '10', '12', '15', '18', '20', '25', '30'};
            app.LineWidthDropDown.Value = '3';
            app.LineTypeDropDownLabel = uilabel(app.PlotSetTab);
            app.LineTypeDropDownLabel.Text = 'LineType';
            app.LineTypeDropDown = uidropdown(app.PlotSetTab);
            app.LineTypeDropDown.Items = {'-', '--', '-.', ':', 'none'};
            app.LineTypeDropDown.Value = '-';
            app.MarkerSizeDropDownLabel = uilabel(app.PlotSetTab);
            app.MarkerSizeDropDownLabel.Text = 'MarkerSize';
            app.MarkerSizeDropDown = uidropdown(app.PlotSetTab);
            app.MarkerSizeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '15', '20', '30', '50', '80', '100', '120', '150', '200'};
            app.MarkerSizeDropDown.Value = '15';
            app.MarkerTypeDropDownLabel = uilabel(app.PlotSetTab);
            app.MarkerTypeDropDownLabel.Text = 'MarkerType';
            app.MarkerTypeDropDown = uidropdown(app.PlotSetTab);
            app.MarkerTypeDropDown.Items = {'o', '+', '*', '.', 'x', 's', 'd', 'p', 'h', 'none'};
            app.MarkerTypeDropDown.Value = 'none';
            app.ColorMapDropDown = uidropdown(app.PlotSetTab);
            app.ColorMapDropDown.Items = {};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = {};
            app.ColorMapLabel = uilabel(app.PlotSetTab);
            app.ColorMapLabel.Text = {'Color'; 'Map'};
            app.NEWButton = uibutton(app.PlotSetTab, 'push');
            app.NEWButton.ButtonPushedFcn = createCallbackFcn(app, @NEWButtonPushed2, true);
            app.NEWButton.Icon = 'square.png';
            app.NEWButton.Tooltip = {'NEW Plot'};
            app.NEWButton.Text = {''; ''};
            app.NEWSubButton = uibutton(app.PlotSetTab, 'push');
            app.NEWSubButton.ButtonPushedFcn = createCallbackFcn(app, @NEWSubButtonPushed, true);
            app.NEWSubButton.Icon = 'apps.png';
            app.NEWSubButton.Tooltip = {'NEW SubPlot'};
            app.NEWSubButton.Text = '';
            app.SubRowDropDownLabel = uilabel(app.PlotSetTab);
            app.SubRowDropDownLabel.Text = 'SubRow';
            app.SubRowDropDown = uidropdown(app.PlotSetTab);
            app.SubRowDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.SubRowDropDown.Value = '1';
            app.SubColDropDownLabel = uilabel(app.PlotSetTab);
            app.SubColDropDownLabel.Text = 'SubCol';
            app.SubColDropDown = uidropdown(app.PlotSetTab);
            app.SubColDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SubColDropDown.Value = '1';
            app.SelectButton = uibutton(app.PlotSetTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Icon = 'select.png';
            app.SelectButton.Tooltip = {'SELECT Sub Figure'};
            app.SelectButton.Text = '';
            app.ClearLastButton = uibutton(app.PlotSetTab, 'push');
            app.ClearLastButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLastButtonPushed, true);
            app.ClearLastButton.Icon = 'undo.png';
            app.ClearLastButton.Tooltip = {'CLEAR Last'};
            app.ClearLastButton.Text = '';
            app.ClearAllButton = uibutton(app.PlotSetTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Icon = 'clear.png';
            app.ClearAllButton.Tooltip = {'CLEAR All'};
            app.ClearAllButton.Text = '';
            app.ReverseCheckBox = uicheckbox(app.PlotSetTab);
            app.ReverseCheckBox.Text = 'F';
            app.SaveFigure = uibutton(app.PlotSetTab, 'push');
            app.SaveFigure.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigure.Icon = 'save_figure.png';
            app.SaveFigure.Tooltip = {'NEW Plot'};
            app.SaveFigure.Text = {''; ''};
            app.RightCheckBox = uicheckbox(app.PlotSetTab);
            app.RightCheckBox.ValueChangedFcn = createCallbackFcn(app, @RightCheckBoxValueChanged, true);
            app.RightCheckBox.Text = 'R';
            app.EdgeCheckBox = uicheckbox(app.PlotSetTab);
            app.EdgeCheckBox.Text = 'E';
            app.EdgeCheckBox.Value = true;
            app.ColorButton = uibutton(app.PlotSetTab, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Icon = 'color.png';
            app.ColorButton.Tooltip = {''};
            app.ColorButton.Text = '';
            app.FigureDropDownLabel = uilabel(app.PlotSetTab);
            app.FigureDropDownLabel.Text = 'Figure';
            app.FigureDropDown = uidropdown(app.PlotSetTab);
            app.FigureDropDown.Items = {'-'};
            app.FigureDropDown.Value = '-';
            app.TimeCheckBox = uicheckbox(app.PlotSetTab);
            app.TimeCheckBox.Text = 'T';
            app.DataTypeTab = uitab(app.ToolTabGroup);
            app.DataTypeTab.Title = 'DataTypeTab';
            app.dSPACEButton = uibutton(app.DataTypeTab, 'state');
            app.dSPACEButton.ValueChangedFcn = createCallbackFcn(app, @dSPACEButtonValueChanged, true);
            app.dSPACEButton.Text = 'dSPACE';
            app.OpenSimButton = uibutton(app.DataTypeTab, 'state');
            app.OpenSimButton.ValueChangedFcn = createCallbackFcn(app, @OpenSimButtonValueChanged, true);
            app.OpenSimButton.Text = 'OpenSim';
            app.PredSimButton = uibutton(app.DataTypeTab, 'state');
            app.PredSimButton.ValueChangedFcn = createCallbackFcn(app, @PredSimButtonValueChanged, true);
            app.PredSimButton.Text = 'PredSim';
            app.SCONEButton = uibutton(app.DataTypeTab, 'state');
            app.SCONEButton.ValueChangedFcn = createCallbackFcn(app, @SCONEButtonValueChanged, true);
            app.SCONEButton.Text = 'SCONE';
            app.HistoryButton = uibutton(app.DataTypeTab, 'state');
            app.HistoryButton.ValueChangedFcn = createCallbackFcn(app, @HistoryButtonValueChanged, true);
            app.HistoryButton.Text = 'History';
            app.StepSelectTab = uitab(app.ToolTabGroup);
            app.StepSelectTab.Title = 'StepSelectTab';
            app.SelectstepstodeleteLabel = uilabel(app.StepSelectTab);
            app.SelectstepstodeleteLabel.Text = {'Select steps'; 'to delete'};
            app.StepListBox = uilistbox(app.StepSelectTab);
            app.StepListBox.Items = {};
            app.StepListBox.Multiselect = 'on';
            app.StepListBox.Value = {};
            app.OKButton = uibutton(app.StepSelectTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CANCELButton = uibutton(app.StepSelectTab, 'push');
            app.CANCELButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton.Text = 'CANCEL';
            app.ButtonGroup = uibuttongroup(app.StepSelectTab);
            app.ButtonGroup.Title = 'Button Group';
            app.BiggerButton = uiradiobutton(app.ButtonGroup);
            app.BiggerButton.Text = 'Bigger';
            app.BiggerButton.Value = true;
            app.SmallerButton = uiradiobutton(app.ButtonGroup);
            app.SmallerButton.Text = 'Smaller';
            app.BaselineEditFieldLabel = uilabel(app.StepSelectTab);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.BaselineEditField = uieditfield(app.StepSelectTab, 'numeric');
            app.BaselineEditField.ValueDisplayFormat = '%.2f';
            app.OKButton_2 = uibutton(app.StepSelectTab, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.GaitPhaseTab = uitab(app.ToolTabGroup);
            app.GaitPhaseTab.Title = 'GaitPhaseTab';
            app.GaitCycleEditField2 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.GaitCycleEditField2.Limits = [0 100];
            app.GaitCycleEditField2.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField2.Value = 10;
            app.GaitCycleEditField_2Label_2 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_2.Text = 'OT';
            app.GaitCycleEditField3 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.GaitCycleEditField3.Limits = [0 100];
            app.GaitCycleEditField3.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField3.Value = 30;
            app.GaitCycleEditField_2Label_3 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_3.Text = 'HR';
            app.GaitCycleEditField4 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.GaitCycleEditField4.Limits = [0 100];
            app.GaitCycleEditField4.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField4.Value = 50;
            app.GaitCycleEditField_2Label_4 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_4.Text = 'OI';
            app.GaitCycleEditField5 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.GaitCycleEditField5.Limits = [0 100];
            app.GaitCycleEditField5.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField5.Value = 60;
            app.GaitCycleEditField_2Label_5 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_5.Text = 'TO';
            app.GaitCycleEditField6 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.GaitCycleEditField6.Limits = [0 100];
            app.GaitCycleEditField6.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField6.Value = 80;
            app.GaitCycleEditField_2Label_6 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_6.Text = 'FA';
            app.GaitCycleEditField7 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.GaitCycleEditField7.Limits = [0 100];
            app.GaitCycleEditField7.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField7.Value = 90;
            app.GaitCycleEditField_2Label_7 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_7.Text = 'TV';
            app.StrideShiftEditField = uieditfield(app.GaitPhaseTab, 'numeric');
            app.StrideShiftEditField.Limits = [0 100];
            app.StrideShiftEditField.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField_2Label_8 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_8.Text = 'Stride Shift';
            app.PhaseStartEditField1 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseStartEditField1.Limits = [0 100];
            app.PhaseStartEditField1.ValueDisplayFormat = '%.1f';
            app.PhaseStartEditField1.Value = 10;
            app.GaitCycleEditField_2Label_9 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_9.Text = 'Phase 1';
            app.PhaseEndEditField1 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseEndEditField1.Limits = [0 100];
            app.PhaseEndEditField1.ValueDisplayFormat = '%.1f';
            app.PhaseEndEditField1.Value = 60;
            app.PhaseStartEditField2 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseStartEditField2.Limits = [0 100];
            app.PhaseStartEditField2.ValueDisplayFormat = '%.1f';
            app.PhaseStartEditField2.Value = 10;
            app.GaitCycleEditField_2Label_10 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_10.Text = 'Phase 2';
            app.PhaseEndEditField2 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseEndEditField2.Limits = [0 100];
            app.PhaseEndEditField2.ValueDisplayFormat = '%.1f';
            app.PhaseEndEditField2.Value = 50;
            app.PhaseStartEditField3 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseStartEditField3.Limits = [0 100];
            app.PhaseStartEditField3.ValueDisplayFormat = '%.1f';
            app.GaitCycleEditField_2Label_11 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_11.Text = 'Phase 3';
            app.PhaseEndEditField3 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseEndEditField3.Limits = [0 100];
            app.PhaseEndEditField3.ValueDisplayFormat = '%.1f';
            app.PhaseEndEditField3.Value = 30;
            app.PhaseStartEditField4 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseStartEditField4.Limits = [0 100];
            app.PhaseStartEditField4.ValueDisplayFormat = '%.1f';
            app.PhaseStartEditField4.Value = 30;
            app.GaitCycleEditField_2Label_12 = uilabel(app.GaitPhaseTab);
            app.GaitCycleEditField_2Label_12.Text = 'Phase 4';
            app.PhaseEndEditField4 = uieditfield(app.GaitPhaseTab, 'numeric');
            app.PhaseEndEditField4.Limits = [0 100];
            app.PhaseEndEditField4.ValueDisplayFormat = '%.1f';
            app.PhaseEndEditField4.Value = 60;
            app.Panel_17 = uipanel(app.GaitPhaseTab);
            app.Panel_18 = uipanel(app.Panel_17);
            app.PlotButtonGroup = uibuttongroup(app.AnaDataTab);
            app.SIGNFIButton = uibutton(app.PlotButtonGroup, 'push');
            app.SIGNFIButton.ButtonPushedFcn = createCallbackFcn(app, @SIGNFIButtonPushed, true);
            app.SIGNFIButton.Icon = 'box-plot.png';
            app.SIGNFIButton.Tooltip = {'Bar Plot'};
            app.SIGNFIButton.Text = '';
            app.FITTINGButton = uibutton(app.PlotButtonGroup, 'push');
            app.FITTINGButton.ButtonPushedFcn = createCallbackFcn(app, @FITTINGButtonPushed, true);
            app.FITTINGButton.Icon = 'fitting.png';
            app.FITTINGButton.Tooltip = {'Bar Plot'};
            app.FITTINGButton.Text = '';
            app.GROUPBARButton = uibutton(app.PlotButtonGroup, 'push');
            app.GROUPBARButton.ButtonPushedFcn = createCallbackFcn(app, @GROUPBARButtonPushed, true);
            app.GROUPBARButton.Icon = 'BarChart.png';
            app.GROUPBARButton.Tooltip = {'Bar Plot'};
            app.GROUPBARButton.Text = '';
            app.PLOTButton = uibutton(app.PlotButtonGroup, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Icon = 'line chart.png';
            app.PLOTButton.Tooltip = {'Line Plot'};
            app.PLOTButton.Text = '';
            app.RESPButton = uibutton(app.PlotButtonGroup, 'push');
            app.RESPButton.ButtonPushedFcn = createCallbackFcn(app, @RESPButtonPushed, true);
            app.RESPButton.Icon = 'Freq.png';
            app.RESPButton.Tooltip = {'Bar Plot'};
            app.RESPButton.Text = '';
            app.BACKButton = uibutton(app.AnaDataTab, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Icon = 'home (1).png';
            app.BACKButton.Tooltip = {'Home'};
            app.BACKButton.Text = {''; ''};
            app.BioMechTab = uitab(app.TabGroup);
            app.BioMechTab.Title = 'BioMech';
            app.BACKButton_3 = uibutton(app.BioMechTab, 'push');
            app.BACKButton_3.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton_3.Icon = 'home (1).png';
            app.BACKButton_3.Tooltip = {'Home'};
            app.BACKButton_3.Text = {''; ''};
            app.C3DTable = uitable(app.BioMechTab);
            app.C3DTable.ColumnName = {'C3D Files'};
            app.C3DTable.RowName = {};
            app.C3DTable.CellSelectionCallback = createCallbackFcn(app, @C3DTableCellSelection, true);
            app.C3DTable.Multiselect = 'off';
            app.MATTable = uitable(app.BioMechTab);
            app.MATTable.ColumnName = {'MAT Files'};
            app.MATTable.RowName = {};
            app.MATTable.CellSelectionCallback = createCallbackFcn(app, @MATTableCellSelection, true);
            app.MATTable.Multiselect = 'off';
            app.AddC3DButton = uibutton(app.BioMechTab, 'push');
            app.AddC3DButton.ButtonPushedFcn = createCallbackFcn(app, @AddC3DButtonPushed, true);
            app.AddC3DButton.Icon = 'add (1).png';
            app.AddC3DButton.Text = '';
            app.UpC3DButton = uibutton(app.BioMechTab, 'push');
            app.UpC3DButton.ButtonPushedFcn = createCallbackFcn(app, @UpC3DButtonPushed, true);
            app.UpC3DButton.Icon = 'caret-up.png';
            app.UpC3DButton.Text = '';
            app.DownC3DButton = uibutton(app.BioMechTab, 'push');
            app.DownC3DButton.ButtonPushedFcn = createCallbackFcn(app, @DownC3DButtonPushed, true);
            app.DownC3DButton.Icon = 'caret-down.png';
            app.DownC3DButton.Text = '';
            app.DeleteC3DButton = uibutton(app.BioMechTab, 'push');
            app.DeleteC3DButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteC3DButtonPushed, true);
            app.DeleteC3DButton.Icon = 'minus-square.png';
            app.DeleteC3DButton.Text = '';
            app.AddMATButton = uibutton(app.BioMechTab, 'push');
            app.AddMATButton.ButtonPushedFcn = createCallbackFcn(app, @AddMATButtonPushed, true);
            app.AddMATButton.Icon = 'add (1).png';
            app.AddMATButton.Text = '';
            app.UpMATButton = uibutton(app.BioMechTab, 'push');
            app.UpMATButton.ButtonPushedFcn = createCallbackFcn(app, @UpMATButtonPushed, true);
            app.UpMATButton.Icon = 'caret-up.png';
            app.UpMATButton.Text = '';
            app.DownMATButton = uibutton(app.BioMechTab, 'push');
            app.DownMATButton.ButtonPushedFcn = createCallbackFcn(app, @DownMATButtonPushed, true);
            app.DownMATButton.Icon = 'caret-down.png';
            app.DownMATButton.Text = '';
            app.DeleteMATButton = uibutton(app.BioMechTab, 'push');
            app.DeleteMATButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteMATButtonPushed, true);
            app.DeleteMATButton.Icon = 'minus-square.png';
            app.DeleteMATButton.Text = '';
            app.ExportTRCButton = uibutton(app.BioMechTab, 'push');
            app.ExportTRCButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTRCButtonPushed, true);
            app.ExportTRCButton.Text = 'Export TRC Files';
            app.ExportMOTButton = uibutton(app.BioMechTab, 'push');
            app.ExportMOTButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMOTButtonPushed, true);
            app.ExportMOTButton.Text = 'Export MOT Files';
            app.TreadmillOriginmmPanel = uipanel(app.BioMechTab);
            app.TreadmillOriginmmPanel.Title = 'Treadmill Origin (mm)';
            app.PosXLabel_2 = uilabel(app.TreadmillOriginmmPanel);
            app.PosXLabel_2.Text = 'Right X';
            app.TOBiasRXEditField = uieditfield(app.TreadmillOriginmmPanel, 'numeric');
            app.TOBiasRXEditField.Limits = [-5000 5000];
            app.TOBiasRXEditField.ValueDisplayFormat = '%.1f';
            app.TOBiasRXEditField.Value = -60.5;
            app.PosXLabel_3 = uilabel(app.TreadmillOriginmmPanel);
            app.PosXLabel_3.Text = 'Right Y';
            app.TOBiasRYEditField = uieditfield(app.TreadmillOriginmmPanel, 'numeric');
            app.TOBiasRYEditField.Limits = [-5000 5000];
            app.TOBiasRYEditField.ValueDisplayFormat = '%.1f';
            app.TOBiasRYEditField.Value = -2.1;
            app.PosXLabel_4 = uilabel(app.TreadmillOriginmmPanel);
            app.PosXLabel_4.Text = 'Right Z';
            app.TOBiasRZEditField = uieditfield(app.TreadmillOriginmmPanel, 'numeric');
            app.TOBiasRZEditField.Limits = [-5000 5000];
            app.TOBiasRZEditField.ValueDisplayFormat = '%.1f';
            app.TOBiasRZEditField.Value = 50.3;
            app.PosXLabel_5 = uilabel(app.TreadmillOriginmmPanel);
            app.PosXLabel_5.Text = 'Left X';
            app.TOBiasLXEditField = uieditfield(app.TreadmillOriginmmPanel, 'numeric');
            app.TOBiasLXEditField.Limits = [-5000 5000];
            app.TOBiasLXEditField.ValueDisplayFormat = '%.1f';
            app.TOBiasLXEditField.Value = -60.5;
            app.PosXLabel_6 = uilabel(app.TreadmillOriginmmPanel);
            app.PosXLabel_6.Text = 'Left Y';
            app.TOBiasLYEditField = uieditfield(app.TreadmillOriginmmPanel, 'numeric');
            app.TOBiasLYEditField.Limits = [-5000 5000];
            app.TOBiasLYEditField.ValueDisplayFormat = '%.1f';
            app.TOBiasLYEditField.Value = -2.1;
            app.PosXLabel_7 = uilabel(app.TreadmillOriginmmPanel);
            app.PosXLabel_7.Text = 'Left Z';
            app.TOBiasLZEditField = uieditfield(app.TreadmillOriginmmPanel, 'numeric');
            app.TOBiasLZEditField.Limits = [-5000 5000];
            app.TOBiasLZEditField.ValueDisplayFormat = '%.1f';
            app.TOBiasLZEditField.Value = -1076.8;
            app.DataPropertyPanel = uipanel(app.BioMechTab);
            app.DataPropertyPanel.Title = 'Data Property';
            app.PosXLabel_8 = uilabel(app.DataPropertyPanel);
            app.PosXLabel_8.Text = 'QTM Rate';
            app.QTMRateEditField = uieditfield(app.DataPropertyPanel, 'numeric');
            app.QTMRateEditField.Limits = [-5000 5000];
            app.QTMRateEditField.ValueDisplayFormat = '%.0f';
            app.QTMRateEditField.Value = 100;
            app.PosXLabel_9 = uilabel(app.DataPropertyPanel);
            app.PosXLabel_9.Text = 'GRF Smooth ';
            app.GRFSmoothLenEditField = uieditfield(app.DataPropertyPanel, 'numeric');
            app.GRFSmoothLenEditField.Limits = [-5000 5000];
            app.GRFSmoothLenEditField.ValueDisplayFormat = '%.0f';
            app.GRFSmoothLenEditField.Value = 5;
            app.GRFPositionMethodDropDownLabel = uilabel(app.DataPropertyPanel);
            app.GRFPositionMethodDropDownLabel.Text = 'GRF Position Method';
            app.GRFPositionMethodDropDown = uidropdown(app.DataPropertyPanel);
            app.GRFPositionMethodDropDown.Items = {'Treadmill Origin', 'Center of Pressure'};
            app.GRFPositionMethodDropDown.Value = 'Center of Pressure';
            app.PosXLabel_24 = uilabel(app.DataPropertyPanel);
            app.PosXLabel_24.Text = 'GRF Trig Bias %';
            app.GRFTrigBiasEditField = uieditfield(app.DataPropertyPanel, 'numeric');
            app.GRFTrigBiasEditField.Limits = [-5000 5000];
            app.GRFTrigBiasEditField.ValueDisplayFormat = '%.1f';
            app.GRFTrigBiasEditField.Value = 5;
            app.GRFRateLabel = uilabel(app.DataPropertyPanel);
            app.GRFRateLabel.Text = 'GRF Rate';
            app.GRFRateEditField = uieditfield(app.DataPropertyPanel, 'numeric');
            app.GRFRateEditField.Limits = [-5000 5000];
            app.GRFRateEditField.ValueDisplayFormat = '%.0f';
            app.GRFRateEditField.Value = 500;
            app.TreadmillForceCalibrationPanel = uipanel(app.BioMechTab);
            app.TreadmillForceCalibrationPanel.Title = 'Treadmill Force Calibration';
            app.PosXLabel_16 = uilabel(app.TreadmillForceCalibrationPanel);
            app.PosXLabel_16.Text = 'Right Start';
            app.PosXLabel_17 = uilabel(app.TreadmillForceCalibrationPanel);
            app.PosXLabel_17.Text = '->';
            app.PosXLabel_22 = uilabel(app.TreadmillForceCalibrationPanel);
            app.PosXLabel_22.Text = 'Left Start';
            app.PosXLabel_23 = uilabel(app.TreadmillForceCalibrationPanel);
            app.PosXLabel_23.Text = '->';
            app.TFLCalibEndEditField = uieditfield(app.TreadmillForceCalibrationPanel, 'numeric');
            app.TFLCalibEndEditField.Limits = [-5000 5000];
            app.TFLCalibEndEditField.ValueDisplayFormat = '%.1f';
            app.TFLCalibStartEditField = uieditfield(app.TreadmillForceCalibrationPanel, 'numeric');
            app.TFLCalibStartEditField.Limits = [-5000 5000];
            app.TFLCalibStartEditField.ValueDisplayFormat = '%.1f';
            app.TFRCalibEndEditField = uieditfield(app.TreadmillForceCalibrationPanel, 'numeric');
            app.TFRCalibEndEditField.Limits = [-5000 5000];
            app.TFRCalibEndEditField.ValueDisplayFormat = '%.1f';
            app.TFRCalibStartEditField = uieditfield(app.TreadmillForceCalibrationPanel, 'numeric');
            app.TFRCalibStartEditField.Limits = [-5000 5000];
            app.TFRCalibStartEditField.ValueDisplayFormat = '%.1f';
            app.CheckGRFButton = uibutton(app.TreadmillForceCalibrationPanel, 'push');
            app.CheckGRFButton.ButtonPushedFcn = createCallbackFcn(app, @CheckGRFButtonPushed, true);
            app.CheckGRFButton.Text = 'Ckeck GRF';
            app.CheckCOPButton = uibutton(app.TreadmillForceCalibrationPanel, 'push');
            app.CheckCOPButton.ButtonPushedFcn = createCallbackFcn(app, @CheckCOPButtonPushed, true);
            app.CheckCOPButton.Text = 'Ckeck COP';
            app.ExportOneMOTButton = uibutton(app.TreadmillForceCalibrationPanel, 'push');
            app.ExportOneMOTButton.ButtonPushedFcn = createCallbackFcn(app, @ExportOneMOTButtonPushed, true);
            app.ExportOneMOTButton.Text = {'Calibrate & Export '; 'Single MOT Files'};
            app.ClassSetColor_2 = uibutton(app.BioMechTab, 'push');
            app.ClassSetColor_2.Icon = 'color_Change.png';
            app.ClassSetColor_2.Text = '';
            app.ClassSetColor_3 = uibutton(app.BioMechTab, 'push');
            app.ClassSetColor_3.Icon = 'color_Change.png';
            app.ClassSetColor_3.Text = '';
            app.GlobalSetTab = uitab(app.TabGroup);
            app.GlobalSetTab.Title = 'GlobalSetTab';
            app.SetBalckColor = uibutton(app.GlobalSetTab, 'push');
            app.SetBalckColor.ButtonPushedFcn = createCallbackFcn(app, @SetBalckColorButtonPushed, true);
            app.SetBalckColor.Enable = 'off';
            app.SetBalckColor.Text = 'Dark';
            app.BACKButton_2 = uibutton(app.GlobalSetTab, 'push');
            app.BACKButton_2.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton_2.Icon = 'home (1).png';
            app.BACKButton_2.Tooltip = {'Home'};
            app.BACKButton_2.Text = {''; ''};
            app.SetBalckColor_2 = uibutton(app.GlobalSetTab, 'push');
            app.SetBalckColor_2.ButtonPushedFcn = createCallbackFcn(app, @SetBalckColor_2ButtonPushed, true);
            app.SetBalckColor_2.Enable = 'off';
            app.SetBalckColor_2.Text = 'Dream';
            app.SetBalckColor_3 = uibutton(app.GlobalSetTab, 'push');
            app.SetBalckColor_3.ButtonPushedFcn = createCallbackFcn(app, @SetBalckColor_3ButtonPushed, true);
            app.SetBalckColor_3.Enable = 'off';
            app.SetBalckColor_3.Text = 'Earth';
            app.SetBalckColor_4 = uibutton(app.GlobalSetTab, 'push');
            app.SetBalckColor_4.ButtonPushedFcn = createCallbackFcn(app, @SetBalckColor_4ButtonPushed, true);
            app.SetBalckColor_4.Enable = 'off';
            app.SetBalckColor_4.Text = 'Kitty';
            app.SetBalckColor_5 = uibutton(app.GlobalSetTab, 'push');
            app.SetBalckColor_5.ButtonPushedFcn = createCallbackFcn(app, @SetBalckColor_5ButtonPushed, true);
            app.SetBalckColor_5.Enable = 'off';
            app.SetBalckColor_5.Text = 'Lake';
            app.ColorsLabel = uilabel(app.GlobalSetTab);
            app.ColorsLabel.Text = 'Colors';
            app.ProcessLabel = uilabel(app.GlobalSetTab);
            app.ProcessLabel.Text = 'Process';
            app.SetBalckColor_6 = uibutton(app.GlobalSetTab, 'push');
            app.SetBalckColor_6.ButtonPushedFcn = createCallbackFcn(app, @SetBalckColor_6ButtonPushed2, true);
            app.SetBalckColor_6.Enable = 'off';
            app.SetBalckColor_6.Text = 'Speed';
            app.SetBalckColor_7 = uibutton(app.GlobalSetTab, 'push');
            app.SetBalckColor_7.ButtonPushedFcn = createCallbackFcn(app, @SetBalckColor_7ButtonPushed, true);
            app.SetBalckColor_7.Text = 'Accuracy';
            app.FigureLabel = uilabel(app.GlobalSetTab);
            app.FigureLabel.Text = 'Figure';
            app.LineWidthDropDown_2Label = uilabel(app.GlobalSetTab);
            app.LineWidthDropDown_2Label.Text = 'LineWidth';
            app.LineWidthDropDown_2 = uidropdown(app.GlobalSetTab);
            app.LineWidthDropDown_2.Items = {'0.2', '0.5', '0.8', '1', '2', '3', '4', '5'};
            app.LineWidthDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LineWidthDropDown_2ValueChanged, true);
            app.LineWidthDropDown_2.Value = '0.5';
            app.FontSizeDropDownLabel = uilabel(app.GlobalSetTab);
            app.FontSizeDropDownLabel.Text = 'FontSize';
            app.FontSizeDropDown = uidropdown(app.GlobalSetTab);
            app.FontSizeDropDown.Items = {'6', '8', '10', '12', '14', '16', '18', '20', '24', '28', '32', '36', '40'};
            app.FontSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @FontSizeDropDownValueChanged, true);
            app.FontSizeDropDown.Value = '14';
            app.XDensityDropDownLabel = uilabel(app.GlobalSetTab);
            app.XDensityDropDownLabel.Text = 'XDensity';
            app.XDensityDropDown = uidropdown(app.GlobalSetTab);
            app.XDensityDropDown.Items = {'1', '5', '10', '20', '25', '30', '40', '50', '65'};

            app.XDensityDropDown.ValueChangedFcn = createCallbackFcn(app, @XDensityDropDownValueChanged, true);
            app.XDensityDropDown.Value = '10';
            app.FigWdithEditField = uieditfield(app.GlobalSetTab, 'numeric');
            app.FigWdithEditField.Limits = [0 2000];
            app.FigWdithEditField.ValueDisplayFormat = '%.0f';
            app.FigWdithEditField.Value = 800;
            app.WidthText = uilabel(app.GlobalSetTab);
            app.WidthText.Text = 'Width';
            app.HeightLabel = uilabel(app.GlobalSetTab);
            app.HeightLabel.Text = 'Height';
            app.FigHeightEditField = uieditfield(app.GlobalSetTab, 'numeric');
            app.FigHeightEditField.Limits = [0 2000];
            app.FigHeightEditField.ValueDisplayFormat = '%.0f';
            app.FigHeightEditField.Value = 400;
            app.PosXLabel = uilabel(app.GlobalSetTab);
            app.PosXLabel.Text = 'PosX';
            app.FigPosXEditField = uieditfield(app.GlobalSetTab, 'numeric');
            app.FigPosXEditField.Limits = [-5000 5000];
            app.FigPosXEditField.ValueDisplayFormat = '%.0f';
            app.PosYLabel = uilabel(app.GlobalSetTab);
            app.PosYLabel.Text = 'PosY';
            app.FigPosYEditField = uieditfield(app.GlobalSetTab, 'numeric');
            app.FigPosYEditField.Limits = [-2000 2000];
            app.FigPosYEditField.ValueDisplayFormat = '%.0f';
            app.FigPosYEditField.Value = 600;
            % Show the figure after all components are created
            app.LabExoAnalystUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DPAS_for_102Lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabExoAnalystUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabExoAnalystUIFigure)
        end
    end
end